classdef CNNselect_v2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        CNNSelect            matlab.ui.Figure
        CNNPredictionsPanel  matlab.ui.container.Panel
        CNNAxesGrid          matlab.ui.container.GridLayout
        UIAxes_1             matlab.ui.control.UIAxes
        UIAxes_2             matlab.ui.control.UIAxes
        UIAxes_3             matlab.ui.control.UIAxes
        UIAxes_4             matlab.ui.control.UIAxes
        UIAxes_5             matlab.ui.control.UIAxes
        UIAxes_6             matlab.ui.control.UIAxes
        UIAxes_7             matlab.ui.control.UIAxes
        UIAxes_8             matlab.ui.control.UIAxes
        UIAxes_9             matlab.ui.control.UIAxes
        UIAxes_10            matlab.ui.control.UIAxes
        UIAxes_11            matlab.ui.control.UIAxes
        UIAxes_12            matlab.ui.control.UIAxes
        UIAxes_13            matlab.ui.control.UIAxes
        UIAxes_14            matlab.ui.control.UIAxes
        UIAxes_15            matlab.ui.control.UIAxes
        UIAxes_16            matlab.ui.control.UIAxes
        UIAxes_17            matlab.ui.control.UIAxes
        UIAxes_18            matlab.ui.control.UIAxes
        UIAxes_19            matlab.ui.control.UIAxes
        UIAxes_20            matlab.ui.control.UIAxes
        UIAxes_21            matlab.ui.control.UIAxes
        UIAxes_22            matlab.ui.control.UIAxes
        UIAxes_23            matlab.ui.control.UIAxes
        UIAxes_24            matlab.ui.control.UIAxes
        UIAxes_25            matlab.ui.control.UIAxes
        UIAxes_26            matlab.ui.control.UIAxes
        UIAxes_27            matlab.ui.control.UIAxes
        UIAxes_28            matlab.ui.control.UIAxes
        UIAxes_29            matlab.ui.control.UIAxes
        UIAxes_30            matlab.ui.control.UIAxes
        UIAxes_31            matlab.ui.control.UIAxes
        UIAxes_32            matlab.ui.control.UIAxes
        UIAxes_33            matlab.ui.control.UIAxes
        UIAxes_34            matlab.ui.control.UIAxes
        UIAxes_35            matlab.ui.control.UIAxes
        UIAxes_36            matlab.ui.control.UIAxes
        UIAxes_37            matlab.ui.control.UIAxes
        UIAxes_38            matlab.ui.control.UIAxes
        CNNRunPanel          matlab.ui.container.Panel
        ZoomInButton         matlab.ui.control.Button
        ZoomOutButton        matlab.ui.control.Button
        ScalingEditField     matlab.ui.control.NumericEditField
        ScaleFieldLabel      matlab.ui.control.Label
        CNNRunButton         matlab.ui.control.Button
        CNNSelectPanel       matlab.ui.container.Panel
        CNNSelectTable       matlab.ui.control.Table
        CNNSelectButton      matlab.ui.control.Button
        CNNSelectSaveButton  matlab.ui.control.Button
        UIAxes_CNNmean       matlab.ui.control.UIAxes
        OutputPanel          matlab.ui.container.Panel
        CancelButton         matlab.ui.control.Button
        OKButton             matlab.ui.control.Button
        SavePanelsButton     matlab.ui.control.Button
        SaveHighResCheckBox  matlab.ui.control.CheckBox
        UIAxes_CLAHE         matlab.ui.control.UIAxes
    end

    
    properties (Access = private)
        images % Structure of images
        var % structure of variables
        CallingApp % handles to the main app
    end
    
    methods (Access = private)
        
        function funcImageDisplay(app,ax,target)
            imshow(target,[],'Parent',ax);
            axis(ax,'off')
            axis(ax,'tight')
            ax.Toolbar.Visible = 'off'; % Turns off the axes toolbar
            drawnow
        end
        
        function [dir_models,filenames] = funcGetCNNModelNames(app)
            % check for the location of the trained networks
            if ~exist('Matlab models','dir')
                dir_models = uigetdir('Select folder with trained models');
            else
                s = what('Matlab models');
                dir_models = s.path;
            end
            %
            % get all the trained networks in the current folder
            dir_struct = dir(dir_models);% read in the directory structure
            file_idx = ~[dir_struct.isdir];% strip out any directories
            [sorted_names,~] = sortrows({dir_struct(file_idx).name}');% sort the filenames in order
            % now show only files with selected extensions
            fnc_find_ext = @(file_ext) ~cellfun(@isempty, regexpi(sorted_names,['\w+' file_ext],'match')); % find any files with the appropriate extension
            included = cellfun(fnc_find_ext, {'.mat'}, 'UniformOutput',0);% feed array of extensions to the find function
            include_idx = max(cell2mat(included'),[],2);% collapse the boolean output into a linear index
            filenames = sorted_names(include_idx);
        end
        
        function [IdxList,IdxTable] = funcGetCNNModelIndex(app)
            IdxTable = app.CNNSelectTable.Data;
            data = cell2mat(IdxTable);
            IdxList = reshape(data',[],1);
            IdxList(39:end) = [];
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app, mainapp, var, CLAHE)
            app.var.dirCNN = var.dirCNN;
            app.var.fnameCore = var.fnameCore;         
            % hide the window until all the controls are rendered
            app.CNNSelect.Visible = 'off';
            % set up the CNN select panel
            app.CNNSelectTable.Data = num2cell(false(7,6));
            app.CNNSelectTable.ColumnWidth = {27,27,27,27,27,27};
            app.CNNSelectTable.ColumnName = {'1';'2';'3';'4';'5';'6'};
            app.CNNSelectTable.RowName = {'f0';'f1';'f2';'f3';'f4';'f5';'f6'};
            app.CNNSelectTable.ColumnEditable = logical([ones(1,38), zeros(1,4)]);
            
            app.CNNSelectTable.FontSize = 11;
            % sort out the axes
            h = findall(app.CNNSelect,'Type','Axes');
            axis(h,'tight')
            for iA = 1:numel(h)
                h(iA).Toolbar.Visible = 'off'; % Turns off the axes toolbar
                h(iA).Box = 'on';
                h(iA).XTick = [];
                h(iA).YTick = [];
            end
            % position the GUI in the middle of the screen
            r = groot;
            s = r.ScreenSize;
            p = app.CNNSelect.Position;
            app.CNNSelect.Position = [round((s(3)-p(3))/2) round((s(4)-p(4))/2) p(3) p(4)];
            % Store main app object
            app.CallingApp = mainapp;
            app.images.CLAHE = CLAHE;
            funcImageDisplay(app, app.UIAxes_CLAHE, app.images.CLAHE);drawnow
            app.CNNSelect.Visible = 'on';
        end

        % Button pushed function: ZoomInButton
        function ZoomInButtonPushed(app, event)
            % get all the axes
            h = findall(app.CNNSelect,'Type','Axes');
            zoom(h,2)
        end

        % Button pushed function: ZoomOutButton
        function ZoomOutButtonPushed(app, event)
            % link all the axes
            h = findall(app.CNNSelect,'Type','Axes');
            zoom(h,0.5)  
        end

        % Button pushed function: CNNSelectButton
        function CNNSelectButtonPushed(app, event)
            [app.var.CNNModelIndex, app.var.CNNModelTable] = funcGetCNNModelIndex(app);
            app.images.CNNmean = mean(app.images.CNN(:,:,app.var.CNNModelIndex),3);
            imshow(app.images.CNNmean, 'Parent',app.UIAxes_CNNmean);
        end

        % Button pushed function: CNNRunButton
        function CNNRunButtonPushed(app, event)
            [app.var.CNNModelDir, app.var.CNNModelFilenames] = funcGetCNNModelNames(app);
            stride = 200;
            if mod(stride,2) == 1
                stride = stride+1;
            end
            if app.ScalingEditField.Value ~= 1
                im = imresize(app.images.CLAHE,app.ScalingEditField.Value);
            else
                im = app.images.CLAHE;
            end
            app.images.CNN = zeros([size(app.images.CLAHE) numel(app.var.CNNModelFilenames)]);
            for iF = 1:min(numel(app.var.CNNModelFilenames),38)
                    fname = app.var.CNNModelFilenames{iF};
                    temp = load(fullfile(app.var.CNNModelDir,fname));
                    net = temp.net;
                    myfun = @(block_struct) predict(net,block_struct.data);
                    border_size = [(256-stride)/2 (256-stride)/2];
                    CNNPredict = blockproc(im,[stride stride],myfun,'PadPartialBlocks',true,'PadMethod','replicate','BorderSize',border_size,'DisplayWaitBar',true);
                    CNN = CNNPredict(1:size(im,1),1:size(im,2));
                    app.images.CNN(:,:,iF) = imresize(CNN,size(app.images.CLAHE));
                    ax = app.(['UIAxes_' num2str(iF)]);
                    funcImageDisplay(app,ax,app.images.CNN(:,:,iF))
            end
        end

        % Button pushed function: OKButton
        function OKButtonPushed(app, event)
            funcUpdateCNNSelectTable(app.CallingApp,app.var.CNNModelTable)
            % Delete the Select app
            delete(app)
        end

        % Button pushed function: CancelButton
        function CancelButtonPushed(app, event)
            % Delete the Select app
            delete(app)
        end

        % Cell edit callback: CNNSelectTable
        function CNNSelectTableCellEdit(app, event)
            indices = event.Indices;
            newData = event.NewData;
            % the linear index needs to run columns then rows
            idx = sub2ind([6 7],indices(1,2),indices(1,1));
            ax = app.(['UIAxes_' num2str(idx)]);
            if newData
                ax.XColor = 'g';
                ax.YColor = 'g';
            else
                ax.XColor = 'k';
                ax.YColor = 'k';
            end
            ax.LineWidth = 2;
            box(ax,'on')
            axis(ax,'on')
            drawnow
        end

        % Button pushed function: CNNSelectSaveButton
        function CNNSelectSaveButtonPushed(app, event)
            cd(app.var.dirCNN)
            [file,path] = uiputfile('*.mat','Save CNN Selection',fullfile(app.var.dirCNN,[app.var.fnameCore '_CNNSelect']));
            [IdxList,IdxTable] = funcGetCNNModelIndex(app);
            save(fullfile(path,file),'IdxList','IdxTable')
        end

        % Button pushed function: SavePanelsButton
        function SavePanelsButtonPushed(app, event)
            [fout,pathname] = uiputfile('*.png','Panel output');
            if ~isempty(fout)
                pos1 = app.CNNSelect.Position;
                [~, name, ~] = fileparts(fout);
                fileout = [name '_all'];
                cd(pathname)
                figure(app.CNNSelect)
                drawnow
                pause(0.5)
                if app.SaveHighResCheckBox.Value
                    screencapture(0,[pos1(1)*2 -pos1(4) pos1(3)*2 pos1(4)*2],'target',fullfile(pathname,[fileout '.png']))
                else
                    screencapture(0,pos1,'target',fullfile(pathname,[fileout '.png']))
                end
            end
            % we cannot easily access the components in the app designer
            % GUI, so we have to specify them manually!
            panels = {'CNNPredictions';'CNNRun';'CNNSelect';'Output'};
            % save every panel as a png
            for iP = 1:numel(panels)
                fileout = [name '_' panels{iP,1}];
                pos2 = app.([panels{iP,1} 'Panel']).Position;
                figure(app.CNNSelect)
                drawnow
                pause(0.5)
                if app.SaveHighResCheckBox.Value
                    screencapture(0,[2*pos1(1)+2*pos2(1)-1 -pos1(4)+2*pos2(2) 2*pos2(3) 2*pos2(4)],'target',fullfile(pathname,[fileout '.png']))
                else
                    screencapture(0,[pos1(1)+pos2(1) pos1(2)+pos2(2) pos2(3) pos2(4)],'target',fullfile(pathname,[fileout '.png']))
                end
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.CNNSelect = uifigure('Visible', 'off');
            app.CNNSelect.Name = 'UI Figure';
            app.CNNPredictionsPanel = uipanel(app.CNNSelect);
            app.CNNAxesGrid = uigridlayout(app.CNNPredictionsPanel);
            app.UIAxes_1 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_1, '')
            xlabel(app.UIAxes_1, '')
            ylabel(app.UIAxes_1, '')
            app.UIAxes_1.XTick = [];
            app.UIAxes_1.YTick = [];
            app.UIAxes_2 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_2, '')
            xlabel(app.UIAxes_2, '')
            ylabel(app.UIAxes_2, '')
            app.UIAxes_2.XTick = [];
            app.UIAxes_2.YTick = [];
            app.UIAxes_3 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_3, '')
            xlabel(app.UIAxes_3, '')
            ylabel(app.UIAxes_3, '')
            app.UIAxes_3.XTick = [];
            app.UIAxes_3.YTick = [];
            app.UIAxes_4 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_4, '')
            xlabel(app.UIAxes_4, '')
            ylabel(app.UIAxes_4, '')
            app.UIAxes_4.XTick = [];
            app.UIAxes_4.YTick = [];
            app.UIAxes_5 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_5, '')
            xlabel(app.UIAxes_5, '')
            ylabel(app.UIAxes_5, '')
            app.UIAxes_5.XTick = [];
            app.UIAxes_5.YTick = [];
            app.UIAxes_6 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_6, '')
            xlabel(app.UIAxes_6, '')
            ylabel(app.UIAxes_6, '')
            app.UIAxes_6.XTick = [];
            app.UIAxes_6.YTick = [];
            app.UIAxes_7 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_7, '')
            xlabel(app.UIAxes_7, '')
            ylabel(app.UIAxes_7, '')
            app.UIAxes_7.XTick = [];
            app.UIAxes_7.YTick = [];
            app.UIAxes_8 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_8, '')
            xlabel(app.UIAxes_8, '')
            ylabel(app.UIAxes_8, '')
            app.UIAxes_8.XTick = [];
            app.UIAxes_8.YTick = [];
            app.UIAxes_9 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_9, '')
            xlabel(app.UIAxes_9, '')
            ylabel(app.UIAxes_9, '')
            app.UIAxes_9.XTick = [];
            app.UIAxes_9.YTick = [];
            app.UIAxes_10 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_10, '')
            xlabel(app.UIAxes_10, '')
            ylabel(app.UIAxes_10, '')
            app.UIAxes_10.XTick = [];
            app.UIAxes_10.YTick = [];
            app.UIAxes_11 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_11, '')
            xlabel(app.UIAxes_11, '')
            ylabel(app.UIAxes_11, '')
            app.UIAxes_11.XTick = [];
            app.UIAxes_11.YTick = [];
            app.UIAxes_12 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_12, '')
            xlabel(app.UIAxes_12, '')
            ylabel(app.UIAxes_12, '')
            app.UIAxes_12.XTick = [];
            app.UIAxes_12.YTick = [];
            app.UIAxes_13 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_13, '')
            xlabel(app.UIAxes_13, '')
            ylabel(app.UIAxes_13, '')
            app.UIAxes_13.XTick = [];
            app.UIAxes_13.YTick = [];
            app.UIAxes_14 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_14, '')
            xlabel(app.UIAxes_14, '')
            ylabel(app.UIAxes_14, '')
            app.UIAxes_14.XTick = [];
            app.UIAxes_14.YTick = [];
            app.UIAxes_15 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_15, '')
            xlabel(app.UIAxes_15, '')
            ylabel(app.UIAxes_15, '')
            app.UIAxes_15.XTick = [];
            app.UIAxes_15.YTick = [];
            app.UIAxes_16 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_16, '')
            xlabel(app.UIAxes_16, '')
            ylabel(app.UIAxes_16, '')
            app.UIAxes_16.XTick = [];
            app.UIAxes_16.YTick = [];
            app.UIAxes_17 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_17, '')
            xlabel(app.UIAxes_17, '')
            ylabel(app.UIAxes_17, '')
            app.UIAxes_17.XTick = [];
            app.UIAxes_17.YTick = [];
            app.UIAxes_18 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_18, '')
            xlabel(app.UIAxes_18, '')
            ylabel(app.UIAxes_18, '')
            app.UIAxes_18.XTick = [];
            app.UIAxes_18.YTick = [];
            app.UIAxes_19 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_19, '')
            xlabel(app.UIAxes_19, '')
            ylabel(app.UIAxes_19, '')
            app.UIAxes_19.XTick = [];
            app.UIAxes_19.YTick = [];
            app.UIAxes_20 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_20, '')
            xlabel(app.UIAxes_20, '')
            ylabel(app.UIAxes_20, '')
            app.UIAxes_20.XTick = [];
            app.UIAxes_20.YTick = [];
            app.UIAxes_21 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_21, '')
            xlabel(app.UIAxes_21, '')
            ylabel(app.UIAxes_21, '')
            app.UIAxes_21.XTick = [];
            app.UIAxes_21.YTick = [];
            app.UIAxes_22 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_22, '')
            xlabel(app.UIAxes_22, '')
            ylabel(app.UIAxes_22, '')
            app.UIAxes_22.XTick = [];
            app.UIAxes_22.YTick = [];
            app.UIAxes_23 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_23, '')
            xlabel(app.UIAxes_23, '')
            ylabel(app.UIAxes_23, '')
            app.UIAxes_23.XTick = [];
            app.UIAxes_23.YTick = [];
            app.UIAxes_24 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_24, '')
            xlabel(app.UIAxes_24, '')
            ylabel(app.UIAxes_24, '')
            app.UIAxes_24.XTick = [];
            app.UIAxes_24.YTick = [];
            app.UIAxes_25 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_25, '')
            xlabel(app.UIAxes_25, '')
            ylabel(app.UIAxes_25, '')
            app.UIAxes_25.XTick = [];
            app.UIAxes_25.YTick = [];
            app.UIAxes_26 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_26, '')
            xlabel(app.UIAxes_26, '')
            ylabel(app.UIAxes_26, '')
            app.UIAxes_26.XTick = [];
            app.UIAxes_26.YTick = [];
            app.UIAxes_27 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_27, '')
            xlabel(app.UIAxes_27, '')
            ylabel(app.UIAxes_27, '')
            app.UIAxes_27.XTick = [];
            app.UIAxes_27.YTick = [];
            app.UIAxes_28 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_28, '')
            xlabel(app.UIAxes_28, '')
            ylabel(app.UIAxes_28, '')
            app.UIAxes_28.XTick = [];
            app.UIAxes_28.YTick = [];
            app.UIAxes_29 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_29, '')
            xlabel(app.UIAxes_29, '')
            ylabel(app.UIAxes_29, '')
            app.UIAxes_29.XTick = [];
            app.UIAxes_29.YTick = [];
            app.UIAxes_30 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_30, '')
            xlabel(app.UIAxes_30, '')
            ylabel(app.UIAxes_30, '')
            app.UIAxes_30.XTick = [];
            app.UIAxes_30.YTick = [];
            app.UIAxes_31 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_31, '')
            xlabel(app.UIAxes_31, '')
            ylabel(app.UIAxes_31, '')
            app.UIAxes_31.XTick = [];
            app.UIAxes_31.YTick = [];
            app.UIAxes_32 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_32, '')
            xlabel(app.UIAxes_32, '')
            ylabel(app.UIAxes_32, '')
            app.UIAxes_32.XTick = [];
            app.UIAxes_32.YTick = [];
            app.UIAxes_33 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_33, '')
            xlabel(app.UIAxes_33, '')
            ylabel(app.UIAxes_33, '')
            app.UIAxes_33.XTick = [];
            app.UIAxes_33.YTick = [];
            app.UIAxes_34 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_34, '')
            xlabel(app.UIAxes_34, '')
            ylabel(app.UIAxes_34, '')
            app.UIAxes_34.XTick = [];
            app.UIAxes_34.YTick = [];
            app.UIAxes_35 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_35, '')
            xlabel(app.UIAxes_35, '')
            ylabel(app.UIAxes_35, '')
            app.UIAxes_35.XTick = [];
            app.UIAxes_35.YTick = [];
            app.UIAxes_36 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_36, '')
            xlabel(app.UIAxes_36, '')
            ylabel(app.UIAxes_36, '')
            app.UIAxes_36.XTick = [];
            app.UIAxes_36.YTick = [];
            app.UIAxes_37 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_37, '')
            xlabel(app.UIAxes_37, '')
            ylabel(app.UIAxes_37, '')
            app.UIAxes_37.XTick = [];
            app.UIAxes_37.YTick = [];
            app.UIAxes_38 = uiaxes(app.CNNAxesGrid);
            title(app.UIAxes_38, '')
            xlabel(app.UIAxes_38, '')
            ylabel(app.UIAxes_38, '')
            app.UIAxes_38.XTick = [];
            app.UIAxes_38.YTick = [];
            app.CNNRunPanel = uipanel(app.CNNSelect);
            app.CNNRunPanel.Title = 'Run CNN';
            app.ZoomInButton = uibutton(app.CNNRunPanel, 'push');
            app.ZoomInButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomInButtonPushed, true);
            app.ZoomInButton.Text = 'Zoom In';
            app.ZoomOutButton = uibutton(app.CNNRunPanel, 'push');
            app.ZoomOutButton.ButtonPushedFcn = createCallbackFcn(app, @ZoomOutButtonPushed, true);
            app.ZoomOutButton.Text = 'Zoom Out';
            app.ScalingEditField = uieditfield(app.CNNRunPanel, 'numeric');
            app.ScalingEditField.Limits = [0 Inf];
            app.ScalingEditField.Value = 1;
            app.ScaleFieldLabel = uilabel(app.CNNRunPanel);
            app.ScaleFieldLabel.Text = 'Scale';
            app.CNNRunButton = uibutton(app.CNNRunPanel, 'push');
            app.CNNRunButton.ButtonPushedFcn = createCallbackFcn(app, @CNNRunButtonPushed, true);
            app.CNNRunButton.Text = 'Run CNN';
            app.CNNSelectPanel = uipanel(app.CNNSelect);
            app.CNNSelectPanel.Title = 'CNN Select';
            app.CNNSelectTable = uitable(app.CNNSelectPanel);
            app.CNNSelectTable.RowName = {};
            app.CNNSelectTable.ColumnEditable = true;
            app.CNNSelectTable.CellEditCallback = createCallbackFcn(app, @CNNSelectTableCellEdit, true);
            app.CNNSelectButton = uibutton(app.CNNSelectPanel, 'push');
            app.CNNSelectButton.ButtonPushedFcn = createCallbackFcn(app, @CNNSelectButtonPushed, true);
            app.CNNSelectButton.Text = 'Select CNN';
            app.CNNSelectSaveButton = uibutton(app.CNNSelectPanel, 'push');
            app.CNNSelectSaveButton.ButtonPushedFcn = createCallbackFcn(app, @CNNSelectSaveButtonPushed, true);
            app.CNNSelectSaveButton.Text = 'Select Save';
            app.UIAxes_CNNmean = uiaxes(app.CNNSelectPanel);
            title(app.UIAxes_CNNmean, '')
            xlabel(app.UIAxes_CNNmean, '')
            ylabel(app.UIAxes_CNNmean, '')
            app.UIAxes_CNNmean.XTick = [];
            app.UIAxes_CNNmean.YTick = [];
            app.OutputPanel = uipanel(app.CNNSelect);
            app.OutputPanel.Title = 'Output';
            app.CancelButton = uibutton(app.OutputPanel, 'push');
            app.CancelButton.ButtonPushedFcn = createCallbackFcn(app, @CancelButtonPushed, true);
            app.CancelButton.Text = 'Cancel';
            app.OKButton = uibutton(app.OutputPanel, 'push');
            app.OKButton.ButtonPushedFcn = createCallbackFcn(app, @OKButtonPushed, true);
            app.OKButton.Text = 'OK';
            app.SavePanelsButton = uibutton(app.OutputPanel, 'push');
            app.SavePanelsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePanelsButtonPushed, true);
            app.SavePanelsButton.Text = 'Save Panels';
            app.SaveHighResCheckBox = uicheckbox(app.OutputPanel);
            app.SaveHighResCheckBox.Text = 'High Res.';
            app.UIAxes_CLAHE = uiaxes(app.CNNSelect);
            title(app.UIAxes_CLAHE, '')
            xlabel(app.UIAxes_CLAHE, '')
            ylabel(app.UIAxes_CLAHE, '')
            app.UIAxes_CLAHE.XTick = [];
            app.UIAxes_CLAHE.YTick = [];
            % Show the figure after all components are created
            app.CNNSelect.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = CNNselect_v2(varargin)

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.CNNSelect)

            % Execute the startup function
            runStartupFcn(app, @(app)startupFcn(app, varargin{:}))

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.CNNSelect)
        end
    end
end