classdef LeafVeinCNN < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        LeafVeinGUI                     matlab.ui.Figure
        LoadPanel                       matlab.ui.container.Panel
        LoadImageButton                 matlab.ui.control.Button
        ImageLabel                      matlab.ui.control.Label
        DimensionsTextAreaLabel         matlab.ui.control.Label
        DimensionsTextArea              matlab.ui.control.TextArea
        TargetDropDownLabel             matlab.ui.control.Label
        TargetDropDown                  matlab.ui.control.DropDown
        LoadMaskButton                  matlab.ui.control.Button
        LoadVeinButton                  matlab.ui.control.Button
        micronperpixelEditFieldLabel    matlab.ui.control.Label
        MicronPerPixelEditField         matlab.ui.control.NumericEditField
        ImagePanel                      matlab.ui.container.Panel
        DisplayAxes                     matlab.ui.control.UIAxes
        EdgeColorbarAxes                matlab.ui.control.UIAxes
        NodeColorbarAxes                matlab.ui.control.UIAxes
        PolygonColorbarAxes             matlab.ui.control.UIAxes
        DisplayControlsPanel            matlab.ui.container.Panel
        DisplayImageLabel               matlab.ui.control.Label
        DisplayImageDropDown            matlab.ui.control.DropDown
        StatusLabel                     matlab.ui.control.Label
        TabGroup                        matlab.ui.container.TabGroup
        ResultsTab                      matlab.ui.container.Tab
        GraphDisplayControlsPanel       matlab.ui.container.Panel
        BaseimageLabel                  matlab.ui.control.Label
        GraphImageDropDown              matlab.ui.control.DropDown
        EdgemetricLabel                 matlab.ui.control.Label
        GraphEdgeDropDown               matlab.ui.control.DropDown
        NodemetricLabel                 matlab.ui.control.Label
        GraphNodeDropDown               matlab.ui.control.DropDown
        StyleLabel                      matlab.ui.control.Label
        GraphEdgeStyleDropDown          matlab.ui.control.DropDown
        GraphEdgePlotCheckBox           matlab.ui.control.CheckBox
        GraphNodePlotCheckBox           matlab.ui.control.CheckBox
        WidthLabel                      matlab.ui.control.Label
        GraphEdgeWidthDropDown          matlab.ui.control.DropDown
        GraphEdgeLogUseCheckBox         matlab.ui.control.CheckBox
        GraphNodeLogUseCheckBox         matlab.ui.control.CheckBox
        GraphEdgeLabelCheckBox          matlab.ui.control.CheckBox
        GraphNodeLabelCheckBox          matlab.ui.control.CheckBox
        UnitsLabel                      matlab.ui.control.Label
        GraphUnitsDropDown              matlab.ui.control.DropDown
        PolygonNodePlotCheckBox         matlab.ui.control.CheckBox
        PolygonLogUseCheckBox           matlab.ui.control.CheckBox
        PolygonNodeDropDown             matlab.ui.control.DropDown
        PolygonNodeLabelCheckBox        matlab.ui.control.CheckBox
        SizeLabel                       matlab.ui.control.Label
        GraphNodeSizeDropDown           matlab.ui.control.DropDown
        SizeLabel_2                     matlab.ui.control.Label
        PolygonSizeDropDown             matlab.ui.control.DropDown
        ColourDropDownLabel             matlab.ui.control.Label
        GraphEdgeLabelColourDropDown    matlab.ui.control.DropDown
        ColourDropDownLabel_2           matlab.ui.control.Label
        GraphNodeLabelColourDropDown    matlab.ui.control.DropDown
        ColourDropDownLabel_3           matlab.ui.control.Label
        PolygonLabelColourDropDown      matlab.ui.control.DropDown
        cMapLabel                       matlab.ui.control.Label
        GraphEdgeColorMapDropDown       matlab.ui.control.DropDown
        cMapLabel_2                     matlab.ui.control.Label
        GraphNodeColorMapDropDown       matlab.ui.control.DropDown
        cMapLabel_3                     matlab.ui.control.Label
        PolygonNodeColorMapDropDown     matlab.ui.control.DropDown
        PolygonDualGraphPlotCheckBox    matlab.ui.control.CheckBox
        PolygonModeDropDown             matlab.ui.control.DropDown
        ResultsAxes                     matlab.ui.control.UIAxes
        ResultsPanel                    matlab.ui.container.Panel
        TargetLabel                     matlab.ui.control.Label
        ResultsTargetDropDown           matlab.ui.control.DropDown
        ResultsClearButton              matlab.ui.control.Button
        YLabel                          matlab.ui.control.Label
        ResultsYaxisDropDown            matlab.ui.control.DropDown
        XLabel                          matlab.ui.control.Label
        ResultsXaxisDropDown            matlab.ui.control.DropDown
        ResultsHoldCheckBox             matlab.ui.control.CheckBox
        TypeLabel                       matlab.ui.control.Label
        ResultsPlotTypeDropDown         matlab.ui.control.DropDown
        ResultsYlogCheckBox             matlab.ui.control.CheckBox
        ResultsXlogCheckBox             matlab.ui.control.CheckBox
        UnitsLabel_2                    matlab.ui.control.Label
        ResultsUnitsDropDown            matlab.ui.control.DropDown
        ResultsExcludeBoundaryCheckBox  matlab.ui.control.CheckBox
        OutputTab                       matlab.ui.container.Tab
        TestButton                      matlab.ui.control.Button
        DisplayOutputPanel              matlab.ui.container.Panel
        SaveSingleImageButton           matlab.ui.control.Button
        SavePlotsButton                 matlab.ui.control.Button
        SaveGUIButton                   matlab.ui.control.Button
        SaveManualButton                matlab.ui.control.Button
        SaveAllPanelsButton             matlab.ui.control.Button
        SaveHighResCheckBox             matlab.ui.control.CheckBox
        AnalysisFiguresPanel            matlab.ui.container.Panel
        FigureSaveButton                matlab.ui.control.Button
        EdgeWidthDropDownLabel          matlab.ui.control.Label
        FigureEdgeWidthDropDown         matlab.ui.control.DropDown
        SkeletonWidthDropDownLabel      matlab.ui.control.Label
        FigureSkeletonWidthDropDown     matlab.ui.control.DropDown
        HLDFiguresPanel                 matlab.ui.container.Panel
        HLDFigureSaveButton             matlab.ui.control.Button
        HLDFullLeafCheckBox             matlab.ui.control.CheckBox
        NumberofROILabel                matlab.ui.control.Label
        HLDNumROIDropDown               matlab.ui.control.DropDown
        PRTab                           matlab.ui.container.Tab
        PRResultsPanel                  matlab.ui.container.Panel
        PRResultsTable                  matlab.ui.control.Table
        ImageLabel_2                    matlab.ui.control.Label
        PRImageDropDown                 matlab.ui.control.DropDown
        MetricLabel                     matlab.ui.control.Label
        PRMetricDropDown                matlab.ui.control.DropDown
        TypeLabel_3                     matlab.ui.control.Label
        PRTypeDropDown                  matlab.ui.control.DropDown
        GraphLabel                      matlab.ui.control.Label
        PRGraphDropDown                 matlab.ui.control.DropDown
        PRDisplayImage                  matlab.ui.control.Button
        PRPlotGraph                     matlab.ui.control.Button
        WidthLabel_2                    matlab.ui.control.Label
        PRWidthDropDown                 matlab.ui.control.DropDown
        PRAxes                          matlab.ui.control.UIAxes
        PRAnalysisPanel                 matlab.ui.container.Panel
        PRLoadGTButton                  matlab.ui.control.Button
        PRLoadGTMaskButton              matlab.ui.control.Button
        PRRunButton                     matlab.ui.control.Button
        DownSampleLabel                 matlab.ui.control.Label
        PRDownSampleDropDown            matlab.ui.control.DropDown
        PRSaveDataButton                matlab.ui.control.Button
        PRSensitivityPanel              matlab.ui.container.Panel
        PRSensitivityButton             matlab.ui.control.Button
        SensitivityLabel                matlab.ui.control.Label
        PRSensitivityDisplayDropDown    matlab.ui.control.DropDown
        PRSensitivitySaveButton         matlab.ui.control.Button
        AnalysisPanel                   matlab.ui.container.Panel
        AnalysisAutoSaveCheckBox        matlab.ui.control.CheckBox
        AnalysisButton                  matlab.ui.control.Button
        AnalysisSaveDataButton          matlab.ui.control.Button
        ScalingPanel                    matlab.ui.container.Panel
        FactorLabel                     matlab.ui.control.Label
        ScalingFactorEditField          matlab.ui.control.NumericEditField
        ScalingButton                   matlab.ui.control.Button
        ScalingValue                    matlab.ui.control.Label
        HLDPanel                        matlab.ui.container.Panel
        HLDSaveFigureCheckBox           matlab.ui.control.CheckBox
        HLDRunButton                    matlab.ui.control.Button
        HLDSaveDataButton               matlab.ui.control.Button
        CNN                             matlab.ui.container.TabGroup
        CNNPredictTab                   matlab.ui.container.Tab
        CNNRunButton                    matlab.ui.control.Button
        CNNSaveButton                   matlab.ui.control.Button
        CNNParallelCheckBox             matlab.ui.control.CheckBox
        EnsembleDropDownLabel           matlab.ui.control.Label
        CNNEnsembleDropDown             matlab.ui.control.DropDown
        PatchSeparationLabel            matlab.ui.control.Label
        CNNStrideSlider                 matlab.ui.control.Slider
        ModeLabel                       matlab.ui.control.Label
        CNNModeDropDown                 matlab.ui.control.DropDown
        CNNSelectLoad_2                 matlab.ui.control.Button
        CNNSelectButton                 matlab.ui.control.Button
        CNNPyramidCheckBox              matlab.ui.control.CheckBox
        CNNSelectTab                    matlab.ui.container.Tab
        CNNModelTable                   matlab.ui.control.Table
        CNNSelectSave                   matlab.ui.control.Button
        CNNSelectLoad                   matlab.ui.control.Button
        CNNSelectAll                    matlab.ui.control.Button
        CNNSelectNone                   matlab.ui.control.Button
        PetiolePanel                    matlab.ui.container.Panel
        PetioleSetButton                matlab.ui.control.Button
        PetioleUseCheckBox              matlab.ui.control.CheckBox
        SegmentationPanel               matlab.ui.container.Panel
        SegmentationThresholdSlider     matlab.ui.control.Slider
        ThresholdEditFieldLabel         matlab.ui.control.Label
        SegmentationThresholdEditField  matlab.ui.control.NumericEditField
        SegmentationButton              matlab.ui.control.Button
        SegmentationSaveButton          matlab.ui.control.Button
        FlowPanel                       matlab.ui.container.Panel
        CropPanel                       matlab.ui.container.Panel
        CropButton                      matlab.ui.control.Button
        CropSaveButton                  matlab.ui.control.Button
        CropSkipCheckBox                matlab.ui.control.CheckBox
        TLabel                          matlab.ui.control.Label
        CropTopEditField                matlab.ui.control.NumericEditField
        LLabel                          matlab.ui.control.Label
        CropLeftEditField               matlab.ui.control.NumericEditField
        WLabel                          matlab.ui.control.Label
        CropWidthEditField              matlab.ui.control.NumericEditField
        HLabel                          matlab.ui.control.Label
        CropHeightEditField             matlab.ui.control.NumericEditField
        CropSetRegionButton             matlab.ui.control.Button
        ChannelPanel                    matlab.ui.container.Panel
        ChannelButton                   matlab.ui.control.Button
        ChannelDropDownLabel            matlab.ui.control.Label
        ChannelDropDown                 matlab.ui.control.DropDown
        CLAHEPanel                      matlab.ui.container.Panel
        ClaheSaveButton                 matlab.ui.control.Button
        ClaheButton                     matlab.ui.control.Button
        ClaheSkipCheckBox               matlab.ui.control.CheckBox
        TileSizeLabel                   matlab.ui.control.Label
        ClaheTileSizeEditField          matlab.ui.control.NumericEditField
        ClipLimitLabel                  matlab.ui.control.Label
        ClaheClipLimitEditField         matlab.ui.control.NumericEditField
    end

    
    properties (Access = private)
        param % Structure for all parameters
        images % Structure to hold all the processed images
        var % structure for variables
        display % contains all the display settings
        ReferenceTable % contains all the metric names and units loaded from an xlsx spreadsheet
        hEdgeColorBar % handle to the colourbar
        hNodeColorBar % handle to the colourbar
        hPolygonColorBar % handle to the colourbar
        CNNSelectApp % handle to the CNN selector app
        PR_methods % Structure to contain all the PR images
        PR_results % Structure to contain the PR results
        PRGraphCMap % Colormap for PR graphs
        PR_sensitivity % Description
    end
    
    methods (Access = private)
        
        function funcLoadImage(app,target)
            app.StatusLabel.Text = 'Loading image. Please wait...';drawnow
            % load in the relevant image and also set a default full-size
            % mask. This get replaced if an actual mask has been saved
            switch target
                case 'original'
                    app.images.original = imread(fullfile(app.var.dirInitial,app.var.fname));
                    app.images.mask_original = true(size(app.images.original,1,2));
                    app.images.vein_original = false(size(app.images.original,1,2));
                case 'crop'
                    app.images.crop = imread(fullfile(app.var.dirInitial,app.var.fname));
                    app.images.mask = true(size(app.images.crop,1,2));
                    app.images.vein = false(size(app.images.crop,1,2));
                case 'CLAHE'
                    app.images.CLAHE = imread(fullfile(app.var.dirInitial,app.var.fname));
                    app.images.mask = true(size(app.images.CLAHE,1,2));
                    fname = strrep(app.var.fname,'_CLAHE','_crop');
                    app.images.crop = imread(fullfile(app.var.dirInitial,fname));
                case 'CNN'
                    % get all the CNN images
                    dir_struct = dir(app.var.dirInitial);% read in the directory structure
                    file_idx = ~[dir_struct.isdir];% strip out any directories
                    [sorted_names,~] = sortrows({dir_struct(file_idx).name}');
                    idx = contains(sorted_names,{'_cnn_';'_CNN_'}) & ~contains(sorted_names,'mask');
                    filenames = sorted_names(idx);
                    for iF = 1:numel(filenames)
                        temp = imread(fullfile(app.var.dirInitial,filenames{iF}));
                        app.images.(target)(:,:,iF) = double(max(temp,[],3))./255;
                    end
                    app.images.CNNmean = mean(app.images.CNN,3);
                    app.images.mask = true(size(app.images.CNNmean,1,2));
                    fname = strrep(app.var.fname,'_CNN*.png','_CLAHE.png');
                    app.images.CLAHE = imread(fullfile(app.var.dirInitial,fname));
                    fname = strrep(app.var.fname,'_CLAHE','_crop');
                    app.images.crop = imread(fullfile(app.var.dirInitial,fname));
                    fname = strrep(app.var.fname,'_CLAHE','_img');
                    app.images.original = imread(fullfile(app.var.dirInitial,fname));
                case 'vein'
                    [im, colormap] = imread(fullfile(app.var.dirInitial,app.var.fname_vein));
                    if ~isempty(colormap) && colormap(1,1) > colormap(end,1)
                        im = imcomplement(im);
                    end
                    app.images.vein_original = imbinarize(im);
                case 'mask'
                    [im, colormap] = imread(fullfile(app.var.dirInitial,app.var.fname_mask));
                    if ~isempty(colormap) && colormap(1,1) > colormap(end,1)
                        im = imcomplement(im);
                    end
                    app.images.mask_original = imbinarize(im);
                case 'GT_original'
                    [im, colormap] = imread(fullfile(app.var.dirInitial,app.var.fname_GT));
                    if ~isempty(colormap) && colormap(1,1) > colormap(end,1)
                        im = imcomplement(im);
                    end
                    if ndims(im)>1
                        im = max(im,[],3);
                    end
                    app.images.GT_original = imbinarize(im);
                case 'GT_mask_original'
                    [im, colormap] = imread(fullfile(app.var.dirInitial,app.var.fname_GT_mask));
                    if ~isempty(colormap) && colormap(1,1) > colormap(end,1)
                        im = imcomplement(im);
                    end
                    if ndims(im)>1
                        im = max(im,[],3);
                    end
                    app.images.GT_mask_original = imbinarize(im);
            end
            switch target
                case {'original','crop','CLAHE','CNN'}
                    if isfile(fullfile(app.var.dirInitial, [app.var.fnameCore '_mask.png']))
                        app.StatusLabel.Text = 'Loading mask image. Please wait...';drawnow
                        [temp, colormap] = imread(fullfile(app.var.dirInitial,[app.var.fnameCore '_mask.png']));
                        if ~isempty(colormap) && colormap(1,1) > colormap(end,1)
                            temp = imcomplement(temp);
                        end
                        switch target
                            case {'crop','CLAHE','CNN'}
                                app.images.mask = imbinarize(temp);
                            otherwise
                                app.images.mask_original = imbinarize(temp);
                        end
                        if ~contains(app.DisplayImageDropDown.Items,'mask')
                            app.DisplayImageDropDown.Items = [app.DisplayImageDropDown.Items,'mask'];
                        end
                    end
                    if isfile(fullfile(app.var.dirInitial, [app.var.fnameCore '_vein.png']))
                        app.StatusLabel.Text = 'Loading vein image. Please wait...';drawnow
                        [temp, colormap] = imread(fullfile(app.var.dirInitial,[app.var.fnameCore '_vein.png']));
                        if ~isempty(colormap) && colormap(1,1) > colormap(end,1)
                            temp = imcomplement(temp);
                        end
                        switch target
                            case {'crop','CLAHE','CNN'}
                                app.images.vein = imbinarize(temp);
                            otherwise
                                app.images.vein_original = imbinarize(temp);
                        end
                        if ~contains(app.DisplayImageDropDown.Items,'vein')
                            app.DisplayImageDropDown.Items = [app.DisplayImageDropDown.Items,'vein'];
                        end
                    elseif isfile(fullfile(app.var.dirInitial, [app.var.fnameCore '_big_mask.png']))
                        app.StatusLabel.Text = 'Loading vein image. Please wait...';drawnow
                        [temp, colormap] = imread(fullfile(app.var.dirInitial,[app.var.fnameCore '_big_mask.png']));
                        if ~isempty(colormap) && colormap(1,1) > colormap(end,1)
                            temp = imcomplement(temp);
                        end
                        switch target
                            case {'crop','CLAHE','CNN'}
                                app.images.vein = imbinarize(temp);
                            otherwise
                                app.images.vein_original = imbinarize(temp);
                        end
                        if ~contains(app.DisplayImageDropDown.Items,'vein')
                            app.DisplayImageDropDown.Items = [app.DisplayImageDropDown.Items,'vein'];
                        end
                    end
            end
            funcImageDisplay(app, target)
            app.StatusLabel.Text = ['Image ' app.var.fname ' loaded'];
        end
        
        function funcGetFileInfo(app,target)
            switch target
                case 'original'
                    [app.var.fname,app.var.dirInitial,~] = uigetfile({'*.png';'*.jpg';'*.tif'},'Select File');
                case 'crop'
                    [app.var.fname,app.var.dirInitial,~] = uigetfile({'*_crop.png'},'Select File');
                case 'CLAHE'
                    [app.var.fname,app.var.dirInitial,~] = uigetfile({'*_CLAHE.png'},'Select File');
                case 'CNN'
                    [app.var.fname,app.var.dirInitial,~] = uigetfile({'*_CNN_*.png'},'Select File');
                case 'vein'
                    [app.var.fname_vein,~,~] = uigetfile({'*_vein.png'},'Select File');
                case 'mask'
                    [app.var.fname_mask,~,~] = uigetfile({'*_mask.png'},'Select File');
                otherwise
                    app.var.fname = 0;
            end
            figure(app.LeafVeinGUI)
            if app.var.fname ~= 0
                cd(app.var.dirInitial)
                app.var.info = imfinfo(fullfile(app.var.dirInitial,app.var.fname));
                app.var.CropROI(1) = 1;
                app.var.CropROI(2) = 1;
                app.var.CropROI(3) = app.var.info.Width;
                app.var.CropROI(4) = app.var.info.Height;
                funcUpdateCropROI(app)
                app.ImageLabel.Text = app.var.fname;
            else
                app.StatusLabel.Text = 'No file selected';
            end
        end
        
        function funcGetCoreFilename(app)
            [~,name,~] = fileparts(app.var.fname);
            %             if contains(name, '_crop')
            %                 app.var.fnameCore = extractBefore(name,'_crop');
            %                 app.var.dirCNN = pwd;
            if contains(name, '_CLAHE')
                app.var.fnameCore = extractBefore(name,'_CLAHE');
                app.var.dirCNN = pwd;
            elseif contains(app.var.fname, '_CNN')
                app.var.fnameCore = extractBefore(name,'_CNN');
                app.var.dirCNN = pwd;
            elseif contains(app.var.fname, '_img') % compatibility with previous image code
                app.var.fnameCore = extractBefore(name,'_img');
                app.var.dirCNN = pwd;
            else
                app.var.fnameCore = name;
%                 if ~isfolder(name)
%                     mkdir(name)
%                 end
%                 app.var.dirCNN = fullfile(pwd,name);
                app.var.dirCNN = pwd;
            end
            cd(app.var.dirCNN)
        end
        
        function funcImageDisplay(app, target)
            if ~contains(app.DisplayImageDropDown.Items,target)
                app.DisplayImageDropDown.Items = [app.DisplayImageDropDown.Items,target];
            end
            app.DisplayImageDropDown.Value = target;
            hold(app.DisplayAxes,'off')
            switch target
                case 'CNNoverlay'
                    overlay = imoverlay(app.images.crop,bwperim(mean(app.images.CNN,3)>0.65),'r');
                    overlay = imoverlay(overlay,bwperim(mean(app.images.CNN,3)>0.5),'g');
                    overlay = imoverlay(overlay,bwperim(mean(app.images.CNN,3)>0.35),'b');
                    imshow(overlay,'Parent',app.DisplayAxes)
                case 'skeleton'
                    overlay = imoverlay(app.images.crop,app.images.skeleton,'g');
                    overlay = imoverlay(overlay,bwperim(mean(app.images.CNN,3)>0.65),'r');
                    imshow(overlay,'Parent',app.DisplayAxes)
                otherwise
                    if size(app.images.(target),3) == 1
                        if isinteger(app.images.(target))
                            im = ind2rgb(app.images.(target),gray(256));
                        else
                            im = ind2rgb(uint8(mat2gray(app.images.(target)).*255),gray(256));
                        end
                        imshow(im,[],'Parent',app.DisplayAxes);
                    elseif size(app.images.(target),3) == 3
                        imshow(app.images.(target),[],'Parent',app.DisplayAxes);
                    else
                        montage(app.images.(target),'Parent',app.DisplayAxes)
                    end
            end
            if strcmp(target,'original')
                axis(app.DisplayAxes,'tight')
            end
            app.DisplayAxes.XLabel.String = [];
            app.DisplayAxes.YLabel.String = [];
            app.DisplayAxes.Title.String = target;
            app.DisplayAxes.XTickMode = 'auto';
            app.StatusLabel.Text = ['Displaying image ' target];
        end
        
        function im_out = funcChannelSelect(app, im_in)
            %offset = app.param.ChannelOffset
            offset = 128;
            % process the channels
            switch app.param.Channel
                case 'mean'
                    temp = mean(im_in,3);
                case 'max'
                    temp = max(im_in,[],3);
                case 'min'
                    temp = min(im_in,[],3);
                case 'R'
                    temp = im_in(:,:,1);
                case 'G'
                    temp = im_in(:,:,2);
                case 'B'
                    temp = im_in(:,:,3);
                case 'R-G'
                    temp = imlincomb(0.5,im_in(:, :, 1),-0.5,im_in(:, :, 2),offset);
                case 'R-B'
                    temp = imlincomb(0.5,im_in(:, :, 1),-0.5,im_in(:, :, 3),offset);
                case 'G-R'
                    temp = imlincomb(0.5,im_in(:, :, 2),-0.5,im_in(:, :, 1),offset);
                case 'G-B'
                    temp = imlincomb(0.5,im_in(:, :, 2),-0.5,im_in(:, :, 3),offset);
                case 'B-R'
                    temp = imlincomb(0.5,im_in(:, :, 3),-0.5,im_in(:, :, 1),offset);
                case 'B-G'
                    temp = imlincomb(0.5,im_in(:, :, 3),-0.5,im_in(:, :, 2),offset);
                case 'R/G'
                    temp = (single(im_in(:, :, 1))+0.5) ./ single(im_in(:, :, 2)+0.5);
                case 'R/B'
                    temp = (single(im_in(:, :, 1))+0.5) ./ single(im_in(:, :, 3)+0.5);
                case 'G/R'
                    temp = (single(im_in(:, :, 2))+0.5) ./ single(im_in(:, :, 1)+0.5);
                case 'G/B'
                    temp = (single(im_in(:, :, 2))+0.5) ./ single(im_in(:, :, 3)+0.5);
                case 'B/R'
                    temp = (single(im_in(:, :, 3))+0.5) ./ single(im_in(:, :, 1)+0.5);
                case 'B/G'
                    temp = (single(im_in(:, :, 3))+0.5) ./ single(im_in(:, :, 2)+0.5);
                case 'R^2/G'
                    temp = (single(im_in(:, :, 1))+0.5).^2 ./ single(im_in(:, :, 2)+0.5);
                case 'R^2/B'
                    temp = (single(im_in(:, :, 1))+0.5).^2 ./ single(im_in(:, :, 3)+0.5);
                case 'G^2/R'
                    temp = (single(im_in(:, :, 2))+0.5).^2 ./ single(im_in(:, :, 1)+0.5);
                case 'G^2/B'
                    temp = (single(im_in(:, :, 2))+0.5).^2 ./ single(im_in(:, :, 3)+0.5);
                case 'B^2/R'
                    temp = (single(im_in(:, :, 3))+0.5).^2 ./ single(im_in(:, :, 1)+0.5);
                case 'B^2/G'
                    temp = (single(im_in(:, :, 3))+0.5).^2 ./ single(im_in(:, :, 2)+0.5);
            end
            % normalise between 0 and 1
            temp = mat2gray(temp);
            % rescale to 1%-99%
            temp = imadjust(temp);
            % convert to uint8
            im_out = uint8(255.*temp);
        end
        
        function funcCropSkip(app)
            if app.CropSkipCheckBox.Value
                app.CropButton.Enable = 'off';
                app.CropSaveButton.Enable = 'off';
                app.ClaheSkipCheckBox.Enable = 'on';
                app.images.crop = app.images.initial;
                app.images.mask = app.images.mask_original;
                if isfield(app.images,'vein_original')
                    app.images.vein = app.images.vein_original;
                end
                funcEnablePanels(app,'on',{'CLAHE'})
            else
                h = app.CropPanel.Children;
                set(h,'Enable','on');
                app.images.crop = [];
                funcEnablePanels(app,'off',{'CLAHE'})
            end
        end
        
        function funcCropSave(app)
            if isfield(app.images,'crop')
                cd(app.var.dirCNN)
                [file,path] = uiputfile('*.png','Save cropped image',fullfile(app.var.dirCNN,[app.var.fnameCore '_crop']));
                imwrite(app.images.crop,fullfile(path,file),'png')
            end
        end
        
        function funcCropMaskSave(app)
            if isfield(app.images,'mask')
                cd(app.var.dirCNN)
                imwrite(im2uint8(app.images.mask),[app.var.fnameCore '_crop_mask.png'],'png');
            end
        end
        
        function  funcCropVeinSave(app)
            if isfield(app.images, 'vein')
                cd(app.var.dirCNN)
                imwrite(im2uint8(app.images.vein),[app.var.fnameCore '_crop_vein.png'],'png');
            end
        end
        
        function funcClaheSkip(app)
            if app.ClaheSkipCheckBox.Value
                h = app.CLAHEPanel.Children;
                set(h,'Enable','off');
                app.ClaheSkipCheckBox.Enable = 'on';
                app.images.CLAHE = app.images.crop;
                funcEnablePanels(app,'on',{'Scaling'})
            else
                h = app.CLAHEPanel.Children;
                set(h,'Enable','on');
                app.images.CLAHE = [];
                funcEnablePanels(app,'off',{'Scaling'})
            end
        end
        
        function funcClaheSave(app)
            cd(app.var.dirCNN)
            [file,path] = uiputfile('*.png','Save CLAHE image',fullfile(app.var.dirCNN,[app.var.fnameCore '_CLAHE']));
            imwrite(app.images.CLAHE,fullfile(path,file),'png')
        end
        
        function [width] = fnc_granulometry(app)
            r = 45;
            im_in = imresize(imcomplement(mat2gray(app.images.crop)),0.5);
            bw = imresize(app.images.CNNfw,0.5);
            im = roifilt2(fspecial('disk',r),padarray(im_in,[r r],'both','replicate'),padarray(bw,[r r],'both','replicate'));
            im = im(r+1:end-r,r+1:end-r);
            s = 0:100;
            imo = zeros([size(bw),length(s)], 'single');
            im = imtophat(im,strel('disk',101));
            for i=1:length(s)
                imo(:,:,i) = imopen(mat2gray(im),strel('disk',s(i)));
            end
            width = imresize(sum(imo,3),size(app.images.CNNfw));
        end
        
        function [CNNfw] = funcCNNFullWidth(app)
            threshold = app.SegmentationThresholdEditField.Value;
            mn = imextendedmin(app.images.CNNmean,0.15);
            im = imimposemin(app.images.CNNmean,mn);
            % convert to a binary image
            CNNfw = imbinarize(im,threshold);
            % smooth the binary image
            CNNfw = medfilt2(CNNfw,[3 3]);
            % add in any manual veins
            if isfield(app.images,'vein')
                CNNfw = CNNfw | app.images.vein;
            end
            CNNfw = CNNfw & app.images.mask;
            % keep the connected component
            CNNfw = bwareafilt(CNNfw,1);
            % tidy up the petiole region so that the skeleton will end up
            % originating from a single node
            if isfield(app.images,'petiole') && ~isempty(app.images.petiole)
                % trim the petiole to match the CNNfw
                app.images.petiole = app.images.petiole & CNNfw;
                % remove the petiole from the skeleton
                CNNfw(app.images.petiole) = 0;
                % keep the largest skeleton component
                CNNfw = bwareafilt(CNNfw,1);
                % add back in the petiole
                CNNfw = CNNfw | app.images.petiole;
            end
        end
        
        function [skVein,skLoop,skTree,petiole,petiole_node,perimeter_node] = funcPetioleSegment(app, sk, skLoop, skTree, petiole)
            % The petiole needs to be processed differently. It connects to the skeleton where they intersect at the edges,
            % but does not have an internal structure. An assumption has to be made about
            % the behaviour of the network within each petiole. The simplest assumption
            % is to place a node at the centre of the petiole and to connect all the
            % perimeter nodes to the central node. Further assumptions are needed to
            % assign weights to the petiole edges in subsequent processing. Typically
            % they are given the euclidean length and the maximum value of width.
            petiole_node = false(size(sk));
            petiole = petiole & app.images.CNNfw;
            % get the feature map from the distance transform of
            % the petiole to find the nearest petiole pixel to each end of the
            % skeleton
            [~,FM] = bwdist(petiole,'Euclidean');
            % remove the petiole from the skeleton
            sk(petiole) = 0;
            % keep the largest skeleton component
            sk = bwareafilt(sk,1);
            skLoop = sk & skLoop;
            skTree = sk & skTree;
            % expand the petiole by one pixel to ensure overlap
            % with the remaining cords
            petiole = imdilate(petiole,ones(3));
            % find the initial endpoints as the overlap
            ep = petiole & sk;
            % some of these may include adjacent points from the
            % skeleton lying along the edge of the petiole. The
            % relevant points to retain will be endpoints or
            % branchpoints from the original skeleton.
            ep = ep & (bwmorph(sk,'endpoints') | bwmorph(sk,'branchpoints'));
            % find the nearest pixel in the petiole to be the true
            % endpoint
            tep = FM(ep);
            Pnodes = zeros(size(sk));
            Pnodes(tep) = 1;
            % add the new endpoints to the skeleton and to the
            % petiole
            sk = sk | Pnodes;
            %             % in case there are still two adjacent pixels in
            %             % pnodes, erode them to a single point now that they
            %             % have been included in the skeleton
            %             % Pnodes = bwmorph(Pnodes,'Shrink','Inf');
            petiole = petiole | Pnodes;
            % tidy-up any remaining single pixel skeleton points
            sk = bwmorph(sk,'clean');
            petiole = bwmorph(petiole,'clean');
            % give each petiole a unique label
            L = single(bwlabel(petiole));
            % the petiole node is the centre of the petiole
            stats = regionprops(L,'Centroid');
            pos = round(cat(1,stats.Centroid));
            idx = sub2ind(size(sk),pos(:,2),pos(:,1));
            % the label is the index of the region
            petiole_node(idx) = 1;
            % give the perimeter nodes the label identity of the parent petiole
            perimeter_node = Pnodes & sk;
            skVein = sk | Pnodes;
            %             sk_perimeter = bwperim(petiole) & ~(sk | imdilate(perimeter_node,ones(3)));
        end
        
        
        function [skFinal, skLoop, skTree, skTruncEdge] = fnc_skeleton(app)
            %             % retrieve the mask image if set
            %             if isfield(app.images,'crop_mask')
            %                 mask = app.images.crop_mask;
            %             elseif isfield(app.images,'mask')
            mask = app.images.mask;
            %             else
            %                 mask = true(size(app.images.CNNfw));
            %             end
            %FW = app.images.CNNfw | ~mask;
            FW = app.images.CNNfw & mask;
            % pad the image with ones to make connected loops around the
            % boundary initially. These will be removed later.
            bw_cnn = padarray(FW,[1 1],1,'both');
            % calculate the distance transform as the input to a watershed segmentation
            D = bwdist(~bw_cnn,'Euclidean');
            W = watershed(D,4);
            % get the watershed skeleton comprising only loops
            skLoop = W == 0;
            % thin to a single pixel skeleton
            skLoop = bwmorph(skLoop,'thin',Inf);
            % find any isolated loops
            skRing = skLoop & ~bwareafilt(skLoop,1);
            % exclude rings touching the border
            skRing = imclearborder(skRing);
            if any(skRing(:))
                % fill the loops
                skLoopFill = imfill(skRing,'holes');
                % punch the loop skeleton back out. This ensure two touching loops are
                % treated separately
                skLoopFill = skLoopFill & ~skRing;
                % erode to a single point in the middle of the loop
                skLoopPoints = bwulterode(skLoopFill);
                % use these points to fill the loop in the binary image
                bw_cnn_fill = imfill(bw_cnn,find(skLoopPoints));
                % thin the binary image to a single pixel skeleton
                sk = bwmorph(bw_cnn_fill,'thin',Inf);
            else
                sk =  bwmorph(bw_cnn,'thin',Inf);
            end
            % only keep the largest connected component
            sk = bwareafilt(sk,1);
            % find tree regions in the thinned skeleton as the difference after spur
            % removal
            skSp = bwmorph(sk,'spur',Inf);
            skTree = xor(sk,skSp);
            % remove single pixel spurs now present as isolated points
            skTree = bwmorph(skTree,'clean');
            if any(skRing(:))
                % punch out the isolated loops
                skTree = skTree & ~skLoopFill;
                % add back in the watershed loop
                skTree = skTree | skRing;
            end
            % get the endpoints of the thinned skeleton, representing the free end
            % veins
            epsk = bwmorph(sk,'endpoints');
            % find the endpoints at the root of the tree
            epskTree = bwmorph(skTree,'endpoints');
            epskTree = xor(epsk,epskTree);
            % only work with endpoints that are not already connected to
            % the watershed skeleton
            connected = bwareafilt(epskTree | skLoop,1);
            epskTree = epskTree & ~connected;
            % get the feature map from the watershed skeleton to find the nearest pixel
            % to connect to
            [~,skW_idx] = bwdist(skLoop);
            % get the pixel co-ordinates for the nearest point on the skeleton
            [y1,x1] = ind2sub(size(skRing),skW_idx(epskTree));
            % get the pixel co-ordinates for the endpoints
            [y2,x2] = find(epskTree);
            % use the bresenham algorithm to find the best pixel line
            [x,y] = arrayfun(@(x1,y1,x2,y2) bresenham(x1,y1,x2,y2),x1,y1,x2,y2,'uniformoutput',0);
            % get the index of the line points
            P = cellfun(@(x,y) sub2ind(size(skLoop),y,x),x,y,'UniformOutput',0);
            % add the lines into the tree skeleton
            skTree(cat(1,P{:})) = 1;
            if any(skRing(:))
                % remove any parts of the skeleton overlapping the loops
                skTree(skLoopFill) = 0;
                % remove the rings from the loop skeleton as they will be included in
                % the tree skeleton
                skLoop(skRing) = 0;
            end
            % add the watershed skeleton back in
            skFinal = skTree | skLoop;
            % remove the padding
            skFinal = skFinal(2:end-1,2:end-1);
            skLoop = skLoop(2:end-1,2:end-1);
            skTree = skTree(2:end-1,2:end-1);
            % remove edges touching the mask. Fist, separate out the edges
            % by dilating the nodes and removing them
            sk = skFinal;
            bp = bwmorph(skFinal,'branchpoints');
            sk(imdilate(bp,ones(3))) = 0;
            % get the perimeter of the mask and the perimeter of the image.
            % use these pixel indices to select connected edges touching
            % these boundaries
            B = bwperim(true(size(mask)));
            [r,c] = find(~mask | B);
            skTouching = bwselect(sk|~mask,c,r);
            % get the truncated skeleton. Note that this includes a single
            % pixel for any edge touching the boundary
            skTrunc = skFinal &~ skTouching;
            skTruncEdge = skTrunc &~ bwskel(skTrunc,'MinBranchLength',1) &~ bwmorph(skTrunc,'branchpoints');
            % keep the largest connected component
            skFinal = bwareafilt(skFinal,1);
            skLoop = skLoop & skFinal;
            skTree = skTree & skFinal;
        end
        
        function [edgelist,edgeim] = fnc_resolve_self_loops(app)
            edgelist = app.var.edgelist;
            edgeim = app.images.edgeim;
            D_idx = 1;
            [nY,nX] = size(edgeim);
            while ~isempty(D_idx)
                % F_idx is the linear index to the first pixel in each edge array
                F_idx = cellfun(@(x) sub2ind([nY,nX],x(1,1),x(1,2)),edgelist);
                % L_idx is the linear index to the last pixel in each edge array
                L_idx = cellfun(@(x) sub2ind([nY,nX],x(end,1),x(end,2)),edgelist);
                % check if nodei and nodej are equal
                D_idx = find(F_idx==L_idx);
                % See if there are any loops
                if ~isempty(D_idx)
                    % Go through each loop and split it in two if it is long enough,
                    % adding an extra edge at the end of the edgelist. If the duplicate
                    % edge is less than 10 pixels long, record the index and then delete it
                    % once all dupicates have been resolved after the while loop has
                    % finished (to make sure the indexing doesn't change in the main
                    % loop)
                    remove_idx = [];
                    %h = waitbar(0,['Resolving loops for timepoint ' num2str(iN) '. Please wait...']);
                    for loop = 1:length(D_idx)
                        % get the pixels for the first duplicate edge
                        pix = edgelist{D_idx(loop)};
                        % find the middle of the edge
                        midnode = floor(length(pix)./2);
                        if midnode > 5
                            % replace this edge with the first half of the original edge
                            edgelist{D_idx(loop)} = pix(1:midnode,:);
                            % find the current number of edges at this time point
                            n = length(edgelist)+1;
                            % add a new edge at the end of the list with the second
                            % half of the edge, including an overlap
                            edgelist{n} = pix(midnode:end,:);
                            % get the pixel co-ordinates for the new edge
                            idx = sub2ind([nY,nX],edgelist{D_idx(loop)}(:,1),edgelist{D_idx(loop)}(:,2));
                            % write in the new edge to the edgeim
                            edgeim(idx) = n;
                        else
                            % store the index of the edge to be deleted, but don't
                            % delete yet as the indexing will alter for the other edges
                            remove_idx(end+1) = D_idx(loop);
                        end
                    end
                    % remove the duplicate edges
                    if ~isempty(remove_idx)
                        edgelist(remove_idx) = [];
                    end
                end
            end
        end
        
        function [edgelist,edgeim] = fnc_resolve_duplicates(app)
            edgelist = app.var.edgelist;
            edgeim = app.images.edgeim;
            D_idx = 1;
            iteration = 0;
            [nY,nX] = size(edgeim);
            while ~isempty(D_idx)
                iteration = iteration+1;
                % F_idx is the linear index to the first pixel in each edge array
                F_idx = cellfun(@(x) sub2ind([nY,nX],x(1,1),x(1,2)),edgelist);
                % L_idx is the linear index to the last pixel in each edge array
                L_idx = cellfun(@(x) sub2ind([nY,nX],x(end,1),x(end,2)),edgelist);
                % combine to give the endpoints (EP) of the edge, i.e. nodei and nodej
                EP = [F_idx' L_idx'];
                % Order the rows of nodei and nodej to be smallest first. This makes sure
                % that duplicates are found whichever way round the nodes were
                % initially, but doesn't alter the ordering of the edges
                EP_ordered = [min(EP, [],2) max(EP,[],2)];
                % find the unique rows (EP_unique) with the corresponding index into
                % the original node pairs (ia). The ia index skips any row
                % that is duplicated. The ic index picks the value from EP_unique to
                % recreate the original array. We can use this index to find the index
                % of the first duplicate node.
                [~,ia,ic] = unique(EP_ordered, 'rows');
                % Compare the ia index with a full list of node IDs to give the index
                % of the edges that were skipped
                D_idx = setdiff(1:length(EP),ia);
                % See if there are any duplicate edges
                if ~isempty(D_idx)
                    % Go through each loop and split it in two if it is long enough,
                    % adding an extra edge at the end of the edgelist. If the duplicate
                    % edge is only one pixel long, record the index and then delete it
                    % once all dupicates have been resolved after the while loop has
                    % finished (to make sure the indexing doesn't change in the main
                    % loop)
                    remove_idx = [];
                    %h = waitbar(0,['Resolving loops for timepoint ' num2str(iN) '. Please wait...']);
                    for loop = 1:length(D_idx)
                        % get the pixels for the first duplicate edge
                        pix = edgelist{D_idx(loop)};
                        % find the middle of the edge
                        midnode = floor(length(pix)./2);
                        if midnode > 1
                            % replace this edge with the first half of the original edge
                            edgelist{D_idx(loop)} = pix(1:midnode,:);
                            % find the current number of edges at this time point
                            n = length(edgelist)+1;
                            % add a new edge at the end of the list with the second
                            % half of the edge, including an overlap
                            edgelist{n} = pix(midnode:end,:);
                            % get the pixel co-ordinates for the new edge
                            idx = sub2ind([nY,nX],edgelist{D_idx(loop)}(:,1),edgelist{D_idx(loop)}(:,2));
                            % write in the new edge to the edgeim
                            edgeim(idx) = n;
                        else
                            % store the index of the edge to be deleted, but don't
                            % delete yet as the indexing will alter for the other edges
                            remove_idx(end+1) = D_idx(loop);
                            % make sure the pixel has the value of the original edge in the
                            % edge image
                            OE = ic(D_idx(loop));
                            idx = sub2ind([nY,nX],edgelist{OE}(:,1),edgelist{OE}(:,2));
                            % write in the old edge back into the edgeim
                            edgeim(idx) = OE;
                        end
                    end
                    % remove the duplicate edges
                    if ~isempty(remove_idx)
                        % disp('removing duplicate edges')
                        edgelist(remove_idx) = [];
                    end
                end
            end
        end
        
        function [G_veins,edgelist] = fnc_weighted_graph(app)
            W_pixels = app.images.WidthPixels;
            edgelist = app.var.edgelist;
            skTree = app.images.skTree;
            [nY,nX] = size(W_pixels);
            % I_idx is the linear index to the first pixel in each edge
            I_idx = cellfun(@(x) sub2ind([nY nX],x(1,1),x(1,2)),edgelist);
            % J_idx is the linear index to the last pixel in each edge array
            J_idx = cellfun(@(x) sub2ind([nY nX],x(end,1),x(end,2)),edgelist);
            % P_idx is the linear index of each pixel in the cell array of edges
            P_idx = cellfun(@(x) sub2ind([nY nX],x(:,1),x(:,2)),edgelist,'UniformOutput',0);
            % N_pix is the number of pixels in the edge list
            N_pix = cellfun(@(x) numel(x(:,1)), edgelist);
            % M_pix is the mid pixel
            M_pix = cellfun(@(x,y) x(round(y/2)), P_idx, num2cell(N_pix));
            % Get various metrics associated with each edge from the various processing
            % stages. Initially the values are returned as cell arrays with individual
            % values for each pixel. These are then summarised to give a single value
            % for each edge for that metric.
            % Get the length as the difference in euclidean distance between pixels
            L_val = cellfun(@(x) hypot(diff(x(:,1)),diff(x(:,2))),edgelist,'UniformOutput',0);
            L_sum = cellfun(@sum, L_val);
            % get the orientation using atan2
            O_val = cellfun(@(x) atan2(x(1,1)-x(end,1),x(1,2)-x(end,2)),edgelist,'UniformOutput',1);
            % Get the width
            W_val = cellfun(@(x) W_pixels(x),P_idx,'UniformOutput',0);
            W_mean = cellfun(@mean, W_val);
            % Set the edge ID
            nEdges = length(I_idx);
            E_ID = (1:nEdges)';
            % set all edges ('E') to belong to a loop ('L') initially
            EType = repmat({'EL'},nEdges,1);
            % Set any edges that belong to a tree with 'T'. Use M_pix to sample the
            % middle point in the skeleton
            EType(skTree(M_pix)) = {'ET'};
            % set any edges touching the boundary to 'B'. Use the
            % skTruncEdge to identify these edges.
            TruncEdge = find(app.images.skTruncEdge);
            B_idx = cellfun(@(x) any(ismember(x,TruncEdge)), P_idx, 'UniformOutput',1);
            EType(B_idx) = {'EB'};
            % set the edge weight to the average width
            E_weight = W_mean;
            % initially the nodes are empty
            nodei = zeros(nEdges,1);
            nodej = zeros(nEdges,1);
            node_idx = unique([I_idx'; J_idx']);
            % combine the edge metrics into an EdgeTable
            names = {'EndNodes', 'node_Idx', 'vein_ID', 'Type', 'Weight', ...
                'Width_initial',  ...
                'Length_initial', ...
                'Orientation_initial', ...
                'N_pix', 'M_pix'};
            
            EdgeTable = table([nodei, nodej], [I_idx', J_idx'], E_ID, EType, E_weight', ...
                W_mean', ...
                L_sum', ...
                O_val', ...
                N_pix', M_pix', ...
                'VariableNames', names);
            % now collect all the node indices to construct a NodeTable. Get the index
            % of all the nodes present in i or j
            node_ID = (1:length(node_idx))';
            node_type = repmat({'E'},length(node_idx),1);
            % get the coordinates of each node
            [y_pix,x_pix] = ind2sub([nY nX],node_idx);
            % Construct the full NodeTable (note at this point the co-ordinates are all
            % in pixels
            NodeTable = table(node_ID, node_idx, node_type, x_pix, y_pix, ...
                'VariableNames',{'node_ID' 'node_Idx' 'node_Type' 'node_X_pix' 'node_Y_pix'});
            % Assemble the MatLab graph object
            % Convert the linear node index into a node ID
            [~,EdgeTable{:,'EndNodes'}(:,1)] = ismember(EdgeTable{:,'node_Idx'}(:,1), node_idx);
            [~,EdgeTable{:,'EndNodes'}(:,2)] = ismember(EdgeTable{:,'node_Idx'}(:,2), node_idx);
            G_veins = graph(EdgeTable, NodeTable);
            %             % The graph automatically sorts edge rows based on node i. The
            %             % original edge index is kept in vein_ID. Reoder the edgelist to match the
            %             % graph order, including nodei <-> node j
            %             edgelist = edgelist(G_veins.Edges.vein_ID);
            %             % I_idx is the linear index to the first pixel in each edge array
            %             I_idx = cellfun(@(x) sub2ind([nY nX],x(1,1),x(1,2)),edgelist);
            %             % Find which EndNodes in the edge table do not start with nodei, and have
            %             % therefore been re-ordered
            %             switch_idx = G_veins.Nodes{G_veins.Edges.EndNodes(:,1),'node_Idx'} ~= I_idx';
            %             % flip the order of pixels in the edgelist to match
            %             edgelist(switch_idx) = cellfun(@(x) flipud(x), edgelist(switch_idx), 'UniformOutput',0);
        end
        
        function [G_veins,edgelist_center] = fnc_refine_width(~,G_veins,edgelist,im,im_cnn,W_pixels)
            [nY,nX] = size(W_pixels);
            % Calculate the node degree
            G_veins.Nodes.node_Degree = degree(G_veins);
            % Get the edges from the graph
            nN = numnodes(G_veins);
            [i,j] = findedge(G_veins);
            % Construct a sparse, symmetric weighted adjacency matrix from the graph
            % widths. The values have to be in double precision for
            % sparse to work.
            width = G_veins.Edges.Width_initial;
            A = sparse(i,j,double(width),nN,nN);
            % make the matrix symmetric
            A = A + A.' - diag(diag(A));
            % Calculate the major edge width and the edge index incident at the node
            % (initially in double precision inherited from the sparse matrix
            [edge_D0, D0_idx] = max(A,[],2); % column format to match the table later
            edge_D0 = full(edge_D0);
            G_veins.Nodes.node_D0 = edge_D0;
            % Calculate the width of the penultimate edge width by removing the max and
            % recalculating the max for the remainder
            rows = (1:size(A,1))';
            D1_idx = sub2ind(size(A),rows,D0_idx);
            AD1 = A;
            AD1(D1_idx) = 0;
            G_veins.Nodes.node_D1 = full(max(AD1,[],2));
            % Calculate the minimum edge width. To calculate the initial min of a
            % sparse matrix, take the negative and then add back the maximum
            [nnzr, nnzc] = find(A);
            B = -A + sparse(nnzr,nnzc,max(edge_D0),size(A,1),size(A,2));
            mn = max(B,[],2);
            G_veins.Nodes.node_D2 = -(mn-max(edge_D0));
            % get the degree for nodei and nodej
            NDegI = G_veins.Nodes{G_veins.Edges.EndNodes(:,1),'node_Degree'};
            NDegJ = G_veins.Nodes{G_veins.Edges.EndNodes(:,2),'node_Degree'};
            % get the maximum edge weight for nodei and nodej
            ND0I = G_veins.Nodes{G_veins.Edges.EndNodes(:,1),'node_D0'};
            ND0J = G_veins.Nodes{G_veins.Edges.EndNodes(:,2),'node_D0'};
            % Get the penultimate edge weight for nodei and nodej
            ND1I = G_veins.Nodes{G_veins.Edges.EndNodes(:,1),'node_D1'};
            ND1J = G_veins.Nodes{G_veins.Edges.EndNodes(:,2),'node_D1'};
            % Get the number of pixels in the edge
            N_pix = G_veins.Edges.N_pix;
            % Get the length as the difference in euclidean distance between pixels
            L_val = cellfun(@(x) hypot(diff(x(:,1)),diff(x(:,2))),edgelist,'UniformOutput',0);
            % L_sum is the cumulative length of the edge starting from nodei
            L_sum = cellfun(@(x) cumsum(x),L_val,'UniformOutput',0);
            % Initially use half the average (NAveI) or maximum (ND0I) at
            % the node as the overlap distance to avoid. Limit the index to
            % 50% of the number of pixels minus 1 (to ensure that there is
            % a minimum length of edge remaining). Find where the index
            % where cumulative length equals or exceeeds half the overlap
            % width
            L_idx = cellfun(@(x,y) find(x>=y/2,1),L_sum,num2cell(ND0I)','UniformOutput',0);
            % empty cells means the overlap distance is longer than the
            % edge. Set these values to be infinite initially, but will be
            % set to half the number of pixels
            L_max = cellfun('isempty',L_idx);
            L_idx(L_max) = {inf};
            % constrain the maximum offset to be half the number of pixels - 1
            % for edges with overlap to make sure there is some edge left.
            first = min(cell2mat(L_idx)',max(round(N_pix.*0.5)-1,1));
            first(first<1) = 1;
            % If the edge width is equal to or greater than the penultimate width of
            % all edges at the node i, or the node degree is 2 or less, then keep the
            % full length of the edge. i.e. set the starting index to 1;
            idx =  width >= ND1I | NDegI <= 2;
            %idx =  NDegI <= 2 & NPerimI == 0;
            first(idx) = 1;
            % Repeat for node j, using half the largest edge width as an
            % index into the array from a minimum of some fraction of the
            % number of pixels + 1 (to ensure that there is a minimum edge
            % length remaining). Switch the order to set nodej at the start
            % for every edge
            L_val = cellfun(@(x) flipud(x),L_val,'UniformOutput',0);
            L_sum = cellfun(@(x) cumsum(x),L_val,'UniformOutput',0);
            % use half of the average (NAveJ) or maximum (ND0J) at the
            % node as the overlap distance to avoid. L_idx is the index
            % into the flipped edgelist.
            L_idx = cellfun(@(x,y) find(x>=y/2,1),L_sum,num2cell(ND0J)','UniformOutput',0);
            % if empty then the edge is within the overlap. Set the index
            % to be inf initially, it will be set to half the width in the
            % next step
            L_max = cellfun('isempty',L_idx);
            L_idx(L_max) = {inf};
            % set the maximum index as N_pix-1 and subtract from N_pix
            last = N_pix - min(cell2mat(L_idx)',max(round(N_pix.*0.5)-1,1));
            last(last<1) = 1;
            last(last>N_pix) = N_pix(last>N_pix);
            % If the edge width is equal to or greater than the penultimate width of
            % all edges at the node i, or the node degree is 2 or less, then keep the
            % full length of the edge. i.e. set the final index to N_pix;
            idx =  width >= ND1J | NDegJ <= 2;
            %idx =  NDegJ <= 2 & NPerimJ == 0;
            % Combine the sets of indices
            last(idx) = N_pix(idx);
            % now collect the linear index of the pixels representing the center of the
            % edge using the first and last indices
            edgelist_center = cellfun(@(x,f1,l1) [x(f1:l1,1),x(f1:l1,2)],edgelist,num2cell(first'),num2cell(last'),'UniformOutput',0);
            % Convert the selected pixels to a linear index
            P_idx = cellfun(@(x) sub2ind([nY nX],x(:,1),x(:,2)),edgelist_center,'UniformOutput',0);
            % Get the intensity for each pixel from the original image using P_idx
            im = single(im);
            I_val = cellfun(@(x) im(x),P_idx,'UniformOutput',0);
            I_mean = cellfun(@mean, I_val);
            I_std = cellfun(@std, I_val);
            G_veins.Edges.Intensity = I_mean';
            G_veins.Edges.Intensity_cv = I_std'./I_mean';
            % Get the probability for each pixel from the cnn image using P_idx
            im_cnn = single(im_cnn);
            P_val = cellfun(@(x) im_cnn(x),P_idx,'UniformOutput',0);
            P_mean = cellfun(@mean, P_val);
            P_std = cellfun(@std, P_val);
            G_veins.Edges.Probability = P_mean';
            G_veins.Edges.Probability_cv = P_std'./P_mean';
            % Get the length as the difference in euclidean distance between pixels
            CL_val = cellfun(@(x) hypot(diff(x(:,1)),diff(x(:,2))),edgelist_center,'UniformOutput',0);
            CL_sum = cellfun(@sum, CL_val);
            % Get the width from the integral granulometry
            CW_val = cellfun(@(x) W_pixels(x),P_idx,'UniformOutput',0);
            CW_mean = cellfun(@mean, CW_val);
            CW_std = cellfun(@std, CW_val);
            CW_cv = CW_std./CW_mean;
            % get the average orientation of the center-width region. These
            % will tend to be in the range pi/2 to -pi/2 because nodei for all
            % edges is likely to be to the left of node j because of the
            % re-ordering in the graph.
            [midr, midc] = ind2sub([nY,nX],G_veins.Edges.M_pix);
            mid = mat2cell([midr midc],ones(length(midr),1),2)';
            % edgelist and mid co-ordinates are in [r,c] format i.e. [y,x]
            % mid - i for ij and mid - j for ji
            % flip y-axis so positive is counterclockwise from horizontal
            CO_ij = cellfun(@(m,i) atan2(-(m(1,1)-i(1,1)), m(1,2)-i(1,2)),mid,edgelist,'UniformOutput',1);
            CO_ji = cellfun(@(m,j) atan2(-(m(1,1)-j(end,1)), m(1,2)-j(end,2)),mid,edgelist,'UniformOutput',1);
            % update the edgetable
            G_veins.Edges.Length = CL_sum';
            G_veins.Edges.Width = CW_mean';
            G_veins.Edges.Width_cv = CW_cv';
            G_veins.Edges.Or_ij = rad2deg(CO_ij');
            G_veins.Edges.Or_ji = rad2deg(CO_ji');
            % calculate the tortuosity as the ratio of the distance between the end
            % points (chord length) and the total length
            CL_chord = cellfun(@(x) hypot(x(1,1)-x(end,1),x(1,2)-x(end,2)),edgelist_center);
            G_veins.Edges.Tortuosity = CL_sum'./CL_chord';
            G_veins.Edges.Tortuosity(isinf(G_veins.Edges.Tortuosity)) = 1;
            % calculate the center-weighted radius
            R = G_veins.Edges.Width/2;
            % calculate the center-weighted area
            G_veins.Edges.Area = pi.*(R.^2);
            % calculate the center-weighted surface area
            G_veins.Edges.SurfaceArea = pi.*2.*R.*G_veins.Edges.Length ;
            % calculate the center-weighted volume
            G_veins.Edges.Volume = G_veins.Edges.Length.*G_veins.Edges.Area;
            % calculate the center-weighted resistance to flow for area or Poiseuille flow
            G_veins.Edges.Resistance_2 = G_veins.Edges.Length./(R.^2);
            G_veins.Edges.Resistance_4 = G_veins.Edges.Length./(R.^4);
            % find the petiole edges and set them to the max
            F_idx = find(strcmp(G_veins.Edges.Type,'F'));
            G_veins.Edges.Width(F_idx) = max(CW_mean);
            G_veins.Edges.Width_cv(F_idx) = 0;
            % recalculate the node parameters from the center-width estimate
            rows = (1:size(A,1))';
            % calculate a symmetrical weighted adjacency matrix for the center width
            A = sparse(i,j,double(G_veins.Edges.Width),nN,nN);
            A = A + A.' - diag(diag(A));
            G_veins.Nodes.node_Strength = full(sum(A,2));
            G_veins.Nodes.node_Average = G_veins.Nodes.node_Strength./G_veins.Nodes.node_Degree;
            % calculate a weighted adjacency matrix for orientation for
            % edges ij and ji
            Oij = sparse(i,j,double(G_veins.Edges.Or_ij),nN,nN);
            Oji = sparse(j,i,double(G_veins.Edges.Or_ji),nN,nN);
            O = Oij + Oji - diag(diag(Oij));
            % Calculate the initial max edge width incident at the node (initially
            % in double precision)
            [edge_D0, mx_idx] = max(A,[],2);
            edge_D0 = full(edge_D0);
            G_veins.Nodes.node_D0 = edge_D0;
            D0_idx = sub2ind(size(A),rows,mx_idx);
            % get the orientation of the largest edge from the orientation adjacency
            % matrix using the max_idx index
            G_veins.Nodes.node_OD0 = full(O(D0_idx));
            % Calculate the minimum edge width. To calculate the min of a
            % sparse matrix, take the negative and then add back the maximum. This
            % avoids finding a full array of zeros!
            MaxA = sparse(i,j,max(edge_D0),nN,nN);
            MaxA = MaxA + MaxA.' - diag(diag(MaxA));
            B = -A + MaxA;
            [edge_D2, mn_idx] = max(B,[],2);
            D2_idx = sub2ind(size(A),rows,mn_idx);
            
            % Calculate the width of the penultimate edge width by removing the max
            % values from the adjacency matrix and recalculating max for the remainder
            AD1 = A;
            AD1(D0_idx) = 0;
            [edge_D1, md_idx] = max(AD1,[],2);
            D1_idx = sub2ind(size(A),rows,md_idx);
            G_veins.Nodes.node_D1 = full(edge_D1);
            % get the orientation of the penultimate edge from the orientation
            % adjacency matrix using the pn_idx
            G_veins.Nodes.node_OD1 = full(O(D1_idx));
            % Add the D2 edge to table here. convert back to absolute positive value by negating and adding back the max
            G_veins.Nodes.node_D2 = -full(edge_D2)+max(edge_D0);
            % get the orentation of the weakest edge from the orientation adjacency
            % matric and the D2_idx
            G_veins.Nodes.node_OD2 = full(O(D2_idx));
            % tidy up results for k=1 nodes
            idx = G_veins.Nodes.node_Degree == 1;
            G_veins.Nodes.node_D1(idx) = nan;
            G_veins.Nodes.node_D2(idx) = nan;
            G_veins.Nodes.node_OD1(idx) = nan;
            G_veins.Nodes.node_OD2(idx) = nan;
            %             % calculate the ratios for the radii of smallest to largest and
            %             % intermediate to largest
            %             G_veins.Nodes.node_D2_D0 = G_veins.Nodes.node_D2./G_veins.Nodes.node_D0;
            %             G_veins.Nodes.node_D1_D0 = G_veins.Nodes.node_D1./G_veins.Nodes.node_D0;
            %             G_veins.Nodes.node_D2_D1 = G_veins.Nodes.node_D2./G_veins.Nodes.node_D1;
            G_veins.Nodes.node_AreaRatio = (G_veins.Nodes.node_D2+G_veins.Nodes.node_D1)./G_veins.Nodes.node_D0;
            G_veins.Nodes.node_Symmetry = G_veins.Nodes.node_D2./G_veins.Nodes.node_D1;
            % calculate the absolute angles around the branch
            %             G_veins.Nodes.theta_D2_D0 = rad2deg(pi - abs(pi - abs(G_veins.Nodes.node_OD2-G_veins.Nodes.node_OD0)));
            %             G_veins.Nodes.theta_D1_D0 = rad2deg(pi - abs(pi - abs(G_veins.Nodes.node_OD1-G_veins.Nodes.node_OD0)));
            %             G_veins.Nodes.theta_D2_D1 = rad2deg(pi - abs(pi - abs(G_veins.Nodes.node_OD2-G_veins.Nodes.node_OD1)));
            G_veins.Nodes.theta_D2_D0 = 180 - abs(180 - abs(G_veins.Nodes.node_OD2-G_veins.Nodes.node_OD0));
            G_veins.Nodes.theta_D1_D0 = 180 - abs(180 - abs(G_veins.Nodes.node_OD1-G_veins.Nodes.node_OD0));
            G_veins.Nodes.theta_D2_D1 = 180 - abs(180 - abs(G_veins.Nodes.node_OD2-G_veins.Nodes.node_OD1));
        end
        
        function coefficient = funcSolveCoefficient(app,G)
            d = uiprogressdlg(app.LeafVeinGUI,'Title','Calculating coefficients','Cancelable','on');
            % solve the coefficient for Murray's Law
            idx = find(G.Nodes.node_Degree == 3);
            coefficient = zeros(numnodes(G),1);
            a = sym('a');
            for iN = 1:numel(idx)
                % Check for Cancel button press
                if d.CancelRequested
                    break
                end
                d.Value = iN/numel(idx);
                try
                    r0 = G.Nodes.node_D0(idx(iN))/2;
                    r1 = G.Nodes.node_D1(idx(iN))/2;
                    r2 = G.Nodes.node_D2(idx(iN))/2;
                    coefficient(idx(iN)) = vpasolve(r0^a == r1^a+r2^a,a,2);
                catch ME
                    iN
                    ME
                end
            end
        end
        
        function [gray_CW,width,coded_CW,coded_FW] = fnc_coded_skeleton(app,im,sk,bw_mask,G_veins,edgelist,edgelist_center,sk_width)
            [nY,nX] = size(sk);
            % set up a blank image
            gray_CW = zeros([nY,nX], 'single');
            CW_center = zeros([nY,nX], 'single');
            CW_full = zeros([nY,nX], 'single');
            width = zeros([nY,nX], 'single');
            % get the value of the edges from the graph. These are sorted
            % automatically when the graph is set up in order of node i
            E = G_veins.Edges.Width;
            % get just the edges (not any features)
            E_idx = contains(G_veins.Edges.Type,'E');
            E = E(E_idx);
            % get the linear pixel index for the center width each edge excluding the features
            CW_idx = cellfun(@(x) sub2ind([nY nX],x(:,1),x(:,2)),edgelist_center(E_idx),'UniformOutput',0);
            % concatenate all the pixels indices to give a single vector
            P_CW = cat(1,CW_idx{:});
            % calculate an edge width skeleton based on the central width
            % for the selected edges
            V_CW = num2cell(E);
            % duplicate the value for the number of pixels in the center part of each edge
            V_coded_idx = cellfun(@(x,y) repmat(y,length(x),1), CW_idx, V_CW','UniformOutput',0);
            % concatenate all the values into a single vector
            V_CW_all = cat(1,V_coded_idx{:});
            % set the edge values to the central width for the center part of each
            % edge. The CW_pixels image is a grayscale image with the intensity
            % of the skeleton making up each edge set to the width in pixels. The
            % overlap region is set to zero.
            gray_CW(P_CW) = single(V_CW_all);
            % To construct the color-coded skeleton, normalise the range between 2 and
            % 256 as an index into the colourmap
            Emin = min(E);
            Emax = max(E);
            cmap_idx = ceil(254.*((E-Emin)./(Emax-Emin)))+2;
            % set any nan values to 1
            cmap_idx(isnan(cmap_idx)) = 1;
            % convert to a cell array
            V_coded = num2cell(cmap_idx);
            % duplicate the value for the number of pixels in each edge
            V_coded_idx = cellfun(@(x,y) repmat(y,length(x),1), CW_idx, V_coded','UniformOutput',0);
            % concatenate all the values into a single vector
            V_coded_all = cat(1,V_coded_idx{:});
            % set the edge values to the central width for each edge in the image
            % scaled between the min and the max
            CW_center(P_CW) = single(V_coded_all);
            if sk_width > 1
                sk_D = imdilate(sk, ones(sk_width));
                CW_center = imdilate(CW_center, ones(sk_width));
            else
                sk_D = sk;
            end
            im2 = im;%uint8(255.*im);
            im2(sk_D) = 0;
            im_rgb = uint8(255.*ind2rgb(im2,gray(256)));
            sk_rgb = uint8(255.*ind2rgb(uint8(CW_center),app.var.EdgeCmap));
            coded_CW = imadd(im_rgb,sk_rgb);
            % add the boundaries of the mask
            coded_CW = imoverlay(coded_CW,bwperim(bw_mask),'m');
            % Repeat the process but this time calculate the full pixel skeleton
            % (including the overlap regions) and coded by the center weight for
            % export. Get the linear pixel index for the full width each edge excluding the features
            FW_idx = cellfun(@(x) sub2ind([nY nX],x(:,1),x(:,2)),edgelist(E_idx),'UniformOutput',0);
            % concatenate all the pixels indices to give a single vector
            P_FW = cat(1,FW_idx{:});
            % calculate an edge width skeleton based on the central width
            % for the selected edges
            V_FW = num2cell(E);
            % duplicate the value for the number of pixels in each edge
            V_FW_idx = cellfun(@(x,y) repmat(y,length(x),1), FW_idx, V_FW','UniformOutput',0);
            % concatenate all the values into a single vector
            V_FW_all = cat(1,V_FW_idx{:});
            % set the edge values to the central width for each edge in the image
            width(P_FW) = single(V_FW_all);
            % make sure that the junctions are set to the local maximum, so that
            % junctions are preserved as part of the strongest edge
            temp = colfilt(width,[3 3],'sliding',@max);
            bp = bwmorph(sk,'branchpoints');
            % The width image is a complete skeleton with no breaks in the overlap
            % region coded by the center-weighted thickness of the edge
            width(bp) = temp(bp);
            % % set the masked regions to -1
            width(~bw_mask) = -1;
            %
            %Calculate complete coded skeleton image using the center-weighted width
            % duplicate the coded width value for the number of pixels in each full edge
            V_FW_idx = cellfun(@(x,y) repmat(y,length(x),1), FW_idx, V_coded','UniformOutput',0);
            % concatenate all the values into a single vector
            V_FW_all = cat(1,V_FW_idx{:});
            % set the edge values to the central width for each edge in the image
            CW_full(P_FW) = single(V_FW_all);
            % set the edge values to the central width for each edge in the image
            % scaled between the min and the max
            temp = colfilt(CW_full,[3 3],'sliding',@max);
            % The width image is a complete skeleton with no breaks in the overlap
            % region coded by the center-weighted thickness of the edge
            CW_full(bp) = temp(bp);
            if sk_width > 1
                CW_full = imdilate(CW_full, ones(sk_width));
            end
            im_rgb = uint8(255.*ind2rgb(im2,gray(256)));
            sk_rgb = uint8(255.*ind2rgb(uint8(CW_full),app.var.EdgeCmap));
            coded_FW = imadd(im_rgb,sk_rgb);
            % add the boundaries of the mask
            coded_FW = imoverlay(coded_FW,bwperim(bw_mask),'m');
        end
        
        function [G_veins, sk_polygon, bw_polygons, bw_areoles, total_area_mask, polygon_LM] = fnc_polygon_find(~,G_veins,bw_cnn,sk,skLoop,bw_mask)
            % remove any partial areas that are not fully bounded and therefore contact
            % the edge
            area = imclearborder(~sk & bw_mask,4);
            % remove any partial areoles that are touching any masked regions
            [r,c] = find(~bw_mask);
            area_mask = ~(bwselect(area | ~bw_mask,c,r,4));
            % get rid of any vestigial skeleton lines within the mask
            area_mask = bwmorph(area_mask, 'open');
            % fill the skeleton lines on the total area
            total_area_mask = imdilate(area, ones(3)) & area_mask;
            % remove isolated polygons
            total_area_mask = bwareafilt(total_area_mask, 1,'largest');
            % The polygons include the areoles and the vein itself.
            bw_polygons = ~skLoop & total_area_mask;
            % find areas that are too small (note bwareafilt does not work as it is not
            % possible to set connectivity to 4)
            CC = bwconncomp(bw_polygons,4);
            stats = regionprops(CC, 'Area');
            % arbitary threshold of ~10x10 pixels
            idx = find([stats.Area] > 100);
            bw_polygons  = ismember(labelmatrix(CC), idx);
            % The areoles exclude the full width of the veins
            bw_areoles = ~bw_cnn & total_area_mask & bw_polygons;
            % remove any orphan pixels
            bw_areoles = bwmorph(bw_areoles,'clean');
            % trim the skeleton to match
            sk_polygon = sk & total_area_mask;
            % construct a label matrix of areas including the veins
            LM = bwlabel(bw_polygons,4);
            % remove the skeleton in the calculation of the neighbouring
            % area
            LM(sk_polygon) = NaN;
            % find the neighbours of each edge
            Neighbour1 = colfilt(LM,[3 3],'sliding',@max);
            Neighbour2 = colfilt(LM,[3 3],'sliding',@(x) min(x,[],'Omitnan'));
            % Note some neighbours may link to the excluded areas and have a value of 0
            % add the neighbours to the graph
            G_veins.Edges.Ai = Neighbour1(G_veins.Edges.M_pix);
            G_veins.Edges.Aj = Neighbour2(G_veins.Edges.M_pix);
            % replace the skeleton pixels with zero
            LM(sk_polygon) = 0;
            % dilate the label matrix to include the pixel skeleton
            % LM = imdilate(LM, [0 1 0; 1 1 1; 0 1 0]);
            % polygon_LM = imdilate(LM, [0 1 0; 1 1 1; 0 1 0]);
            polygon_LM = imdilate(LM,ones(3));
            % trim off any extension beyond the boundary
            polygon_LM = polygon_LM.*total_area_mask;
        end
        
        function im_polygons_rgb = fnc_polygon_image(app,polygon_stats, sk_polygon, total_area_mask)
            [nY,nX] = size(total_area_mask);
            % construct a colour-coded area image from the log area
            idx = cat(1,polygon_stats.PixelIdxList);
            polygon_areas = [polygon_stats.Area];
            %
            logA = log10(polygon_areas);
            logA(isinf(logA)) = nan;
            Amin = 1;
            Amax = log10(sum(total_area_mask(:)));
            % normalise the range between 2 and 256 as an index into the
            % colourmap
            A_idx = ceil(254.*((logA-Amin)./(Amax-Amin)))+1;
            % set any nan values to 1
            A_idx(isnan(A_idx)) = 1;
            % duplicate the area value for the number of pixels in each edge
            A_val = cellfun(@(x,y) repmat(x,y,1), num2cell(A_idx),num2cell(polygon_areas),'UniformOutput',0);
            % concatenate all the values into a single vector
            A_all = cat(1,A_val{:});
            % set the edge values to the edge ID for each edge in the image
            %im_polygons = zeros(nY,nX);
            im_polygons = zeros(nY,nX, 'single');
            im_polygons(idx) = single(A_all);
            % punch out the skeleton to make the polygons distinct
            im_polygons(sk_polygon) = 0;
            im_polygons = uint8(im_polygons);
            im_polygons_rgb = uint8(255.*ind2rgb(im_polygons,app.var.PolygonCmap));
        end
        
        function [areole_stats,polygon_stats] = fnc_polygon_analysis(~,bw_polygons,bw_areoles, polygon_LM,FullMetrics)
            % measure the stats for the polygonal regions using the label matrix
            if FullMetrics == 0
                % don't calculate the most time consuming metrics (ConvexArea,
                % Solidity) and the internal distance metrics.
                polygon_stats = regionprops(polygon_LM, ...
                    'Area', ...
                    'Centroid', ...
                    'Eccentricity', ...
                    'EquivDiameter', ...
                    'MajorAxisLength', ...
                    'MinorAxisLength', ...
                    'Orientation', ...
                    'Perimeter', ...
                    'PixelIdxList');
            else
                P_stats = regionprops(polygon_LM, ...
                    'Area', ...
                    'Centroid', ...
                    'ConvexArea', ...
                    'Eccentricity', ...
                    'EquivDiameter', ...
                    'MajorAxisLength', ...
                    'MinorAxisLength', ...
                    'Orientation', ...
                    'Perimeter', ...
                    'Solidity', ...
                    'PixelIdxList');
                % get the maximum distance to the skeleton for each area
                D_stats = regionprops(polygon_LM,bwdist(~bw_polygons)+0.5,'MaxIntensity','MeanIntensity');
                % check for any empty values as these will switch the format from a
                % number to a cell
                test4empty = cellfun(@isempty,{D_stats.MaxIntensity});
                if any(test4empty)
                    % replace the empty cell with the minimum distance of 0.5 pixel
                    [D_stats(test4empty).MaxIntensity] = deal(0.5);
                end
                % rename the fields
                [D_stats.('MaxDistance')] = D_stats.('MaxIntensity');
                D_stats = rmfield(D_stats,'MaxIntensity');
                % get the average distance to the skeleton for each area
                [D_stats.('MeanDistance')] = D_stats.('MeanIntensity');
                D_stats = rmfield(D_stats,'MeanIntensity');
                names = [fieldnames(P_stats); fieldnames(D_stats)];
                % combine the two stats arrays
                polygon_stats = cell2struct([struct2cell(P_stats);struct2cell(D_stats)],names,1);
            end
            % calculate additional parameters
            ID = num2cell(1:length(polygon_stats));
            Circularity = num2cell((4.*pi.*[polygon_stats.Area])./([polygon_stats.Perimeter].^2));
            Elongation = num2cell([polygon_stats.MajorAxisLength]./[polygon_stats.MinorAxisLength]);
            Roughness = num2cell(([polygon_stats.Perimeter].^2)./[polygon_stats.Area]);
            [polygon_stats(:).Circularity] = deal(Circularity{:});
            [polygon_stats(:).Elongation] = deal(Elongation{:});
            [polygon_stats(:).Roughness] = deal(Roughness{:});
            [polygon_stats(:).polygon_ID] = deal(ID{:});
            % modify the label matrix to only include the areoles, but with the same ID
            % as the polygons
            areole_LM = polygon_LM;
            areole_LM(~bw_areoles) = 0;
            % run all the stats for the areoles
            if FullMetrics == 0
                % don't calculate the most time consuming metrics (ConvexArea,
                % Solidity) and don't calculate the internal distance measures
                areole_stats = regionprops(areole_LM, ...
                    'Area', ...
                    'Centroid', ...
                    'Eccentricity', ...
                    'MajorAxisLength', ...
                    'MinorAxisLength', ...
                    'Orientation', ...
                    'Perimeter', ...
                    'PixelIdxList');
            else
                A_stats = regionprops(areole_LM, ...
                    'Area', ...
                    'Centroid', ...
                    'ConvexArea', ...
                    'Eccentricity', ...
                    'MajorAxisLength', ...
                    'MinorAxisLength', ...
                    'Orientation', ...
                    'Perimeter', ...
                    'Solidity', ...
                    'PixelIdxList');
                % get the maximum distance to the skeleton for each area
                D_stats = regionprops(areole_LM,bwdist(~bw_areoles)+0.5,'MaxIntensity','MeanIntensity');
                % find any empty cells in the maxIntensity and replace with nan. This
                % ensures that the metric is saved as a number rather than a cell array
                test4empty = cellfun(@isempty,{D_stats.MaxIntensity});
                if any(test4empty)
                    [D_stats(test4empty).MaxIntensity] = deal(0.5);
                end
                % rename the fields
                [D_stats.('MaxDistance')] = D_stats.('MaxIntensity');
                D_stats = rmfield(D_stats,'MaxIntensity');
                % get the average distance to the skeleton for each area
                [D_stats.('MeanDistance')] = D_stats.('MeanIntensity');
                D_stats = rmfield(D_stats,'MeanIntensity');
                names = [fieldnames(A_stats); fieldnames(D_stats)];
                % combine the two stats arrays
                areole_stats = cell2struct([struct2cell(A_stats);struct2cell(D_stats)],names,1);
            end
            % calculate additional parameters
            ID = num2cell(1:length(areole_stats));
            Circularity = num2cell((4.*pi.*[areole_stats.Area])./([areole_stats.Perimeter].^2));
            Elongation = num2cell([areole_stats.MajorAxisLength]./[areole_stats.MinorAxisLength]);
            Roughness = num2cell(([areole_stats.Perimeter].^2)./[areole_stats.Area]);
            [areole_stats(:).Circularity] = deal(Circularity{:});
            [areole_stats(:).Elongation] = deal(Elongation{:});
            [areole_stats(:).Roughness] = deal(Roughness{:});
            [areole_stats(:).areole_ID] = deal(ID{:});
        end
        
        function [G_areas,LM] = fnc_area_graph(~,G_veins,area_stats,LM,type)
            % Construct a NodeTable with the node for each area, along with the
            % corresponding metrics
            % extract the centroid values
            polygon_Centroid = cat(1,area_stats.Centroid);
            % remove the centroid and PixelIdxList fields
            stats = rmfield(area_stats,{'Centroid';'PixelIdxList'});
            % convert to a NodeTable
            NodeTable = struct2table(stats);
            % add the centroid positions back in as separate columns in the table
            NodeTable.node_X_pix = polygon_Centroid(:,1);
            NodeTable.node_Y_pix = polygon_Centroid(:,2);
            % reorder the table to get the ID and co-ordinates first
            NodeTable = NodeTable(:,[end-2:end, 1:end-3]);
            % Construct an EdgeTable with the width of the pixel skeleton edge that it
            % crosses
            names = {'EndNodes' 'Width' 'vein_ID'};
            % If an area has a k=1 edge within it (i.e. a terminal vein) there could be
            % two possible edges to an adjacent area on either side with the same ID.
            % Therefore duplicate edges are resolved to the minimum. Reorder nodes to
            % be minimum first
            i = min(G_veins.Edges.Ai, G_veins.Edges.Aj);
            j = max(G_veins.Edges.Ai, G_veins.Edges.Aj);
            edges = [i j G_veins.Edges.Width G_veins.Edges.vein_ID];
            % if any edge links to an excluded region Ai or Aj will have a value of
            % zero. Therefore exclude these edges from analysis.
            edges(i==0 | j==0,:) = [];
            % sort by the edge width and keep the smallest
            edges = sortrows(edges,3);
            [~,idx] = unique(edges(:,1:2),'rows');
            edges = double(edges(idx,:));
            % remove edges connected to the background
            idx = max(edges(:,1:2)==0, [],2);
            edges(idx,:) = [];
            % remove edges connected to themselves
            idx = diff(edges(:,1:2),[],2)==0;
            edges(idx,:) = [];
            % create the edgetable
            EdgeTable = table([edges(:,1) edges(:,2)],edges(:,3),edges(:,4), 'VariableNames', names);
            G_areas = graph(EdgeTable,NodeTable,'OmitSelfLoops');
            % check the number of components and only keep the largest
            [CC, binsizes] = conncomp(G_areas);
            [~,GCC] = max(binsizes);
            %             CC = conncomp(G_areas);
            %             [N,~] = histcounts(CC,max(CC));
            %             [~,GCC] = max(N);
            % only keep the connected areas in the label matrix
            switch type
                case 'areoles'
                    LM(~ismember(LM,G_areas.Nodes.areole_ID(CC==GCC))) = 0;
                case 'polygons'
                    LM(~ismember(LM,G_areas.Nodes.polygon_ID(CC==GCC))) = 0;
            end
            G_areas = rmnode(G_areas,find(CC~=GCC));
        end
        
        function T = fnc_summary_veins(app,G_veins,total_area,polygon_area,micron_per_pixel)
            % set calibration factors
            mm = micron_per_pixel./1000;
            % get the index for all edges, loops and trees
            E_idx = find(contains(G_veins.Edges.Type,'E'));
            L_idx = find(contains(G_veins.Edges.Type,'L'));
            T_idx = find(contains(G_veins.Edges.Type,'T'));
            % get vein and node numbers
            T = table;
            T.VNE = numedges(G_veins); % dimensionless
            T.VNN = numnodes(G_veins); % dimensionless
            Deg = degree(G_veins); % dimensionless
            T.VFE = sum(Deg==1); % freely ending veins have degree one
            T.VFEratio = T.VFE / T.VNE; % Number of FEV divided by number of edges
            T.Valpha = (T.VNE - T.VNN + 1)/((2*T.VNN)-5); % dimensionless
            % get the total length of veins (in mm)
            T.VTotL = sum(G_veins.Edges.Length(E_idx)).*mm; %
            T.VLoopL = sum(G_veins.Edges.Length(L_idx)).*mm; %
            T.VTreeL = sum(G_veins.Edges.Length(T_idx)).*mm; %
            % get the total volume of the veins
            T.VTotV = sum(G_veins.Edges.Volume(E_idx)).*(mm.^3); %
            T.VLoopV = sum(G_veins.Edges.Volume(L_idx)).*(mm.^3); %
            T.VTreeV = sum(G_veins.Edges.Volume(T_idx)).*(mm.^3); %
            % get the weighted vein width
            T.VTotW = (1/T.VTotL).*(sum(G_veins.Edges.Length(E_idx).*G_veins.Edges.Width(E_idx))).*mm;
            T.VLoopW = (1/T.VLoopL).*(sum(G_veins.Edges.Length(L_idx).*G_veins.Edges.Width(L_idx))).*mm;
            T.VTreeW = (1/T.VTreeL).*(sum(G_veins.Edges.Length(T_idx).*G_veins.Edges.Width(T_idx))).*mm;
            % set the graph weight to be the length (in microns);
            G_veins.Edges.Weight = G_veins.Edges.Length.*mm;
            % total areas analysed
            T.TotA = total_area.*(mm.^2);
            T.TotPA = polygon_area.*(mm.^2);
            % get the density measurements
            T.VTotLD = T.VTotL / T.TotA;
            T.VLoopLD = T.VLoopL / T.TotA;
            T.VTreeLD = T.VTreeL / T.TotA;
            T.VTotVD = T.VTotV / T.TotA;
            T.VLoopVD = T.VLoopV / T.TotA;
            T.VTreeVD = T.VTreeV / T.TotA;
            T.VNND = T.VNN / T.TotA;
            % get the summary statistics for all relevant edge metrics in the table
            % usage: T = fnc_summary(T,metric,prefix,suffix,transform,units)
            T = fnc_summary(app,T,G_veins.Edges.Length(E_idx),'VTot','Len','none',mm);
            T = fnc_summary(app,T,G_veins.Edges.Length(L_idx),'VLoop','Len','none',mm);
            T = fnc_summary(app,T,G_veins.Edges.Length(T_idx),'VTree','Len','none',mm);
            T = fnc_summary(app,T,G_veins.Edges.Width(E_idx),'VTot','Wid','none',mm);
            T = fnc_summary(app,T,G_veins.Edges.Width(L_idx),'VLoop','Wid','none',mm);
            T = fnc_summary(app,T,G_veins.Edges.Width(T_idx),'VTree','Wid','none',mm);
            T = fnc_summary(app,T,G_veins.Edges.SurfaceArea(E_idx),'VTot','SAr','none',mm);
            T = fnc_summary(app,T,G_veins.Edges.SurfaceArea(L_idx),'VLoop','SAr','none',mm);
            T = fnc_summary(app,T,G_veins.Edges.SurfaceArea(T_idx),'VTree','SAr','none',mm);
            T = fnc_summary(app,T,G_veins.Edges.Volume(E_idx),'VTot','Vol','none',mm^3);
            T = fnc_summary(app,T,G_veins.Edges.Volume(L_idx),'VLoop','Vol','none',mm^3);
            T = fnc_summary(app,T,G_veins.Edges.Volume(T_idx),'VTree','Vol','none',mm^3);
            T = fnc_summary(app,T,G_veins.Edges.Tortuosity(E_idx),'V','Tor','none',1);
            T = fnc_summary(app,T,G_veins.Edges.Or_ij(E_idx),'V','Ori','circ',1);
            T = fnc_summary(app,T,G_veins.Nodes.node_D0,'N','BD0','none',1);
            T = fnc_summary(app,T,G_veins.Nodes.node_D1,'N','BD1','none',1);
            T = fnc_summary(app,T,G_veins.Nodes.node_D2,'N','BD2','none',1);
            %             T = fnc_summary(app,T,G_veins.Nodes.node_D2_D1,'N','Rnd','none',1);
            %             T = fnc_summary(app,T,G_veins.Nodes.node_D2_D0,'N','Rnx','none',1);
            %             T = fnc_summary(app,T,G_veins.Nodes.node_D1_D0,'N','Rdx','none',1);
            T = fnc_summary(app,T,G_veins.Nodes.theta_D1_D0,'N','A10','circ',1);
            T = fnc_summary(app,T,G_veins.Nodes.theta_D2_D0,'N','A20','circ',1);
            T = fnc_summary(app,T,G_veins.Nodes.theta_D2_D1,'N','A21','circ',1);
        end
        
        function T = fnc_summary_areoles(app,G_areoles,polygon_area,micron_per_pixel,FullMetrics)
            % set calibration factors
            mm = micron_per_pixel./1000;
            T = table;
            T.ATA = sum(G_areoles.Nodes.Area).*(mm.^2);
            T.ANN = numnodes(G_areoles);
            PTA = polygon_area.*(mm.^2);
            T.Aloop = T.ANN / PTA; % should be the same as T.Ploop
            % get areole statistics
            T = fnc_summary(app,T,G_areoles.Nodes.Area,'A','Are','none',mm^2);
            T = fnc_summary(app,T,G_areoles.Nodes.Eccentricity,'A','Ecc','none',1);
            T = fnc_summary(app,T,G_areoles.Nodes.MajorAxisLength,'A','Maj','none',mm);
            T = fnc_summary(app,T,G_areoles.Nodes.MinorAxisLength,'A','Min','none',mm);
            T = fnc_summary(app,T,G_areoles.Nodes.Perimeter,'A','Per','none',mm);
            T = fnc_summary(app,T,G_areoles.Nodes.Elongation,'A','Elg','none',1);
            T = fnc_summary(app,T,G_areoles.Nodes.Circularity,'A','Cir','none',1);
            T = fnc_summary(app,T,G_areoles.Nodes.Roughness,'A','Rgh','none',1);
            T = fnc_summary(app,T,G_areoles.Nodes.Orientation,'A','Ori','circ',1);
            % add in additional metrics if required
            if FullMetrics == 1
                T = fnc_summary(app,T,G_areoles.Nodes.ConvexArea,'A','CnA','none',mm^2);
                T = fnc_summary(app,T,G_areoles.Nodes.Solidity,'A','Sld','none',mm);
                T = fnc_summary(app,T,G_areoles.Nodes.MeanDistance,'A','Dav','none',1);
                T = fnc_summary(app,T,G_areoles.Nodes.MaxDistance,'A','Dmx','none',1);
            end
        end
        
        function T = fnc_summary_polygons(app,G_polygons,micron_per_pixel,FullMetrics)
            % set calibration factors
            mm = micron_per_pixel./1000;
            T = table;
            T.PTA = sum(G_polygons.Nodes.Area).*(mm.^2);
            T.PNN = numnodes(G_polygons);
            T.Ploop = T.PNN / T.PTA; % should be the same as T.Aloop
            % get polgonal area statistics
            T = fnc_summary(app,T,G_polygons.Nodes.Area,'P','Are','none',mm^2);
            T = fnc_summary(app,T,G_polygons.Nodes.Eccentricity,'P','Ecc','none',1);
            T = fnc_summary(app,T,G_polygons.Nodes.MajorAxisLength,'P','Maj','none',mm);
            T = fnc_summary(app,T,G_polygons.Nodes.MinorAxisLength,'P','Min','none',mm);
            T = fnc_summary(app,T,G_polygons.Nodes.Perimeter,'P','Per','none',mm);
            T = fnc_summary(app,T,G_polygons.Nodes.Elongation,'P','Elg','none',1);
            T = fnc_summary(app,T,G_polygons.Nodes.Circularity,'P','Cir','none',1);
            T = fnc_summary(app,T,G_polygons.Nodes.Roughness,'P','Rgh','none',1);
            T = fnc_summary(app,T,G_polygons.Nodes.Orientation,'P','Ori','circ',1);
            % add in additional metrics if required
            if FullMetrics == 1
                T = fnc_summary(app,T,G_polygons.Nodes.ConvexArea,'P','CnA','none',mm^2);
                T = fnc_summary(app,T,G_polygons.Nodes.Solidity,'P','Sld','none',mm);
                T = fnc_summary(app,T,G_polygons.Nodes.MeanDistance,'P','Dav','none',1);
                T = fnc_summary(app,T,G_polygons.Nodes.MaxDistance,'P','Dmx','none',1);
            end
        end
        
        function T = fnc_summary(~,T,metric,prefix,suffix,transform,units)
            % ignore infinite or nan values
            metric = metric(~isinf(metric) & ~isnan(metric));
            % transform the data if required
            switch transform
                case 'log'
                    metric = log(metric);
                case 'inverse'
                    metric = 1./metric;
            end
            % calculate summary statistic. Note: uncomment lines to add metrics
            switch transform
                case 'circ'
                    % use circular stats
                    warning off
                    T.([prefix 'av' suffix]) = circ_rad2ang(circ_mean(deg2rad(metric)));
                    %         don't calculate the median as the intermediate array size is Ne x NE
                    %         T.([prefix 'md' suffix]) = circ_rad2ang(circ_median(metric)); %
                    %         T.([prefix 'mn' suffix]) = circ_rad2ang(min(metric));
                    %         T.([prefix 'mx' suffix]) = circ_rad2ang(max(metric));
                    T.([prefix 'sd' suffix]) = circ_rad2ang(circ_std(deg2rad(metric)));
                    %         T.([prefix 'sk' suffix]) = circ_skewness(metric);
                    warning on
                otherwise
                    T.([prefix 'av' suffix]) = mean(metric).*units;
                    T.([prefix 'md' suffix]) = median(metric).*units;
                    %         T.([prefix 'mo' suffix]) = mode(metric).*units;
                    %         T.([prefix 'mn' suffix]) = min(metric).*units;
                    %         T.([prefix 'mx' suffix]) = max(metric).*units;
                    T.([prefix 'sd' suffix]) = std(metric).*units;
                    %         T.([prefix 'sk' suffix]) = skewness(metric);
            end
        end
        

        function [G_HLD, HLD_image, parent] = fnc_HLD(~,G_veins, G_polygons, polygon_stats, areole_stats, polygon_LM, bw_polygons, micron_per_pixel)
            % set calibration factors
            mm = micron_per_pixel./1000;
            % construct a binary polygon CC object
            PCC.Connectivity = 4;
            PCC.ImageSize = size(polygon_LM);
            PCC.NumObjects = 1;
            PCC.PixelIdxList = {};
            % find all the polygons on the boundary. The label matrix has already been
            % dilated to remove the internal skeleton lines, so the only background
            % values will be at the edges or internal masked regions. If these are dilated using
            % a 5x5 kernel, they should overlap any polygons adjacent to the boundary.
            % These can then be extracted using the dilated boundary region as seed points.
            % The set of polygons touching the boundary can then be used to set a
            % boundary flag in the G_polygons graph.
            boundary = polygon_LM==0;
            %             boundary = bwmorph(boundary,'clean');
            %             boundary = bwareafilt(boundary,[200 inf]);
            boundary = imdilate(boundary,ones(5));
            [r,c] = find(boundary);
            B_polygons = bwselect(bw_polygons,c,r,4);
            B_polygons = imerode(B_polygons, ones(3));
            % get the ID of the boundary polygons from the label matrix
            Bidx = unique(polygon_LM(B_polygons));
            Bidx(Bidx==0) = [];
            %
            % % %                         % get the pixelidxlist from the polygon_stats array to check the correct
            % % %                         % polygons have been identified
            % % %                         Bpix = cat(1,polygon_stats(Bidx).PixelIdxList);
            % % %                         Bim = zeros(size(B_polygons));
            % % %                         Bim(Bpix) = 1;
            % % %                         figure
            % % %                         imshow(single(cat(3,B_polygons,bw_polygons,Bim)))
            % % %                         %
            % add in a boundary flag if the node is a boundary polygon
            G_polygons.Nodes.Boundary = ismember(G_polygons.Nodes.polygon_ID,Bidx);
            % select the largest component of the sub-graph
            CC = conncomp(G_polygons);
            [N,~] = histcounts(CC,max(CC));
            [~,GCC] = max(N);
            SG_GCC = subgraph(G_polygons,find(CC==GCC));
            % % % %
            % % % %             hold on
            % % % %             plot(SG_GCC, 'XData',SG_GCC.Nodes.node_X_pix,'YData',SG_GCC.Nodes.node_Y_pix, ...
            % % % %                 'NodeColor','r','MarkerSize',1,'Marker', 'none', 'NodeLabel', [], ...
            % % % %                 'EdgeColor','r','EdgeAlpha',1,'EdgeLabel', [],'LineWidth',1);
            % % % %             scatter(SG_GCC.Nodes.node_X_pix(~SG_GCC.Nodes.Boundary),SG_GCC.Nodes.node_Y_pix(~SG_GCC.Nodes.Boundary),48,'r','*')
            % % % %             scatter(SG_GCC.Nodes.node_X_pix(SG_GCC.Nodes.Boundary),SG_GCC.Nodes.node_Y_pix(SG_GCC.Nodes.Boundary),48,'m','o')
            % % % %             %
            %
            % extract the same component from the stats arrays. These arrays contain
            % data for all polygons orginally identified and given a unique number (ID)
            polygon_stats = polygon_stats(SG_GCC.Nodes.polygon_ID);
            % Keep veins from the vein graph that form part of the polygon_graph. These
            % will be the boundary edges and any internal tree-like parts of the
            % network, but will exclude edges from incomplete polygons on the boundary
            % or disconnected polygons. Edges should have Ai and/or
            % Aj corresponding to a polygon node ID.
            Vidx = ismember(G_veins.Edges.Ai,SG_GCC.Nodes.polygon_ID) | ismember(G_veins.Edges.Aj,SG_GCC.Nodes.polygon_ID);
            G_veins = rmedge(G_veins,find(~Vidx));
            % only keep veins that are still connected to the largest component
            [CC, binsizes] = conncomp(G_veins);
            [~,GCC] = max(binsizes);
            G_veins = subgraph(G_veins,find(CC==GCC));
            % % % %
            % % % %             plot(G_veins, 'XData',G_veins.Nodes.node_X_pix,'YData',G_veins.Nodes.node_Y_pix, ...
            % % % %                 'NodeColor','b','MarkerSize',1,'Marker', 'none', 'NodeLabel', [], ...
            % % % %                 'EdgeColor','m','EdgeAlpha',1,'EdgeLabel', [],'LineWidth',2);
            % % % %             %
            % calculate the initial length and MST ratio for the veins
            L = sum(G_veins.Edges.Length);
            % set the weights in the vein graph to length
            G_veins.Edges.Weight = G_veins.Edges.Length;
            MST = minspantree(G_veins,'method','sparse');
            MSTL = sum(MST.Edges.Weight)/L;
            % get the number of nodes and edge in the dual graph
            nnP = numnodes(SG_GCC);
            neP = numedges(SG_GCC);
            % dimension all the arrays
            parent = zeros(1,nnP);
            width_threshold = zeros((2*nnP)-1,1);
            node_Boundary = [SG_GCC.Nodes{:,'Boundary'}; zeros(nnP-1,1)];
            node_Area = [SG_GCC.Nodes{:,'Area'}; zeros(nnP-1,1)];
            node_Degree = [ones(nnP,1); zeros(nnP-1,1)];
            degree_Asymmetry = zeros(nnP*2-1,1);
            area_Asymmetry = zeros(nnP*2-1,1);
            node_HS = [ones(nnP,1); zeros(nnP-1,1)];
            subtree_degree_Asymmetry = zeros((2*nnP)-1,1);
            subtree_area_Asymmetry = zeros((2*nnP)-1,1);
            VTotLen = [repmat(L,nnP,1); zeros(nnP-1,1)];
            VTotVol = [repmat(L,nnP,1); zeros(nnP-1,1)];
            MSTRatio = [repmat(MSTL,nnP,1); zeros(nnP-1,1)];
            % redimension the stat arrays to accomodate all the fused nodes
            areole_stats(nnP.*2-1).Area = 0;
            polygon_stats(nnP.*2-1).Area = 0;
            %             % order the edges by width in the polygon graph
            %             [W,idx] = sort(SG_GCC.Edges{:,'Width'});
            %             % sort the edge nodes and edge ID to match the ordered widths
            %             nodei = SG_GCC.Edges{idx,'EndNodes'}(:,1);
            %             nodej = SG_GCC.Edges{idx,'EndNodes'}(:,2);
            %             % set up a list of the initial edges
            %             ET = [nodei nodej W];
            ET = [SG_GCC.Edges.EndNodes(:,1) SG_GCC.Edges.EndNodes(:,2) SG_GCC.Edges{:,'Width'}];
            % sort by width
            ET = sortrows(ET,3,'ascend');
            % start the index for the new node (Nk) to follow on the number of existing
            % nodes (nnP)
            Nk = nnP;
            Ne = 0;
            % get all the current polygon PixelIdxLists at the start as a cell array;
            P_PIL = {polygon_stats.PixelIdxList};
            PCC.NumObjects = length(P_PIL);
            PCC.PixelIdxList  = {polygon_stats.PixelIdxList}';
            LM = labelmatrix(PCC);
            % start the HLD_image with the pixel skeleton
            HLD_image = uint8(~bw_polygons);
            %
            P_stats = regionprops('table',LM,'Area','Centroid','Perimeter','MajorAxisLength','MinorAxisLength','Eccentricity','Orientation','Image','BoundingBox','PixelList');
            % set up the endnodes
            EndNodes = zeros(nnP*2-2,2);
            % loop through all the edges, calculating the metrics
            for iE = 1:neP
                % test each edge in sequence
                Ni = ET(1,1);
                Nj = ET(1,2);
                % the edge to be removed only links two areas if
                % the end nodes are different
                if Ni~=Nj
                    % create a new node
                    Nk = Nk+1;
                    % get the width threshold for the HLD for this partition
                    width_threshold(Nk,1) = ET(1,3);
                    % construct the EndNodes for the two new edges that connect Ni and Nj to Nk
                    Ne = Ne+1;
                    EndNodes(Ne,:) = [Ni Nk];
                    Ne = Ne+1;
                    EndNodes(Ne,:) = [Nj Nk];
                    % check whether the fused area includes a boundary region
                    node_Boundary(Nk) = max(node_Boundary(Ni),node_Boundary(Nj));
                    % sum the areas of the nodes to fuse
                    node_Area(Nk) = node_Area(Ni)+node_Area(Nj);
                    % sum the degree of the subtree at each node
                    node_Degree(Nk) = node_Degree(Ni)+node_Degree(Nj);
                    % find a measure of the partition asymmetry of the
                    % bifurcation vertex
                    degree_Asymmetry(Nk,1) = abs(node_Degree(Ni)-node_Degree(Nj))/max(node_Degree(Ni),node_Degree(Nj));
                    % calculate the subtree asymmetry
                    subtree_degree_Asymmetry(Nk,1) = (1/(node_Degree(Nk)-1))*((degree_Asymmetry(Ni)*(node_Degree(Ni)-1))+(degree_Asymmetry(Nj)*(node_Degree(Nj)-1)));
                    area_Asymmetry(Nk,1) = abs(node_Area(Ni)-node_Area(Nj))/max(node_Area(Ni),node_Area(Nj));
                    % calculate the subtree asymmetry
                    subtree_area_Asymmetry(Nk,1) = (1/(node_Area(Nk)-1))*((area_Asymmetry(Ni)*(node_Area(Ni)-1))+(area_Asymmetry(Nj)*(node_Area(Nj)-1)));
                    % Strahler index: keep the larger of the Horton-Strahler indices if they
                    % are not equal. If they are equal, increment the HS index by one
                    if node_HS(Ni) ~= node_HS(Nj)
                        node_HS(Nk,1) = max(node_HS(Ni),node_HS(Nj));
                    else
                        node_HS(Nk,1) = node_HS(Ni)+1;
                    end
                    % delete the current edge
                    ET(1,:) = [];
                    % replace any other occurrences of the nodes that have fused with
                    % the new node ID
                    idx = ET(:,1) == Ni | ET(:,1) == Nj;
                    ET(idx,1) = Nk;
                    idx = ET(:,2) == Ni | ET(:,2) == Nj;
                    ET(idx,2) = Nk;
                    % set the parent of the nodes to be fused to the new node
                    parent(Ni) = Nk;
                    parent(Nj) = Nk;
                    % add the PixelIdxList for the nodes that have fused to the new node
                    P_PIL(Nk) = {cat(1,P_PIL{Ni},P_PIL{Nj})};
                    % calculate the statistics for the new region and add them to the
                    % stats structures
                    PCC.NumObjects = 1;
                    PCC.PixelIdxList  = P_PIL(Nk);
                    P_stats(Nk,:) = regionprops('table',PCC,'Area','Centroid','Perimeter','MajorAxisLength','MinorAxisLength','Eccentricity','Orientation','Image','BoundingBox','PixelList');
                    % construct an image of the HLD level
                    B = bwboundaries(P_stats.Image{Nk,1});
                    Ridx = sub2ind(size(HLD_image),floor(B{1}(:,1)+P_stats.BoundingBox(Nk,2)),floor(B{1}(:,2)+P_stats.BoundingBox(Nk,1)));
                    HLD_image(Ridx) = HLD_image(Ridx)+1;
                    % find edges in the vein graph up to and including this edge width
                    Eidx = G_veins.Edges.Width <= width_threshold(Nk,1);
                    % remove these edges from the graph
                    G_veins = rmedge(G_veins,find(Eidx));
                    % only keep veins that are still connected to the largest component
                    CC = conncomp(G_veins);
                    [N,~] = histcounts(CC,max(CC));
                    [~,GCC] = max(N);
                    G_veins = subgraph(G_veins,find(CC==GCC));
                    % replace any occurrences of the nodes that have fused with
                    % the new node ID
                    idx = G_veins.Edges.Ai == Ni | G_veins.Edges.Ai == Nj;
                    G_veins.Edges.Ai(idx) = Nk;
                    idx = G_veins.Edges.Aj == Ni | G_veins.Edges.Aj == Nj;
                    G_veins.Edges.Aj(idx) = Nk;
                    % calculate the minimum spanning tree using Kruskal's algorithm
                    MST = minspantree(G_veins,'method','sparse');
                    VTotLen(Nk,1) = sum(G_veins.Edges.Weight);
                    VTotVol(Nk,1) = sum(G_veins.Edges.Volume);
                    MSTRatio(Nk,1) = sum(MST.Edges.Weight)/VTotLen(Nk,1);
                else
                    % delete the current edge as it lies within areas that are already
                    % fused
                    ET(1,:) = [];
                end
            end
            % complete links to the root node
            parent(end+1) = Nk+1;
            parent = double(fliplr(max(parent(:)) - parent));
            % calculate additional metrics
            Circularity = (4.*pi.*[P_stats.Area])./([P_stats.Perimeter].^2);
            Elongation = [P_stats.MajorAxisLength]./[P_stats.MinorAxisLength];
            Roughness = ([P_stats.Perimeter].^2)./[P_stats.Area];
            % assemble the HLD graph object
            NodeTable = table((1:(2*nnP)-1)', width_threshold.*mm, ...
                node_Area.*(mm^2), ...
                node_Degree, ...
                degree_Asymmetry, ...
                subtree_degree_Asymmetry, ...
                area_Asymmetry, ...
                subtree_area_Asymmetry, ...
                node_HS, ...
                [P_stats.Perimeter].*mm, ...
                [P_stats.MajorAxisLength].*mm, ...
                [P_stats.MinorAxisLength].*mm, ...
                [P_stats.Eccentricity], ...
                [P_stats.Orientation], ...
                Circularity, ...
                Elongation, ...
                Roughness, ...
                VTotLen.*mm, ...
                VTotVol.*mm^3, ...
                MSTRatio, ...
                node_Boundary, ...
                'VariableNames',{'node_ID', 'width_threshold', 'node_Area', 'node_Degree', ...
                'degree_Asymmetry',  'subtree_degree_Asymmetry', 'area_Asymmetry',  'subtree_area_Asymmetry', ...
                'node_HS','Perimeter','MajorAxisLength', 'MinorAxisLength', 'Eccentricity','Orientation','Circularity','Elongation','Roughness','VTotLen','VTotVol','MSTRatio','Boundary'});
            %             idx = NodeTable.node_Area == 0;
            %             NodeTable(idx,:) = [];
            EdgeTable = table(EndNodes, ...
                'VariableNames', {'EndNodes'});
            idx = EdgeTable.EndNodes(:,1)==0;
            EdgeTable(idx,:) = [];
            idx = EdgeTable.EndNodes(:,2)==0;
            EdgeTable(idx,:) = [];
            % combine to form the HLD tree graph
            G_HLD = graph(EdgeTable, NodeTable);
        end
        
        function hfig = funcDisplayFigure(app,images,graphs,titles,G,EdgeWidth,links,name,ExportFigs)
            %hfig = figure('Renderer','painters');
            hfig = figure; % remote only uses software opengl
            hfig.Units = 'normalized';
            hfig.Position = [0 0 1 1];
            hfig.Color = 'w';
            inset_sz = 2/5;
            inset_zoom = 6;
            for ia = 1:6
                ax(ia) = subplot(2,3,ia);
                pos = ax(ia).OuterPosition;
                pos(3) = 0.25;
                pos(4) = 0.4;
                ax(ia).Position = pos;
                ax(ia+6) = axes('Position', [pos(1)+pos(3)*(1-inset_sz) pos(2)+pos(4)*(1-inset_sz) pos(3)*inset_sz pos(4)*inset_sz]);
            end
            linkaxes(ax(links),'xy')
            for ia = 1:6
                axes(ax(ia))
                if ~isempty(images{ia})
                    imshow(images{ia},[],'Border','tight','InitialMagnification','fit');
                    h = title(titles{ia},'fontsize',12,'fontweight','normal','interpreter','none');
                    h.FontWeight = 'normal';
                    axis on
                    ax(ia).XTick = [];
                    ax(ia).YTick = [];
                    ax(ia).XColor = 'k';
                    ax(ia).YColor = 'k';
                    ax(ia).LineWidth = 1;
                    % display the inset
                    axes(ax(ia+6))
                    imshow(images{ia},[]);
                    zoom(inset_zoom)
                    axis on
                    ax(ia+6).XTick = [];
                    ax(ia+6).YTick = [];
                    ax(ia+6).XColor = 'k';
                    ax(ia+6).YColor = 'k';
                    ax(ia+6).LineWidth = 1;
                end
            end
            for ia = 1:6
                if ~strcmp(graphs{ia},'none')
                    % get the edge parameter to display
                    E = G.Edges.(graphs{ia});
                    E(isinf(E)) = nan;
                    % Normalise the metric between min and max and generate an index
                    % into the colourmap
                    E_min = min(E,[], 'omitnan');
                    E_max = max(E,[], 'omitnan');
                    % normalise the range between 2 and 256 as an index into the
                    % colourmap
                    E_idx = ceil(254.*((E-E_min)./(E_max-E_min)))+1;
                    % set any nan values to 1 in the index
                    E_idx(isnan(E_idx)) = 1;
                    % the index should be real numbers normalised between 1 and 256 at
                    % this point.
                    E_color =  app.var.EdgeCmap(E_idx,:);
                    % plot the graph
                    axes(ax(ia))
                    hold on
                    plot(G, 'XData',G.Nodes.node_X_pix,'YData',G.Nodes.node_Y_pix, ...
                        'NodeColor','g','MarkerSize',1,'Marker', 'none', 'NodeLabel', [], ...
                        'EdgeColor',E_color,'EdgeAlpha',1,'EdgeLabel', [],'LineWidth',EdgeWidth);
                    axes(ax(ia+6))
                    hold on
                    plot(G, 'XData',G.Nodes.node_X_pix,'YData',G.Nodes.node_Y_pix, ...
                        'NodeColor','g','MarkerSize',1,'Marker', 'none', 'NodeLabel', [], ...
                        'EdgeColor',E_color,'EdgeAlpha',1,'EdgeLabel', [],'LineWidth',EdgeWidth);
                end
            end
            drawnow
            if ExportFigs
                warning('off','MATLAB:prnRenderer:opengl');
                export_fig(name,'-png','-pdf','-r150',hfig)
                %     saveas(hfig,name)
            end
            delete(hfig);
        end
        
        function hfig = display_HLD(app,G_polygons,im_cnn,G_HLD,FullLeaf,name,ExportFigs)
            %hfig = figure('Renderer','painters');
            hfig = figure; % remote only uses software opengl
            hfig.Units = 'normalized';
            hfig.Position = [0 0 0.6 1];
            hfig.Color = 'w';
            % display the CNN image to overlay the subgraphs
            ax(1) = subplot(3,3,4);
            axes(ax(1))
            pos = ax(1).OuterPosition;
            ax(1).Position = pos;
            imshow(1-im_cnn,[],'Parent',ax(1))
            hold on
            axis off
            box on
            % display the complete treeplot
            ax(2) = subplot(3,3,[1,3]);
            h = plot(ax(2), G_HLD,'Layout','layered','Direction','down','sources',numnodes(G_HLD), ...
                'AssignLayers','alap','NodeColor','k','Marker','.','EdgeColor','k','LineWidth',1);
            % recolor any nodes connected to the boundary in grey
            SP = shortestpathtree(G_HLD,numnodes(G_HLD),G_HLD.Nodes.node_ID(find(G_HLD.Nodes.Boundary)));
            highlight(h,SP,'NodeColor',[0.5 0.5 0.5],'EdgeColor',[0.5 0.5 0.5],'Marker','*','LineWidth',1)
            ylabel(ax(2),'node level');
            xlabel(ax(2),'terminal node');
            axis tight
            axis on
            xlim(ax(2),[0 numnodes(G_polygons)])
            y = ylim;
            ylim(ax(2),[0 y(2)])
            box on
            % display the tree width histogram
            ax(3) = subplot(3,3,5);
            nbins = 50;
            vv = G_HLD.Nodes.width_threshold;
            ww = G_HLD.Nodes.node_Area;
            % get automatic bin limits using the histogram function
            [~,edges] = histcounts(vv,nbins);
            % calculate a weighted histogram
            [histw, ~] = histwv(app,vv, ww, min(edges), max(edges), nbins);
            % plot the histogram against the bin centers
            center = edges(2:end)-diff(edges(1:2))./2;
            bar(ax(3),center(2:end),histw(2:end),'FaceColor',[0.5 0.5 0.5])
            xlabel(ax(3),'width of edge removed')
            ylabel(ax(3),'area weighted freq.')
            box on
            if FullLeaf == 1
                last = 1;
                g1 = G_HLD;
            else
                % extract the subgraph for nodes that are not linked to a boundary node
                g1 = subgraph(G_HLD,find(~G_HLD.Nodes.Boundary));
                % extract the largest five fully connected subgraphs
                last = str2double(app.HLDNumROIDropDown.Value);
            end
            % order by the largest connected subtree
            cc = conncomp(g1,'OutputForm','cell');
            l = cellfun(@(x) length(x),cc);
            [~,idx] = sort(l,'descend');
            % set up plot options
            cols = repmat({'r','g','b','c','m','y'},1,50);
            width_limits = ([round(min(log(g1.Nodes.width_threshold(g1.Nodes.width_threshold>0)))-0.1,1) round(max(log(g1.Nodes.width_threshold))+0.1,1)]);
            area_limits = ([floor(min(log(g1.Nodes.node_Area))-0.25) ceil(max(log(g1.Nodes.node_Area))+0.25)]);
            for icc = 1:last
                % extract the HLD subgraph
                g2 = subgraph(g1,cc{idx(icc)});
                % recolor the nodes and edges
                highlight(h,g2.Nodes.node_ID(g2.Edges.EndNodes(:,1)),g2.Nodes.node_ID(g2.Edges.EndNodes(:,2)),'NodeColor',cols{icc},'Marker','o','EdgeColor',cols{icc},'LineWidth',1)
                % get the node-IDs for the subtree to overlay on the image
                Gidx = g1.Nodes.node_ID(cc{idx(icc)});
                % limit the nodes to display to the initial nodes
                Gidx(Gidx>numnodes(G_polygons)) = [];
                % extract the subgraph from the original dual-graph that contain the selected nodes
                g3 = subgraph(G_polygons,Gidx);
                % display the subgraph on the image
                plot(ax(1),g3, 'XData',g3.Nodes.node_X_pix,'YData',g3.Nodes.node_Y_pix, ...
                    'NodeColor','g','MarkerSize',1,'Marker', 'none', 'NodeLabel', [], ...
                    'EdgeColor',cols{icc},'EdgeAlpha',1,'EdgeLabel', [],'LineWidth',1);
                
                % plot the strahler bifurcation ratio
                maxX = max(g1.Nodes.node_HS);
                Bifurcation_ratio = [];
                for iHS = 1:max(g2.Nodes.node_HS)-1
                    Bifurcation_ratio(iHS) = sum(g2.Nodes.node_HS==iHS)/sum(g2.Nodes.node_HS==iHS+1);
                end
                ax(4) = subplot(3,3,6);
                plot(ax(4),Bifurcation_ratio+icc.*.1,'Color',cols{icc},'Marker','o','Linestyle', '-')
                hold on
                xlim(ax(4),[0 maxX])
                ylim(ax(4),[0 8])
                xlabel(ax(4),'Strahler number')
                ylabel(ax(4),'bifurcation ratio')
                box on
                
                % calculate the cumulative size distribution
                a = sort(g2.Nodes.node_Area,'ascend');
                csN = cumsum(1:numnodes(g2));
                Pa = 1-(csN/max(csN));
                ax(5) = subplot(3,3,7);
                plot(ax(5),log(a),log(a.*Pa'), 'Color',cols{icc},'Marker','o','Linestyle', '-')
                xlabel(ax(5),'log(area)')
                ylabel(ax(5),'log Pa*area')
                xlim(ax(5),area_limits)
                %ylim([-6 0])
                hold on
                box on
                
                % plot the MSTRatio
                ax(6) = subplot(3,3,8);
                plot(ax(6),log(g2.Nodes.width_threshold), g2.Nodes.MSTRatio,'LineStyle','none','Marker','.','Color',cols{icc})
                xlabel(ax(6),'log(width)')
                ylabel(ax(6),'MST ratio')
                xlim(ax(6),width_limits)
                ylim(ax(6),[0.5 1])
                hold on
                box on
                
                % plot the Circularity
                ax(7) = subplot(3,3,9);
                plot(ax(7),log(g2.Nodes.width_threshold), 4*pi*g2.Nodes.node_Area./g2.Nodes.Perimeter.^2,'LineStyle','none','Marker','.','Color',cols{icc})
                xlabel(ax(7),'log(width)')
                ylabel(ax(7),'circularity')
                xlim(ax(7),width_limits)
                ylim(ax(7),[0.2 1])
                hold on
                box on
                
            end
            drawnow
            if ExportFigs
                warning('off','MATLAB:prnRenderer:opengl');
                %export_fig(name,'-png','-pdf','-r300','-painters',hfig)
                export_fig(name,'-png','-pdf','-r300',hfig)
                %     saveas(hfig,name)
            end
            delete(hfig);
        end
        
        function hfig = display_HLD_figure(app,G_polygons,im_cnn,G_HLD,FullLeaf,name,ExportFigs)
            %hfig = figure('Renderer','painters');
            hfig = figure; % remote only uses software opengl
            hfig.Units = 'normalized';
            hfig.Position = [0 0 1 0.5];
            hfig.Color = 'w';
            % set up the subplots
            for ia = 1:3
                ax(ia) = subplot(1,3,ia);
                pos = ax(ia).OuterPosition;
                ax(ia).Position = pos.*[1 1 0.95 1];
            end
            % display the CNN image to overlay the subgraphs
            axes(ax(1))
            imshow(1-im_cnn,[])
            axis on
            ax(1).XTick = [];
            ax(1).YTick = [];
            hold on
            % display the complete treeplot
            subplot(1,3,[2,3])
            h = plot(G_HLD,'Layout','layered','Direction','down','sources',numnodes(G_HLD), ...
                'AssignLayers','alap','NodeColor','k','Marker','.','EdgeColor','k','LineWidth',1);
            % recolor any nodes connected to the boundary in grey
            SP = shortestpathtree(G_HLD,numnodes(G_HLD),G_HLD.Nodes.node_ID(find(G_HLD.Nodes.Boundary)));
            highlight(h,SP,'NodeColor',[0.5 0.5 0.5],'EdgeColor',[0.5 0.5 0.5],'Marker','*','LineWidth',1)
            ylabel('node level');
            xlabel('terminal node');
            axis tight
            axis on
            xlim([0 numnodes(G_polygons)])
            y = ylim;
            ylim([0 y(2)])
            box on
            if FullLeaf == 1
                last = 1;
                g1 = G_HLD;
            else
                % extract the subgraph for nodes that are not linked to a boundary node
                g1 = subgraph(G_HLD,find(~G_HLD.Nodes.Boundary));
                % extract the largest five fully connected subgraphs
                last = str2double(app.HLDNumROIDropDown.Value);
            end
            % order by the largest connected subtree
            cc = conncomp(g1,'OutputForm','cell');
            l = cellfun(@(x) length(x),cc);
            [~,idx] = sort(l,'descend');
            % set up plot options
            cols = repmat({'r','g','b','c','m','y'},1,50);
            area_limits = ([floor(min(log(g1.Nodes.node_Area))-0.25) ceil(max(log(g1.Nodes.node_Area))+0.25)]);
            % extract the largest five fully connected subgraphs
            for icc = 1:last
                % extract the HLD subgraph
                g2 = subgraph(g1,cc{idx(icc)});
                % recolor the nodes and edges
                highlight(h,g2.Nodes.node_ID(g2.Edges.EndNodes(:,1)),g2.Nodes.node_ID(g2.Edges.EndNodes(:,2)),'NodeColor',cols{icc},'Marker','o','EdgeColor',cols{icc},'LineWidth',1)
                % get the node-IDs for the subtree to overlay on the image
                Gidx = g1.Nodes.node_ID(cc{idx(icc)});
                % limit the nodes to display to the initial nodes
                Gidx(Gidx>numnodes(G_polygons)) = [];
                % extract the subgraph from the original dual-graph that contain the selected nodes
                g3 = subgraph(G_polygons,Gidx);
                % display the subgraph on the image
                plot(ax(1),g3, 'XData',g3.Nodes.node_X_pix,'YData',g3.Nodes.node_Y_pix, ...
                    'NodeColor','g','MarkerSize',1,'Marker', 'none', 'NodeLabel', [], ...
                    'EdgeColor',cols{icc},'EdgeAlpha',1,'EdgeLabel', [],'LineWidth',1);
            end
            drawnow
            if ExportFigs
                warning('off','MATLAB:prnRenderer:opengl');
                %export_fig(name,'-png','-r600','-painters',hfig)
                export_fig(name,'-png','-r600',hfig)
                %     saveas(hfig,name)
            end
        end
        
        function [histw, histv] = histwv(~,v, w, min, max, bins)
            % code from Brent
            %Inputs:
            %vv - values
            %ww - weights
            %minV - minimum value
            %maxV - max value
            %bins - number of bins (inclusive)
            
            %Outputs:
            %histw - weighted histogram
            %histv (optional) - histogram of values
            
            delta = (max-min)/(bins-1);
            subs = round((v-min)/delta)+1;
            
            histv = accumarray(subs,1,[bins,1]);
            histw = accumarray(subs,w,[bins,1]);
        end
        
        function funcSetUpOutputFolders(app)
            dirInitial = app.var.dirInitial;
            app.var.dir_out_summary = ['summary' filesep];
            if ~exist([dirInitial filesep 'summary' filesep 'images'],'dir')
                mkdir([dirInitial filesep 'summary' filesep 'images'])
                if isunix
                    fileattrib([dirInitial filesep 'summary' filesep 'images'],'+w','a')
                end
            end
            if ~exist([dirInitial filesep 'summary' filesep 'width'],'dir')
                mkdir([dirInitial filesep 'summary' filesep 'width'])
                if isunix
                    fileattrib([dirInitial filesep 'summary' filesep 'width'],'+w','a')
                end
            end
            if ~exist([dirInitial filesep 'summary' filesep 'data'],'dir')
                mkdir([dirInitial filesep 'summary' filesep 'data'])
                if isunix
                    fileattrib([dirInitial filesep 'summary' filesep 'data'],'+w','a')
                end
            end
            if ~exist([dirInitial filesep 'summary' filesep 'HLD'],'dir')
                mkdir([dirInitial filesep 'summary' filesep 'HLD'])
                if isunix
                    fileattrib([dirInitial filesep 'summary' filesep 'HLD'],'+w','a')
                end
            end
            app.var.dir_out_images = ['summary' filesep 'images' filesep];
            app.var.dir_out_width = ['summary' filesep 'width' filesep];
            app.var.dir_out_data = ['summary' filesep 'data' filesep];
            app.var.dir_out_HLD = ['summary' filesep 'HLD' filesep];
        end
        
        function funcAnalysisDisplay(app, target)
            %             if ~contains(app.GraphImageDropDown.Items,target)
            %                 app.GraphImageDropDown.Items = [app.GraphImageDropDown.Items,target];
            %             end
            %             app.GraphImageDropDown.Value = target;
            hold(app.DisplayAxes,'off')
            switch target
                case 'clahe'
                    im = imresize(app.images.CLAHE,size(app.images.CNNmean));
                    imshow(im,[],'Parent',app.DisplayAxes);
                otherwise
                    if size(app.images.(target),3) ==1 || size(app.images.(target),3) == 3
                        imshow(app.images.(target),[],'Parent',app.DisplayAxes);
                    else
                        montage(app.images.(target),'Parent',app.DisplayAxes)
                    end
            end
            %axis(app.DisplayAxes,'tight')
            app.StatusLabel.Text = ['Displaying image ' target];
        end
        
        function funcGraphOptionsUpdate(app)
            % set the options for the veins
            idx = ismember(app.ReferenceTable.object,'veins');
            label = unique(app.ReferenceTable.parameter(idx));
            label(strcmpi(label,'')) = [];
            app.GraphEdgeDropDown.Items = ['none'; label; 'polygon_Area';'white';'black'];
            % set the options for the nodes
            idx = ismember(app.ReferenceTable.object,'nodes');
            label = unique(app.ReferenceTable.parameter(idx));
            label(strcmpi(label,'')) = [];
            app.GraphNodeDropDown.Items = ['none'; label];
        end
        
        function funcPolygonOptionsUpdate(app)
            switch app.PolygonModeDropDown.Value
                case 'polygons'
                    idx = ismember(app.ReferenceTable.object,'polygons');
                case 'areoles'
                    idx = ismember(app.ReferenceTable.object,'areoles');
            end
            label = unique(app.ReferenceTable.parameter(idx));
            label(strcmpi(label,'')) = [];
            app.PolygonNodeDropDown.Items = ['none'; label];
        end
        
        function funcDisplayGraph(app)
            % clear any existing graph
            funcClearGraph(app,{'Edges';'Nodes';'Polygons';'DualGraph'})
            % plot either a graph or an embedded colour-coded pixel skeleton
            style = app.GraphEdgeStyleDropDown.Value;
            if app.GraphEdgePlotCheckBox.Value || app.GraphNodePlotCheckBox.Value
                if isfield(app.var,'G_veins')
                    switch style
                        case {'graph'}
                            funcDisplayGraphEdges(app);
                        case {'pixel'}
                            funcDisplayGraphPixels(app);
                    end
                    funcDisplayGraphNodes(app)
                end
            end
        end
        
        function funcDisplayGraphEdges(app)
            funcClearGraph(app,{'Edges'})
            if app.GraphEdgePlotCheckBox.Value
                lookup = app.ReferenceTable;
                units = app.GraphUnitsDropDown.Value;
                edge_target = app.GraphEdgeDropDown.Value;
                G = app.var.G_veins;
                E_color = 'none';
                switch edge_target
                    case 'none'
                    otherwise
                        % get the edge metric for display
                        idx = ismember(lookup.parameter,edge_target);
                        parameter = lookup.parameter(idx);
                        parameter = parameter(1);
                        E_label = lookup.label{idx};
                        if ismember(parameter,G.Edges.Properties.VariableNames)
                            Eidx  = find(strncmp(G.Edges{:,'Type'},'E',1));
                            E = G.Edges{:,parameter};
                            switch units
                                case 'pixels'
                                    E_label_units = lookup.pixels{idx};
                                    E_scaling = 1;
                                case 'microns'
                                    E_label_units = lookup.microns{idx};
                                    E_scaling = app.param.MicronPerPixel.^lookup.scaling_coefficient(idx);
                                case 'mm'
                                    E_label_units = lookup.mm{idx};
                                    E_scaling = app.param.MicronPerPixel./1000.^lookup.scaling_coefficient(idx);
                            end
                            E = E.*E_scaling;
                            % Adjust for log or linear scaling of the edges
                            if app.GraphEdgeLogUseCheckBox.Value
                                E = log10(E);
                                E_prefix = 'log_{10} ';
                            else
                                E_prefix = [];
                            end
                            E(isinf(E)) = nan;
                            E_min = min(E(Eidx),[],'omitnan');
                            E_max = max(E(Eidx),[],'omitnan');
                            % normalise the range between 2 and 256 as an index into the
                            % colourmap
                            E_idx = ceil(254.*((E-E_min)./(E_max-E_min)))+1;
                            E_idx(E_idx>255) = 256;
                            E_idx(E_idx<1) = 1;
                            % set any nan values to 1 in the index
                            E_idx(isnan(E_idx)) = 1;
                            % set any nan values to zero in the actual metric
                            E(isnan(E)) = 0;
                            % the index should be real numbers normalised between 1 and 256 at
                            % this point.
                            EdgeCmap = app.var.EdgeCmap;
                            E_color =  EdgeCmap(E_idx,:);
                            % set the colorbar limits
                            colormap(app.EdgeColorbarAxes,EdgeCmap)
                            if ~isgraphics(app.hEdgeColorBar)
                                % recreate the colorbar
                                app.hEdgeColorBar = colorbar(app.EdgeColorbarAxes,'location','east','TickDirection','out','TickLength',0.02,'FontSize',8);
                            end
                            app.hEdgeColorBar.Label.String = [E_prefix ' ' E_label ' ' E_label_units];
                            app.hEdgeColorBar.Label.Interpreter = 'Tex';
                            if E_max>E_min
                                app.EdgeColorbarAxes.CLim = [E_min E_max];
                            else
                                app.EdgeColorbarAxes.CLim = [0 1];
                            end
                        end
                end
                x_pix = G.Nodes.node_X_pix;
                y_pix = G.Nodes.node_Y_pix;
                hold(app.DisplayAxes, 'on')
                E_width = str2double(app.GraphEdgeWidthDropDown.Value);
                N_size = str2double(app.GraphNodeSizeDropDown.Value);
                if app.GraphNodePlotCheckBox.Value
                    hg = plot(app.DisplayAxes,G, 'XData',x_pix,'YData',y_pix, ...
                        'NodeColor','none','MarkerSize',N_size,'Marker', 'o', 'NodeLabel', [], ...
                        'EdgeColor',E_color,'EdgeAlpha',1,'EdgeLabel', [],'LineWidth',E_width,'Tag','Edges');
                else
                    hg = plot(app.DisplayAxes,G, 'XData',x_pix,'YData',y_pix, ...
                        'NodeColor','none','MarkerSize',5,'Marker', 'none', 'NodeLabel', [], ...
                        'EdgeColor',E_color,'EdgeAlpha',1,'EdgeLabel', [],'LineWidth',E_width,'Tag','Edges');
                end
                if app.GraphEdgeLabelCheckBox.Value
                    labeledge(hg,1:numedges(G),cellstr(num2str(round(E,2,'significant'))))
                    hg.EdgeLabelColor = app.GraphEdgeLabelColourDropDown.Value;
                    hg.NodeFontSize = 8+N_size;
                end
            end
        end
        
        function funcDisplayGraphNodes(app)
            funcClearGraph(app,{'Nodes'})
            if app.GraphNodePlotCheckBox.Value
                lookup = app.ReferenceTable;
                units = app.GraphUnitsDropDown.Value;
                node_target = app.GraphNodeDropDown.Value;
                G = app.var.G_veins;
                N_color = 'none';
                switch node_target
                    case 'none'
                        N = G.Nodes.('node_ID');
                        N_color = 'none';
                    otherwise
                        % get the edge metric for display
                        idx = ismember(lookup.parameter,node_target);
                        parameter = lookup.parameter(idx);
                        parameter = parameter(1);
                        N_label = lookup.label{idx};
                        if ismember(parameter,G.Nodes.Properties.VariableNames)
                            % get the index of the edge nodes
                            F_idx = contains(G.Nodes.node_Type,'F');
                            % get the node metric
                            N = G.Nodes.(node_target);
                            switch units
                                case 'pixels'
                                    N_label_units = lookup.pixels{idx};
                                    N_scaling = 1;
                                case 'microns'
                                    N_label_units = lookup.microns{idx};
                                    N_scaling = app.param.MicronPerPixel.^lookup.scaling_coefficient(idx);
                                case 'mm'
                                    N_label_units = lookup.mm{idx};
                                    N_scaling = app.param.MicronPerPixel./1000.^lookup.scaling_coefficient(idx);
                            end
                            N = N.*N_scaling;
                            % Adjust for log or linear scaling of the
                            % nodes
                            if app.GraphNodeLogUseCheckBox.Value
                                N = log10(N);
                                N_prefix = 'log_{10} ';
                            else
                                N_prefix = [];
                            end
                            N(isinf(N)) = nan;
                            N(F_idx) = nan;
                            % Normalise the metric between min and max and generate an index
                            % into the colourmap
                            N_min = min(N,[], 'omitnan');
                            N_max = max(N,[], 'omitnan');
                            %                                     % Normalise the metric between min and max
                            %                                     N_cmin = min(N);
                            %                                     N_cmax = max(N);
                            N_idx = ceil(254.*((N-N_min)./(N_max-N_min)))+1;
                            N_idx(N_idx>255) = 256;
                            N_idx(N_idx<1) = 1;
                            N_idx(isnan(N_idx)) = 1;
                            % use the normalised metric as an index into the colormap
                            NodeCmap = app.var.NodeCmap;
                            N_color = NodeCmap(N_idx,:);
                            % set the colorbar limits
                            colormap(app.NodeColorbarAxes,NodeCmap)
                            if ~isgraphics(app.hNodeColorBar)
                                % recreate the colorbar
                                app.hNodeColorBar = colorbar(app.NodeColorbarAxes,'location','east','TickDirection','out','TickLength',0.02,'FontSize',8);
                            end
                            app.hNodeColorBar.Label.String = [N_prefix ' ' N_label ' ' N_label_units];
                            app.hNodeColorBar.Label.Interpreter = 'Tex';
                            if N_max>N_min
                                app.NodeColorbarAxes.CLim = [N_min N_max];
                            else
                                app.NodeColorbarAxes.CLim = [0 1];
                            end
                        end
                end
                
                x_pix = G.Nodes.node_X_pix;
                y_pix = G.Nodes.node_Y_pix;
                hold(app.DisplayAxes, 'on')
                N_size = str2double(app.GraphNodeSizeDropDown.Value);
                
                if app.GraphNodePlotCheckBox.Value
                    hg = plot(app.DisplayAxes,G, 'XData',x_pix,'YData',y_pix, ...
                        'NodeColor',N_color,'MarkerSize',N_size,'Marker', 'o', 'NodeLabel', [], ...
                        'EdgeColor','none','EdgeLabel', [],'LineWidth',1,'Tag','Nodes');
                else
                    hg = plot(app.DisplayAxes,G, 'XData',x_pix,'YData',y_pix, ...
                        'NodeColor',N_color,'MarkerSize',5,'Marker', 'none', 'NodeLabel', [], ...
                        'EdgeColor','none','EdgeAlpha',1,'EdgeLabel', [],'LineWidth',1,'Tag','Nodes');
                end
                
                if app.GraphNodeLabelCheckBox.Value
                    labelnode(hg,1:numnodes(G),cellstr(num2str(round(N,2,'significant'))))
                    hg.NodeLabelColor = app.GraphNodeLabelColourDropDown.Value;
                    hg.NodeFontSize = 8+N_size;
                end
            end
        end
        
        function funcDisplayGraphPixels(app)
            funcClearGraph(app,{'Edges'})
            edge_target = app.GraphEdgeDropDown.Value;
            h = findobj(app.DisplayAxes,'Tag','graph');
            if ~isempty(h)
                delete(h)
            end
            [nY, nX] = size(app.images.CNNmean);
            % get the graph for the current time point
            if strcmpi(edge_target,'polygon_Area')
                G = [];
                app.StatusLabel.Text = 'no data to plot';
            else
                G = app.var.G_veins;
                edgelist = app.var.edgelist;
            end
            if ~strcmpi(edge_target,'none')
                % get the edge metric for display
                lookup = app.ReferenceTable;
                idx = ismember(lookup.parameter,edge_target);
                parameter = lookup.parameter(idx);
                metric = parameter{1};
                if ismember(metric,G.Edges.Properties.VariableNames)
                    Eidx  = find(strncmp(G.Edges{:,'Type'},'E',1));
                    E = G.Edges{:,metric};
                    % Adjust for log or linear scaling of the edges
                    if app.GraphEdgeLogUseCheckBox.Value
                        E = log10(E);
                        E(isinf(E)) = nan;
                        E_min = min(E(Eidx),[],'omitnan');
                        E_max = max(E(Eidx),[],'omitnan');
                    else
                        E(isinf(E)) = nan;
                        % Normalise the metric between min and max and generate an index
                        % into the colourmap
                        E_min = min(E(Eidx),[], 'omitnan');
                        E_max = max(E(Eidx),[], 'omitnan');
                    end
                    % normalise the range between 2 and 256 as an index into the
                    % colourmap
                    E_idx = ceil(254.*((E-E_min)./(E_max-E_min)))+1;
                    E_idx(E_idx>255) = 256;
                    E_idx(E_idx<1) = 1;
                    hold(app.DisplayAxes,'on')
                    switch edge_target
                        case 'polygons'
                        otherwise
                            % Get the original edge ID to use as an index into the edgeim
                            type = contains(G.Edges{:,'Type'},{'E','F'});
                            % set up a blank image
                            im = zeros([nY,nX],'uint8');
                            sk = false(nY,nX);
                            % get the colormap values for each edge (for the image values
                            % range from 0-255, so subtract 1)
                            V_int = num2cell(E_idx(type)-1);
                            % get the linear pixel index for each edge remaining
                            P_idx = cellfun(@(x) sub2ind(size(im) ,x(:,1),x(:,2)),edgelist(type),'UniformOutput',0);
                            % concatenate all the pixels indices to give a single vector
                            P_all = cat(1,P_idx{:});
                            % duplicate the value for the number of pixels in each edge
                            V_idx = cellfun(@(x,y) repmat(y,length(x),1), P_idx, V_int','UniformOutput',0);
                            % concatenate all the values into a single vector
                            V_all = cat(1,V_idx{:});
                            % set the edge ID pixels to the edge value for each edge in the image
                            im(P_all) = single(V_all);
                            sk(P_all) = 1;
                            E_width = str2double(app.GraphEdgeWidthDropDown.Value);
                            if E_width > 1
                                im = imdilate(im, ones(round(E_width)));
                            end
                            imrgb = ind2rgb(im,app.var.EdgeCmap);
                            % now get the base image
                            base = app.GraphImageDropDown.Value;
                            switch base
                                case 'crop'
                                    im2 = app.images.crop;
                                case 'clahe'
                                    im2 = imresize(app.images.CLAHE, size(app.images.CNNmean));
                                case 'CNNmean'
                                    im2 = app.images.CNNmean.*255;
                                case 'black'
                                    im2 = zeros([nY,nX],'single');
                                case 'white'
                                    im2 = ones([nY,nX],'single');
                                otherwise
                                    im2 = app.images.(base);
                            end
                            if E_width > 1
                                sk = imdilate(sk,ones(E_width));
                            end
                            im2(sk) = 0;
                            im2rgb = ind2rgb(uint8(im2),gray(256));
                            % combine the two
                            app.images.graphim = [];
                            app.images.graphim = imadd(im2rgb,imrgb);
                            funcImageDisplay(app,'graphim')
                    end
                end
            end
        end
        
        function funcDisplayPolygonDualGraph(app)
            funcClearGraph(app,{'DualGraph'})
            G = app.var.G_polygons;
            if app.PolygonDualGraphPlotCheckBox.Value
                E = G.Edges{:,'Width'};
                E(isinf(E)) = nan;
                % Normalise the metric between min and max and generate an index
                % into the colourmap
                E_min = min(E,[], 'omitnan');
                E_max = max(E,[], 'omitnan');
                %                 end
                % normalise the range between 2 and 256 as an index into the
                % colourmap
                E_idx = ceil(254.*((E-E_min)./(E_max-E_min)))+1;
                % set any nan values to 1 in the index
                E_idx(isnan(E_idx)) = 1;
                cmap = jet(256);
                cmap(1,:) = 0;
                E_color = cmap(E_idx,:);
                x_pix = G.Nodes.node_X_pix;
                y_pix = G.Nodes.node_Y_pix;
                hold(app.DisplayAxes, 'on')
                E_width = str2double(app.GraphEdgeWidthDropDown.Value);
                plot(app.DisplayAxes,G, 'XData',x_pix,'YData',y_pix, ...
                    'NodeColor','none','MarkerSize',5,'Marker', 'none', 'NodeLabel', [], ...
                    'EdgeColor',E_color,'EdgeAlpha',1,'EdgeLabel', [],'LineWidth',E_width,'Tag','DualGraph');
            end
        end
        
        function funcDisplayPolygonNodes(app)
            funcClearGraph(app,{'PolygonNodes'})
            if app.PolygonNodePlotCheckBox.Value
                lookup = app.ReferenceTable;
                units = app.GraphUnitsDropDown.Value;
                mode = app.PolygonModeDropDown.Value;
                node_target = app.PolygonNodeDropDown.Value;
                switch node_target
                    case 'none'
                        
                    otherwise
                        % get the metric for display
                        idx = ismember(lookup.parameter,[node_target]);
                        parameter = lookup.parameter(idx);
                        parameter = parameter{1};
                        switch mode
                            case 'areoles'
                                parameter = strrep(parameter,'areole_','');
                                G = app.var.G_areoles;
                            case 'polygons'
                                parameter = strrep(parameter,'polygon_','');
                                G = app.var.G_polygons;
                        end
                        N_label = lookup.label{idx};
                        if ismember(parameter,G.Nodes.Properties.VariableNames)
                            % get the node metric
                            N = G.Nodes.(parameter);
                            switch units
                                case 'pixels'
                                    N_label_units = lookup.pixels{idx};
                                    N_scaling = 1;
                                case 'microns'
                                    N_label_units = lookup.microns{idx};
                                    N_scaling = app.param.MicronPerPixel.^lookup.scaling_coefficient(idx);
                                case 'mm'
                                    N_label_units = lookup.mm{idx};
                                    N_scaling = app.param.MicronPerPixel./1000.^lookup.scaling_coefficient(idx);
                            end
                            N = N.*N_scaling;
                            % Adjust for log or linear scaling of the
                            % nodes
                            if app.PolygonLogUseCheckBox.Value
                                N = log10(N);
                                N_prefix = 'log_{10} ';
                            else
                                N_prefix = [];
                            end
                            N(isinf(N)) = nan;
                            % Normalise the metric between min and max and generate an index
                            % into the colourmap
                            N_min = min(N,[], 'omitnan');
                            N_max = max(N,[], 'omitnan');
                            N_idx = ceil(254.*((N-N_min)./(N_max-N_min)))+2;
                            N_idx(isnan(N_idx)) = 1;
                            % use the normalised metric as an index into the colormap
                            PolygonCmap = app.var.PolygonCmap;
                            N_color = PolygonCmap(N_idx,:);
                            % set the colorbar limits
                            colormap(app.PolygonColorbarAxes,PolygonCmap)
                            if ~isgraphics(app.hPolygonColorBar)
                                % recreate the colorbar
                                app.hPolygonColorBar = colorbar(app.PolygonColorbarAxes,'location','east','TickDirection','out','TickLength',0.02,'FontSize',8);
                            end
                            app.hPolygonColorBar.Label.String = [N_prefix ' ' N_label ' ' N_label_units];
                            app.hPolygonColorBar.Label.Interpreter = 'Tex';
                            if N_max>N_min
                                app.PolygonColorbarAxes.CLim = [N_min N_max];
                            else
                                app.PolygonColorbarAxes.CLim = [0 1];
                            end
                        end
                        x_pix = G.Nodes.node_X_pix;
                        y_pix = G.Nodes.node_Y_pix;
                        % clear any existing graph
                        hold(app.DisplayAxes, 'on')
                        N_size = str2double(app.PolygonSizeDropDown.Value);
                        hg = plot(app.DisplayAxes,G, 'XData',x_pix,'YData',y_pix, ...
                            'NodeColor',N_color,'MarkerSize',N_size,'Marker', 'o', 'NodeLabel', [], ...
                            'EdgeColor','none','EdgeAlpha',1,'EdgeLabel', [],'LineWidth',1,'Tag','PolygonNodes');
                        if app.PolygonNodeLabelCheckBox.Value
                            labelnode(hg,1:numnodes(G),cellstr(num2str(round(N,2,'significant'))))
                            hg.NodeLabelColor = app.PolygonLabelColourDropDown.Value;
                            hg.NodeFontSize = 8+N_size;
                        end
                end
            end
        end
        
        function funcClearGraph(app,mode)
            for iM = 1:numel(mode)
                h = findobj(app.DisplayAxes,'Tag',mode{iM});
                delete(h)
            end
        end
        
        function funcSetUpEdgeColormap(app)
            cmap = app.GraphEdgeColorMapDropDown.Value;
            switch app.GraphEdgeColorMapDropDown.Value
                case {'L1';'L3';'L7';'L8';'L9';'D2';'D3';'D7';'R2'}
                    app.var.EdgeCmap = colorcet(cmap,'N',256);
                case 'white'
                    app.var.EdgeCmap = ones(256,3);
                case 'black'
                    app.var.EdgeCmap = zeros(256,3);
                otherwise
                    app.var.EdgeCmap = colormap(app.EdgeColorbarAxes,[cmap '(256)']);
            end
            app.var.EdgeCmap(1,:) = 0;
            app.var.EdgeCmap(256,:) = app.var.EdgeCmap(256,:).*0.5;
            app.EdgeColorbarAxes.BackgroundColor = [0.94 0.94 0.94];
            axis(app.EdgeColorbarAxes,'off')
            colormap(app.EdgeColorbarAxes,app.var.EdgeCmap)
            if isgraphics(app.hEdgeColorBar)
                delete(app.hEdgeColorBar)
            end
            app.hEdgeColorBar = colorbar(app.EdgeColorbarAxes,'location','east','TickDirection','out','TickLength',0.02,'FontSize',8);
            pos = app.hEdgeColorBar.Position;
            app.hEdgeColorBar.Position = [0.6 pos(2) pos(3).*2 pos(4)];
            title(app.hEdgeColorBar,'veins');
        end
        
        
        function funcSetUpNodeColormap(app)
            cmap = app.GraphNodeColorMapDropDown.Value;
            switch app.GraphNodeColorMapDropDown.Value
                case {'L1';'L3';'L7';'L8';'L9';'D2';'D3';'D7';'R2'}
                    app.var.NodeCmap = colorcet(cmap,'N',256);
                case 'black'
                    app.var.NodeCmap = zeros(256,3);
                case 'white'
                    app.var.NodeCmap = ones(256,3);
                otherwise
                    app.var.NodeCmap = colormap(app.NodeColorbarAxes,[cmap '(256)']);
            end
            %app.var.NodeCmap(1,:) = 0;
            app.NodeColorbarAxes.BackgroundColor = [0.94 0.94 0.94];
            axis(app.NodeColorbarAxes,'off')
            colormap(app.NodeColorbarAxes,app.var.NodeCmap)
            if isgraphics(app.hEdgeColorBar)
                delete(app.hNodeColorBar)
            end
            app.hNodeColorBar = colorbar(app.NodeColorbarAxes,'location','east','TickDirection','out','TickLength',0.02,'FontSize',8);
            pos = app.hNodeColorBar.Position;
            app.hNodeColorBar.Position = [0.6 pos(2) pos(3).*2 pos(4)];
            title(app.hNodeColorBar,'Nodes');
        end
        
        function funcSetUpPolygonColormap(app)
            cmap = app.PolygonNodeColorMapDropDown.Value;
            switch app.PolygonNodeColorMapDropDown.Value
                case {'L1';'L3';'L7';'L8';'L9';'D2';'D3';'D7';'R2'}
                    app.var.PolygonCmap = colorcet(cmap,'N',256);
                otherwise
                    app.var.PolygonCmap = colormap(app.PolygonColorbarAxes,[cmap '(256)']);
            end
            app.var.PolygonCmap(1,:) = 1;
            app.PolygonColorbarAxes.BackgroundColor = [0.94 0.94 0.94];
            axis(app.PolygonColorbarAxes,'off')
            colormap(app.PolygonColorbarAxes,app.var.PolygonCmap)
            if isgraphics(app.hPolygonColorBar)
                delete(app.hPolygonColorBar)
            end
            app.hPolygonColorBar = colorbar(app.PolygonColorbarAxes,'location','east','TickDirection','out','TickLength',0.02,'FontSize',8);
            pos = app.hPolygonColorBar.Position;
            app.hPolygonColorBar.Position = [0.6 pos(2) pos(3)*2 pos(4)];
            title(app.hPolygonColorBar,'polygons');
        end
        
        function funcResultsTarget(app)
            % Sets the dropdown menu options for plotting a graph
            % veins and nodes - gives options for edges and nodes
            % polygons - gives options for inter-cord regions
            % features - gives options for features
            % HLD - plots the hierarchical decomposition tree
            % the selection also updates the x and y axis titles
            
            % get the edge metric for display
            lookup = app.ReferenceTable;
            names = {' '};
            switch app.ResultsTargetDropDown.Value
                case 'veins'
                    idx = ismember(lookup.object,'veins');
                    names = lookup.parameter(idx);
                case 'nodes'
                    idx = ismember(lookup.object,'nodes');
                    names = lookup.parameter(idx);
                case 'areoles'
                    idx = ismember(lookup.object,'areoles');
                    names = lookup.parameter(idx);
                case 'polygons'
                    idx = ismember(lookup.object,'polygons');
                    names = lookup.parameter(idx);
                case 'HLD'
                    idx = ismember(lookup.object,'HLD');
                    names = lookup.parameter(idx);
                case 'summary'
                    idx = ismember(lookup.object,'summary');
                    names = lookup.parameter(idx);
            end
            
            app.ResultsXaxisDropDown.Items = names;
            app.ResultsYaxisDropDown.Items = names;
        end
        
        function funcResultsPlot(app)
            units = app.ResultsUnitsDropDown.Value;
            % clear the axes
            app.ResultsAxes.View = [0 90];
            axis(app.ResultsAxes,'normal');
            if app.ResultsHoldCheckBox.Value
                hold(app.ResultsAxes,'on')
            else
                cla(app.ResultsAxes)
                hold(app.ResultsAxes,'off')
            end
            target = app.ResultsTargetDropDown.Value;
            x_var = app.ResultsXaxisDropDown.Value;
            y_var = app.ResultsYaxisDropDown.Value;
            plot_type = app.ResultsPlotTypeDropDown.Value;
            cmap = jet(256);
            colormap(app.ResultsAxes,cmap)
            X = [];
            Y = [];
            % get the appropriate variables
            %             switch target
            %                 case 'HLDTree'
            %                     hold off
            %                     iC = 1;
            %                     if isfield(app.var,'parent')
            %                         treeplot(app.var.parent,'','r');
            %                     else
            %                         app.StatusLabel.Text, 'String','No data to plot')
            %                     end
            %                     ylabel('node level');
            %                     xlabel('terminal nodes');
            %                     set(gca,'FontName','Helvetica','FontUnits','pixels','FontSize',11,'XTickLabel',[]);
            %                 case 'profile'
            %                     fnc_feature_profile_plot(handles)
            %                 otherwise
            lookup = app.ReferenceTable;
            switch plot_type
                case '1D hist'
                    nV = 1;
                case {'scatter';'2D hist'}
                    nV = 2;
            end
            for variable = 1:nV
                if variable == 1
                    metric = y_var;
                    idx = ismember(lookup.parameter,metric);
                else
                    metric = x_var;
                    idx = ismember(lookup.parameter,metric);
                end
                % get the edge metric for the axis labels
                label = lookup.label{idx};
                % get the units for labelling
                switch units
                    case 'pixels'
                        label_units = lookup.pixels{idx};
                        scaling = 1;
                    case 'microns'
                        label_units = lookup.microns{idx};
                        scaling = app.param.MicronPerPixel.^lookup.scaling_coefficient(idx);
                    case 'mm'
                        label_units = lookup.mm{idx};
                        scaling = app.param.MicronPerPixel./1000.^lookup.scaling_coefficient(idx);
                end
                if variable == 1
                    if app.ResultsYlogCheckBox.Value
                        y_label = ['log_{10} ' label ' ' label_units];
                    else
                        y_label = [label ' ' label_units];
                    end
                else
                    if app.ResultsXlogCheckBox.Value
                        x_label = ['log_{10} ' label ' ' label_units];
                    else
                        x_label = [label ' ' label_units];
                    end
                end
                % now get the data
                switch target
                    case 'veins'
                        if app.ResultsExcludeBoundaryCheckBox.Value
                            E_idx = contains(app.var.G_veins.Edges.Type,{'EL','ET'});
                        else
                            E_idx = contains(app.var.G_veins.Edges.Type,'E');
                        end
                        if variable == 1
                            Y = app.var.G_veins.Edges{E_idx,metric}.*scaling;
                        else
                            X = app.var.G_veins.Edges{E_idx,metric}.*scaling;
                        end
                    case 'nodes'
                        N_idx = contains(app.var.G_veins.Nodes.node_Type,'E');
                        if variable == 1
                            Y = app.var.G_veins.Nodes{N_idx,metric}.*scaling;
                        else
                            X = app.var.G_veins.Nodes{N_idx,metric}.*scaling;
                        end
                    case 'areoles'
                        if ~contains(metric,'ID')
                            metric = strrep(metric,'areole_','');
                        end
                        if variable == 1
                            Y = app.var.G_areoles.Nodes{:,metric}.*scaling;
                        else
                            X = app.var.G_areoles.Nodes{:,metric}.*scaling;
                        end
                    case 'polygons'
                        if ~contains(metric,'ID')
                            metric = strrep(metric,'polygon_','');
                        end
                        if variable == 1
                            Y = app.var.G_polygons.Nodes{:,metric}.*scaling;
                        else
                            X = app.var.G_polygons.Nodes{:,metric}.*scaling;
                        end
                    case 'HLD'
                        metric = strrep(metric,'HLD_','');
                        if variable == 1
                            Y = app.var.G_HLD.Nodes{:,metric}.*scaling;
                        else
                            X = app.var.G_HLD.Nodes{:,metric}.*scaling;
                        end
                    case 'summary'
                        if isfield(app.var,'graph_analysis')
                            if variable == 1
                                Y = app.graph_analysis.(metric).*scaling;
                            else
                                X = app.graph_analysis.(metric).*scaling;
                            end
                        end
                end
            end
            if ~isempty(Y)
                switch plot_type
                    case {'scatter'}
                        if app.ResultsYlogCheckBox.Value
                            Y = log10(Y);
                        end
                        if app.ResultsXlogCheckBox.Value
                            X = log10(X);
                        end
                        scatter(app.ResultsAxes,X,Y,'.')
                        ylabel(app.ResultsAxes,y_label, 'interpreter','tex')
                        xlabel(app.ResultsAxes,x_label, 'interpreter','tex')
                    case {'1D hist'}
                        if app.ResultsYlogCheckBox.Value
                            histogram(app.ResultsAxes,log10(Y), 50,'FaceAlpha',1,'EdgeColor','k')
                        else
                            histogram(app.ResultsAxes,Y, 50,'FaceAlpha',1,'EdgeColor','k')
                        end
                        xlabel(app.ResultsAxes,y_label, 'interpreter','tex')
                        ylabel(app.ResultsAxes, 'frequency')
                    case '2D hist'
                        if app.ResultsYlogCheckBox.Value
                            Y = log10(Y);
                        end
                        if app.ResultsXlogCheckBox.Value
                            X = log10(X);
                        end
                        histogram2(app.ResultsAxes,X,Y)
                        xlabel(app.ResultsAxes,x_label, 'interpreter','tex')
                        ylabel(app.ResultsAxes,y_label, 'interpreter','tex')
                end
                app.ResultsAxes.FontName = 'Helvetica';
                app.ResultsAxes.FontUnits = 'pixels';
                app.ResultsAxes.FontSize = 11;
                app.ResultsAxes.Box = 'on';
                app.ResultsAxes.Title.String = target;
            end
            app.var.X = X;
            app.var.Y = Y;
            %             end
        end
        
        function funcEnablePanels(app,mode,target)
            for iP = 1:numel(target)
                switch target{iP}
                    case 'CNN'
                        h = app.CNNPredictTab.Children;
                    otherwise
                        h = app.([target{iP} 'Panel']).Children;
                end
                set(h,'Enable',mode);
            end
        end
        
        
        %         function funcSlidingPrediction(app);% temporary code to paste in to CNN
        %             for iF = 1:ensemble
        %                 fname = filenames{idx(iF)};
        %                 app.StatusLabel.Text = ['Calculating the CNN prediction for iteration ' num2str(iF) ' using network ' fname '. This will take some time. Please wait...'];drawnow
        %                 temp = load(fullfile(dir_models,fname));
        %                 net = temp.net;
        %                 myfun = @(block_struct) predict(net,block_struct.data);
        %                 border_size = [(256-stride)/2 (256-stride)/2];
        %                 n = 0;
        %                 imtemp = zeros(size(app.images.CLAHE,1),size(app.images.CLAHE,2),6);
        %                 for iS = 0:100:256
        %                     n = n+1;
        %                     im = padarray(app.images.CLAHE,[iS iS],'replicate','pre');
        %                     cnn = blockproc(im,[stride stride],myfun,'PadPartialBlocks',true,'PadMethod','replicate','BorderSize',border_size,'DisplayWaitbar',true);
        %                     imtemp(:,:,n) = cnn(iS+1:iS+size(app.images.CLAHE,1),iS+1:iS+size(app.images.CLAHE,2));
        %                 end
        %                 figure
        %                 montage(imtemp)
        %                 app.images.CNN_2(:,:,iF) = imresize(mean(imtemp,3),size(app.images.crop));
        %             end
        %         end
        
        function funcAnalysisSaveFigure(app)
            ExportFigs = 1;
            [~,name,~] = fileparts(app.var.fname) ;
            EdgeWidth = app.param.AnalysisEdgeWidth;
            app.StatusLabel.Text = 'Setting up the results figure. Please wait...';drawnow
            skel = imerode(single(cat(3,1-app.images.skTree,1-app.images.skLoop,1-app.images.skTree)), ones(5));
            pics = {app.images.crop,max(app.images.CNNmean(:))-app.images.CNNmean,skel,app.images.coded,app.images.areolesRGB,max(app.images.CNNmean(:))-app.images.CNNmean};
            graphs = {'none','none','none','none','none','Width'};
            % have to make the original a single plane
            titles = {'CLAHE','CNN','Skeleton','width','areoles','dual graph'};
            funcDisplayFigure(app,pics,graphs,titles,app.var.G_polygons,EdgeWidth,(1:6),[app.var.dir_out_images name '_Figure'],ExportFigs);
            % save the coded images
            [nY,nX,~] = size(app.images.coded);
            fout = [app.var.dir_out_images name '_fullwidth.png'];
            imwrite(app.images.coded,fout,'png','Xresolution',nX,'Yresolution',nY)
            %             fout = [app.var.dir_out_images FolderName '_centrewidth.png'];
            %             imwrite(app.images.coded_CW,fout,'png','Xresolution',nX,'Yresolution',nY)
        end
        
        function funcHLDSaveFigure(app)
            ExportFigs = 1;
            FullLeaf = app.HLDFullLeafCheckBox.Value;
            [~,FolderName,~] = fileparts(app.var.fname) ;% not actually a folder - just the root of the filename at the moment
            app.StatusLabel.Text = 'Setting up the HLD figures. Please wait...';drawnow
            display_HLD(app,app.var.G_polygons,app.images.CNNmean,app.var.G_HLD,FullLeaf,[app.var.dir_out_HLD FolderName '_HLD'],ExportFigs);
            display_HLD_figure(app,app.var.G_polygons,app.images.CNNmean,app.var.G_HLD,FullLeaf,[app.var.dir_out_HLD FolderName '_HLD_2'],ExportFigs);
            
            app.StatusLabel.Text = 'Saving the HLD figures. Please wait...';drawnow
            [nY,nX,~] = size(app.images.HLD_image);
            % save the color-coded width images
            fout = [app.var.dir_out_images FolderName '_HLD_level.png'];
            mx = double(max(app.images.HLD_image(:)));
            cmap = jet(mx+1);
            cmap(1,:) = 0;
            %HLD_image = colfilt(HLD_image,[3 3],'sliding',@max);
            app.images.HLD_image(app.images.bw_polygons) = 0;
            hfig = figure;
            imshow(ind2rgb(app.images.HLD_image,cmap))
            imwrite(ind2rgb(app.images.HLD_image,cmap),fout,'png','Xresolution',nX,'Yresolution',nY)
            %                 delete(hfig)
        end
        
        function funcSave2Excel(app,graph,target)
            [~,FolderName,~] = fileparts(app.var.fname) ;% not actually a folder - just the root of the filename at the moment
            fileout = fullfile(app.var.dir_out_data,[FolderName '.xlsx']);
            % extract the edges or nodes
            T = app.var.(graph).(target);
            % add in the filename
            T.name = repmat({FolderName},[height(T),1]);
            % reorder the graph so the filename is first
            T = T(:,[end:end 1:end-1]);
            warning('off','MATLAB:xlswrite:AddSheet')
            mode = strrep(graph,'G_','');
            writetable(T,fileout,'Sheet',[mode ' ' target],'WriteVariableNames',true);
        end
        
        function funcGetModels(app)
            [app.var.CNNModelDir, app.var.CNNModelFilenames] = funcGetCNNModelNames(app);
            [app.var.CNNModelIndex, app.var.CNNModelTable] = funcGetCNNModelIndex(app);
            nModels = str2double(app.CNNEnsembleDropDown.Value);
            switch app.CNNModeDropDown.Value
                case 'random'
                    %                     exclude = [5,6,8,17,19,24,27,33,37,38];
                    %                     population = setdiff(1:38,exclude);
                    %                     idx = randsample(population,ensemble);
                    idx = randsample(1:38,nModels);
                case 'fixed'
                    idx = app.var.CNNModelIndex;
                case 'select'
                    idx = randsample(find(app.var.CNNModelIndex),nModels);
            end
            app.var.CNNModelNames = app.var.CNNModelFilenames(idx);
        end
        
        function [dir_models,filenames] = funcGetCNNModelNames(app)
            % check for the location of the trained networks
            if ~exist('Matlab models','dir')
                dir_models = uigetdir('Select folder with trained models');
            else
                s = what('Matlab models');
                dir_models = s.path;
            end
            figure(app.LeafVeinGUI)
            %
            % get all the trained networks in the current folder
            dir_struct = dir(dir_models);% read in the directory structure
            file_idx = ~[dir_struct.isdir];% strip out any directories
            [sorted_names,~] = sortrows({dir_struct(file_idx).name}');% sort the filenames in order
            % now show only files with selected extensions
            fnc_find_ext = @(file_ext) ~cellfun(@isempty, regexpi(sorted_names,['\w+' file_ext],'match')); % find any files with the appropriate extension
            included = cellfun(fnc_find_ext, {'.mat'}, 'UniformOutput',0);% feed array of extensions to the find function
            include_idx = max(cell2mat(included'),[],2);% collapse the boolean output into a linear index
            filenames = sorted_names(include_idx);
        end
        
        function [IdxList,IdxTable] = funcGetCNNModelIndex(app)
            IdxTable = app.CNNModelTable.Data;
            data = cell2mat(IdxTable);
            IdxList = reshape(data',[],1);
            IdxList(39:end) = [];
        end
        
        function funcAnalysisSaveData(app)
            [~,FolderName,~] = fileparts(app.var.fname) ;% not actually a folder - just the root of the filename at the moment
            app.StatusLabel.Text = 'Saving data to Excel. Please wait...';drawnow
            funcSave2Excel(app,'G_veins','Nodes')
            funcSave2Excel(app,'G_veins','Edges')
            funcSave2Excel(app,'G_areoles','Nodes')
            funcSave2Excel(app,'G_areoles','Edges')
            funcSave2Excel(app,'G_polygons','Nodes')
            funcSave2Excel(app,'G_polygons','Edges')
            % save the results to a single file
            Results = app.var.Results;
            writetable(Results,fullfile(app.var.dir_out_summary, 'results.xlsx'),'FileType','Spreadsheet','Range', 'A1','WriteVariableNames',1,'WriteRowNames',1)
            % save the graphs to matlab
            app.StatusLabel.Text = 'Saving the graphs as matlab arrays. Please wait...';drawnow
            % save the results
            G_veins = app.var.G_veins;
            G_areoles = app.var.G_areoles;
            G_polygons = app.var.G_polygons;
            save([app.var.dir_out_data FolderName '_Graphs.mat'],'G_veins','G_areoles','G_polygons','Results')
            app.StatusLabel.Text = 'Data saved';drawnow
        end
        
        function funcCNNSelectLoad(app)
            cd(app.var.dirCNN)
            [file,path] = uigetfile('*.mat','Load CNN Selection',fullfile(app.var.dirCNN,[app.var.fnameCore '_CNNSelect']));
            figure(app.LeafVeinGUI)
            if ~isempty(file)
                temp = load(fullfile(path,file));
                app.CNNModelTable.Data = temp.IdxTable;
            end
            app.CNNModeDropDown.Value = 'select';
        end
        
        function [edgelist_out, edgeim_out, sk_out, G_out] = funcAnalysisPetiole(app, ...
                im, ...
                feature_node, ...
                perimeter_node, ...
                edgelist, ...
                edgeim, ...
                sk, ...
                G)
            % Connects the feature nodes with the corresponding perimeter nodes with
            % new edges and assigns default values for each new edge. The Feature nodes
            % and edges are added to the EdgeTable and NodeTable respectively in the
            % graph object. The edgelist is also updated to include the new edges.
            %
            % Usage: [edgelist_out, edgeim_out, sk_out, G_out] = fnc_analysis_features( ...
            %                                     im_in, ...
            %                                     feature_node_in, ...
            %                                     perimeter_node_in, ...
            %                                     edgelist_in, ...
            %                                     edgeim_in, ...
            %                                     sk_in, ...
            %                                     G_in, ...
            %                                     param)
            %
            % Arguments:    im_in             - an image used to extract the correct
            %                                   image dimensions
            %               feature_node_in   - an image with single pixels at the
            %                                   centre of each feature whose value
            %                                   represents the feature ID, starting at
            %                                   1.
            %               perimeter_node_in - an image with single pixels where each
            %                                   edge overlaps the feature and whose
            %                                   value corresponds to the feature ID
            %               edgelist_in       - a cell array with the row and column
            %                                   coordinates of each edge
            %               edgeim_in         - an image where the intensity represents
            %                                   the edge ID.
            %               sk_in             - the initial skeleton image.
            %               G_in              - a MatLab graph object with an
            %                                   EdgeTable containing nodei, nodej, and
            %                                   the edge metrics, and a NodeTable with
            %                                   the node ID and x,y pixel co-ordinate.
            %               param             - a structure containing all the
            %                                   processing parameters.
            %
            % Returns:      edgelist_out  - an updated cell array with the row and column
            %                               coordinates of each edge
            %               edgeim_out    - an updated image where the intensity
            %                               represents the edge ID, includng the
            %                               feature edges
            %               sk_out        - an updated image where the intensity
            %                               represents the edge ID, includng the
            %                               feature edges
            %               G_out         - a updated MatLab graph object with an
            %                               EdgeTable containing nodei, nodej, and the
            %                               edge metrics, and a NodeTable with the node
            %                               ID and x,y pixel co-ordinates
            %
            [nY, nX] = size(im);
            % Get the index of the feature nodes
            F_idx = find(feature_node);
            % Get a node ID for the feature nodes.
            feature_NodeID = numnodes(G) + (1:length(F_idx));
            % choose default values for the edge metrics in the feature as
            % the maximum value
            WF = max(G.Edges.Width_initial);
            nEdge = numedges(G);
            output = 0;
            % combine all the edge metrics into an EdgeTable
            names = {'EndNodes', 'node_Idx', 'vein_ID', 'Type', 'Weight', ...
                'Width_initial', 'Length_initial', ...
                'Orientation_initial', 'N_pix', ...
                'M_pix'};
            % loop through each petiole node
            for Nj = 1:length(F_idx)
                % find the coordinates of the feature node
                [y1_pix,x1_pix] = ind2sub([nY nX],F_idx(Nj));
                % add the feature node to the node table (note at this stage the
                % co-ordinates are all in pixels)
                NodeTable = table(feature_NodeID(Nj), F_idx(Nj), {['F' num2str(Nj)]}, x1_pix, y1_pix, ...
                    'VariableNames',{'node_ID' 'node_Idx' 'node_Type' 'node_X_pix' 'node_Y_pix'});
                % add the new node to the graph
                G = addnode(G,NodeTable);
                % find perimeter nodes with the same label as the feature node
                Pi = find(perimeter_node == feature_node(F_idx(Nj)));
                % get the subset of these pixels that correspond to a node
                Pi_idx = intersect(G.Nodes{:,'node_Idx'}, Pi,'stable');
                k = length(Pi_idx);
                % loop through these nodes
                for iNode = 1:k
                    nEdge = nEdge + 1;
                    % add in the extra edges linking perimeter nodes to the feature centre
                    [y2,x2] = ind2sub([nY nX],Pi_idx(iNode));
                    if output == 1
                        plot(x1_pix,y1_pix,'g*')
                        plot(x2,y2,'ro')
                    end
                    % Convert the linear node index into node IDs
                    nodei = find(G.Nodes{:,'node_Idx'} == Pi_idx(iNode));
                    nodej = feature_NodeID(Nj);
                    % change to node type to be a perimeter node
                    G.Nodes{nodei,'node_Type'} = {'P'};
                    % update the edgelist to include the new edges. Use the bresenham
                    % algorithm written by Aaron Welcher to get the pixel positions
                    [x,y] = bresenham(x1_pix, y1_pix,x2, y2);
                    edgelist{nEdge} = [y,x];
                    pix = sub2ind([nY nX],y,x);
                    M_pix = pix(round(length(pix)./2));
                    edgeim(pix) = nEdge;
                    sk(pix) = 1;
                    Etype = {'F'};
                    % Calculate the Euclidean length
                    LF = hypot(x1_pix-x2,y1_pix-y2);
                    OF = atan2(x1_pix-x2,y1_pix-y2);
                    % Set up a new edge in the EdgeTable
                    EdgeTable = table([nodei(1), nodej(1)], [Pi_idx(iNode) F_idx(Nj)], nEdge, Etype, WF, ...
                        WF, LF, OF, ...
                        length(x), M_pix, ...
                        'VariableNames', names);
                    % Add the edge to the edgelist and edgeim at each timepoint
                    G = addedge(G,EdgeTable);
                end
            end
            edgelist_out= edgelist;
            edgeim_out = edgeim;
            sk_out = sk;
            G_out = G;
        end
        
        function im_out = funcImageResize(app,im_in)
            im_out = imresize(im_in,app.param.ScalingFactor,'Method','bilinear');
        end
        
        function CNN_with_stride(app)
            app.StatusLabel.Text = 'Initialising the CNN';
            tic
            funcGetModels(app);
            nModels = numel(app.var.CNNModelNames);
            app.images.CNN = zeros([size(app.images.crop) nModels]);
            stride = round(app.CNNStrideSlider.Value);
            border_size = [(256-stride)/2 (256-stride)/2];
            UseParallel = app.CNNParallelCheckBox.Value;
            if mod(stride,2) == 1
                stride = stride+1;
            end
            
            for iF = 1:nModels
                fname = app.var.CNNModelNames{iF};
                app.StatusLabel.Text = ['Calculating the CNN prediction for iteration ' num2str(iF) ' using network ' fname '. This will take some time. Please wait...'];drawnow
                temp = load(fullfile(app.var.CNNModelDir,fname));
                net = temp.net;
                
                myfun = @(block_struct) predict(net,block_struct.data);
                n = 0;
                sz1 = size(app.images.CLAHE);
                cnn = zeros([size(app.images.CLAHE),36]);
                size(cnn)
                for iR = 0:5
                    for iC =0:5
                        n = n+1;
                        im = padarray(double(app.images.CLAHE),[50*iR 50*iC],'replicate','both');
                        sz2 = size(im);
                        temp = blockproc(im,[stride stride],myfun,'PadPartialBlocks',true,'PadMethod','replicate','BorderSize',border_size,'UseParallel',UseParallel,'DisplayWaitBar',true);
                        cnn(:,:,n) = temp(1+50*iR:sz1(1)+50*iR,1+50*iC:sz1(2)+50*iC);
                    end
                end
                meancnn = mean(cnn,3);
                figure
                imshow(meancnn,[])
                app.images.CNN(:,:,iF) = imresize(meancnn(1:size(app.images.CLAHE,1),1:size(app.images.CLAHE,2)),size(app.images.crop));
            end
            app.images.CNNmean = mean(app.images.CNN,3);
            funcImageDisplay(app,'CNN')
            app.DisplayImageDropDown.Items = unique([app.DisplayImageDropDown.Items, 'CNNmean'],'stable');
            app.DisplayImageDropDown.Items = unique([app.DisplayImageDropDown.Items, 'CNNoverlay'],'stable');
            funcEnablePanels(app,'on',{'Petiole','Segmentation'})
            t = toc;
            app.StatusLabel.Text = ['CNN Complete in ' num2str(t,0) ' s.'];
        end
        
        function funcSaveImage(app,fileout)
            % exportgraphics(app.DisplayAxes,[fileout '.png'])
            % exportgraphics(app.LeafVeinGUI,[fileout '.pdf'],'ContentType','image')
            pos1 = app.LeafVeinGUI.Position;
            pos2 = app.ImagePanel.Position;
            figure(app.LeafVeinGUI)
            drawnow
            pause(0.5)
            if app.SaveHighResCheckBox.Value
                screencapture(0,[2*pos1(1)+2*pos2(1) -pos1(4)+2*pos2(2) 2*pos2(3) 2*pos2(4)],'target',[fileout '.png'])
            else
                screencapture(0,[pos1(1)+pos2(1) pos1(2)+pos2(2) pos2(3) pos2(4)],'target',[fileout '.png'])
            end
        end
        
        function funcSavePlots(app,fileout)
            exportgraphics(app.ResultsAxes,[fileout '.png'])
            exportgraphics(app.ResultsAxes,[fileout '.pdf'],'ContentType','auto')
        end
        
        function funcUpdateCropROI(app)
            app.CropTopEditField.Value = app.var.CropROI(2);
            app.CropLeftEditField.Value = app.var.CropROI(1);
            app.CropWidthEditField.Value = app.var.CropROI(3);
            app.CropHeightEditField.Value = app.var.CropROI(4);
            app.DimensionsTextArea.Value = {['Width: ' num2str(app.var.CropROI(3))]; ['Height: ' num2str(app.var.CropROI(4))]; ['BitDepth: ' num2str(app.var.info.BitDepth)]};
        end
        
        function [edgelist_out, edgeim_out] = funcMatchEdgelist2Graph(app, ...
                G, ...
                edgelist, ...
                edgeim)
            % Reorder the edgelist to match the graph
            % Note the graph automatically sorts edge rows based on node i. The
            % original edge index is kept in vein_ID. Reorder the edgelist to match the
            % graph order, including nodei <-> node j
            [nY,nX] = size(edgeim);
            % reorder the edgelist to match the order in the graph
            edgelist = edgelist(G.Edges.vein_ID);
            % I_idx is the linear index to the first pixel in each edge array
            I_idx = cellfun(@(x) sub2ind([nY nX],x(1,1),x(1,2)),edgelist);
            % Find which EndNodes in the edge table do not start with nodei, and have
            % therefore need to be re-ordered
            switch_idx = G.Nodes{G.Edges.EndNodes(:,1),'node_Idx'} ~= I_idx';
            % flip the order of pixels in the edgelist to match
            edgelist(switch_idx) = cellfun(@(x) flipud(x), edgelist(switch_idx), 'UniformOutput',0);
            % construct the edgeim to reflect the new edgelist order.
            % P_idx is the linear index to all the pixels in each edge
            P_idx = cellfun(@(x) sub2ind([nY,nX] ,x(:,1),x(:,2)),edgelist,'UniformOutput',0);
            % concatenate all the pixels indices to give a single vector
            P_all = cat(1,P_idx{:});
            % calculate an edge index based on the order in the graph
            V_int = num2cell((1:numedges(G))');
            % duplicate the value for the number of pixels in each edge
            V_idx = cellfun(@(x,y) repmat(y,length(x),1), P_idx, V_int','UniformOutput',0);
            % concatenate all the values into a single vector
            V_all = cat(1,V_idx{:});
            % set the edge values to the central width for each edge in the image
            temp = zeros(nY,nX,'like',edgeim);
            temp(P_all) = single(single(V_all));
            edgeim_out = temp;
            edgelist_out = edgelist;
        end
        
        function [G_out, euclidean, geodesic] = funcDistance(app, ...
                G, ...
                edgelist)
            [nY,nX] = size(app.images.crop);
            source = find(contains(app.var.G_veins.Nodes.node_Type,'F1'));
            app.var.distance_reference(:,1) = app.var.G_veins.Nodes.node_X_pix(source);
            app.var.distance_reference(:,2) = app.var.G_veins.Nodes.node_Y_pix(source);
            %set the default position to the middle of the image
            %     handles.expt.distance_reference(:,2) = round(size(handles.images.resampled,1)./2);
            %     handles.expt.distance_reference(:,1) = round(size(handles.images.resampled,2)./2);
            BW = false(nY,nX);
            BW(app.var.distance_reference(:,2),app.var.distance_reference(:,1)) = 1;
            euclidean = bwdist(BW,'Euclidean');
            geodesic = bwdistgeodesic(app.images.sk_petiole, app.var.distance_reference(:,1),app.var.distance_reference(:,2),'quasi-euclidean');
            
            % P_idx is the linear index of each pixel in the cell array of edges
            P_idx = cellfun(@(x) sub2ind([nY nX],x(:,1),x(:,2)),edgelist,'UniformOutput',0);
            % Get the distance for each pixel from the distance image using P_idx
            D_val = cellfun(@(x) single(euclidean(x)),P_idx,'UniformOutput',0);
            D_mean = cellfun(@mean, D_val);
            G.Edges.Distance = D_mean';
            % Get the distance for the nodes
            G.Nodes.node_Distance = single(euclidean(G.Nodes.node_Idx));
            % calculate the geodesic distance through the skeleton
            D_val = cellfun(@(x) single(geodesic(x)),P_idx,'UniformOutput',0);
            D_mean = cellfun(@mean, D_val);
            G.Edges.Geodesic = D_mean';
            % Get the distance for the nodes
            G.Nodes.node_Geodesic = single(geodesic(G.Nodes.node_Idx));
            G.Edges.Route_factor = G.Edges.Geodesic./G.Edges.Distance;
            G.Nodes.node_Route_factor = G.Nodes.node_Geodesic./G.Nodes.node_Distance;
            G_out = G;
        end
        
        function G_out = funcHydraulicAccessibility(app,G)
            %                     G.Edges.Weight = G.Edges.Resistance_4;
            G.Edges.Weight = G.Edges.Resistance_2;
            % find the first feature node to use as the source
            source = find(contains(G.Nodes.node_Type,'F1'));
            % calculate the shortest path from the source to all other
            % nodes
            d = distances(G,source(1));
            % update the node table
            G.Nodes.node_Accessibility = (1./d)';
            % calculate the SP for the edge as the average of the end
            % nodes and convert to conductivity for display
            SPi = G.Nodes.node_Accessibility(G.Edges.EndNodes(:,1));
            SPj = G.Nodes.node_Accessibility(G.Edges.EndNodes(:,2));
            G.Edges.Accessibility = 1./((SPi+SPj)./2);
            % set the value for the feature to be the max of the edges
            F_idx = find(contains(G.Edges.Type,'F'));
            E_idx = find(contains(G.Edges.Type,'E'));
            G.Edges.Accessibility(F_idx) = max(G.Edges.Accessibility(E_idx));
            %             G.Edges.Accessibility_ratio = G.Edges.Accessibility.*G.Edges.Distance;
            %             G.Edges.Accessibility_ratio(F_idx) = max(G.Edges.Accessibility_ratio(E_idx));
            G_out = G;
        end
        
        function funcPRAnalysis(app)
            warning off
            n = 0;
            for iM = 1:sum(app.PR_methods.select)
                tic
                n = n+1;
                idx = find(app.PR_methods.select);
                method = app.PR_methods.name{idx(iM)};
                switch method
                    case {'Niblack';'Midgrey'}
                        T = app.param.threshold-0.5; % allow the offset to run from -0.5 to 0.5
                    otherwise
                        T = app.param.threshold;
                end
                % enhance the image
                funcPREnhanceSwitch(app, method)
                % extract the full width binary image for each threshold
                funcPRFullWidthSwitch(app, T, method)
                % evaluate the PR performance
                funcPRFullWidthEvaluate(app, T, method)
                % convert to a skeleton
                if n == 1
                    % get the ground truth skeleton
                    [app.PR_methods.sk.('GT')] = funcPRSkeletonSwitch(app,app.PR_methods.roi.GT,T,'GT');
                end
                % extract the skeleton for each threshold
                app.PR_methods.sk.(method) = funcPRSkeletonSwitch(app,app.PR_methods.fullwidth.(method),T,method);
                % evaluate the PR performance
                funcPRSkeletonEvaluate(app, T, method)
                % get some basic graph results for the ground truth
                if n ==1
                    GT_full = funcPRSkeletonAnalysis(app,app.PR_methods.sk.('GT'), 1);
                end
                % get some basic graph results for each method with varying threshold
                results_full = funcPRSkeletonAnalysis(app,app.PR_methods.sk.(method), T);
                if iM == 1
                    app.PR_results.('GT') = GT_full;
                    app.PR_results.F1(1,:) = GT_full;
                    app.PR_results.FBeta2(1,:) = GT_full;
                    app.PR_results.F1.Properties.VariableNames = results_full.Properties.VariableNames;
                    app.PR_results.FBeta2.Properties.VariableNames = results_full.Properties.VariableNames;
                end
                app.PR_results.(method) = results_full;
                app.PR_results.F1(idx(iM)+1,:) = results_full(app.PR_methods.evaluation_sk{method,'F1_idx'},:);
                app.PR_results.FBeta2(idx(iM)+1,:) = results_full(app.PR_methods.evaluation_sk{method,'FBeta2_idx'},:);
                app.StatusLabel.Text = ['Elapsed time for method: ' method ' = ' num2str(toc)];
            end
            warning on
            app.StatusLabel.Text = 'Processing complete';
        end
        

        function funcPRParameters(app)
            [~,app.var.PRName,~] = fileparts(app.var.fname);
            app.var.dir_out_PR_results = ['PR' filesep 'results' filesep];
            app.var.dir_out_PR_images = ['PR' filesep 'images' filesep];
            app.var.dir_out_PR_graphs = ['PR' filesep 'graphs' filesep];
            
            cd(app.var.dirCNN)
            if ~exist([app.var.dirCNN filesep 'PR' filesep 'results'],'dir')
                mkdir([app.var.dirCNN filesep 'PR' filesep 'results'])
            end
            if ~exist([app.var.dirCNN filesep 'PR' filesep 'images'],'dir')
                mkdir([app.var.dirCNN filesep 'PR' filesep 'images'])
            end
            if ~exist([app.var.dirCNN filesep 'PR' filesep 'graphs'],'dir')
                mkdir([app.var.dirCNN filesep 'PR' filesep 'graphs'])
            end
            
            app.param.PRDownSample = str2double(app.PRDownSampleDropDown.Value);
            app.param.PRRadius = round(90./app.param.PRDownSample);
            app.param.PRMinimumWidth = 4;
            app.param.PRnLevels = 4;
            %app.param.PRTolerance = round(6./app.param.PRDownSample);
            app.param.PRTolerance = max(3,(6*1.68)/(app.param.MicronPerPixel*app.param.PRDownSample));
            % set up threshold parameters
            Tmin = 0.05;
            Tmax = 1;
            nT = 19;
            Tint = (Tmax-Tmin)/(nT);
            app.param.threshold = Tmin:Tint:Tmax-Tint;
        end
        
        function funcPRExtractROI(app)
            app.StatusLabel.Text = 'Preparing images for Precision-Recall Analysis. Please wait...';drawnow
            % subsample images to match the area used for the ground truth
            stats = regionprops(app.images.GT_mask,'BoundingBox');
            BB = round(stats.BoundingBox);
            app.PR_methods.roi.bw = app.images.GT_mask(BB(2):BB(2)+BB(4)-1,BB(1):BB(1)+BB(3)-1);
            app.PR_methods.roi.im = app.images.CLAHE(BB(2):BB(2)+BB(4)-1,BB(1):BB(1)+BB(3)-1);
            app.PR_methods.roi.cnn = app.images.CNNmean(BB(2):BB(2)+BB(4)-1,BB(1):BB(1)+BB(3)-1);
            app.PR_methods.roi.mask = app.images.mask(BB(2):BB(2)+BB(4)-1,BB(1):BB(1)+BB(3)-1);
            if isfield(app.images,'vein')
                app.PR_methods.roi.vein = app.images.vein(BB(2):BB(2)+BB(4)-1,BB(1):BB(1)+BB(3)-1);
            end
            app.PR_methods.roi.GT = app.images.GT(BB(2):BB(2)+BB(4)-1,BB(1):BB(1)+BB(3)-1);
            % keep the largest connected component
            % roi_GT = bwareafilt(roi_GT,1);
            % invert and normalise the original image
            app.PR_methods.roi.invert = imcomplement(mat2gray(app.PR_methods.roi.im));
            if app.param.PRDownSample ~=1
                app.PR_methods.roi.bw = imresize(app.PR_methods.roi.bw,1/app.param.PRDownSample);
                app.PR_methods.roi.im = imresize(app.PR_methods.roi.im,1/app.param.PRDownSample);
                app.PR_methods.roi.cnn = imresize(app.PR_methods.roi.cnn,1/app.param.PRDownSample);
                app.PR_methods.roi.mask = imresize(app.PR_methods.roi.mask,1/app.param.PRDownSample);
                if isfield(app.images,'vein')
                    app.PR_methods.roi.vein = imresize(app.PR_methods.roi.vein,1/app.param.PRDownSample);
                end
                app.PR_methods.roi.GT = imresize(app.PR_methods.roi.GT,1/app.param.PRDownSample);
                app.PR_methods.roi.invert = imresize(app.PR_methods.roi.invert,1/app.param.PRDownSample);
            end
            app.StatusLabel.Text = 'PR images cropped.';
        end
        
        function funcPRSetupResults(app)
            app.StatusLabel.Text = 'Setting up the results table for PR analysis. Please wait...';drawnow
            %% set up an array of methods
            app.PR_methods.name = {'CNN';'Midgrey';'Niblack';'Bernsen';'Sauvola';'Vesselness';'MFATl';'MFATp';'FeatureType';'BowlerHat'};
            % choose which methods to test
            app.PR_methods.select = logical([app.PRResultsTable.Data{:,1}])';
            % set up the evaluation table for the full width images
            app.PR_methods.evaluation_fw = array2table(zeros(numel(app.PR_methods.name),6,'single'));
            app.PR_methods.evaluation_fw.Properties.VariableNames = { ...
                'F1' 'F1_idx' 'F1_threshold' ...
                'FBeta2' 'FBeta2_idx' 'FBeta2_threshold'};
            app.PR_methods.evaluation_fw.Properties.RowNames = app.PR_methods.name;
            % add in the filename and method at the start
            app.PR_methods.evaluation_fw.Filename = repelem({app.var.PRName},height(app.PR_methods.evaluation_fw),1);
            app.PR_methods.evaluation_fw.Method = app.PR_methods.name;
            app.PR_methods.evaluation_fw = app.PR_methods.evaluation_fw(:,[end-1:end, 1:end-2]);
            
            % set up an evaluation table for the skeleton comparison
            app.PR_methods.evaluation_sk = array2table(zeros(numel(app.PR_methods.name),6,'single'));
            app.PR_methods.evaluation_sk.Properties.VariableNames = { ...
                'F1' 'F1_idx' 'F1_threshold' ...
                'FBeta2' 'FBeta2_idx' 'FBeta2_threshold'};
            app.PR_methods.evaluation_sk.Properties.RowNames = app.PR_methods.name;
            % add in the filename and method at the start
            app.PR_methods.evaluation_sk.Filename = repelem({app.var.PRName},height(app.PR_methods.evaluation_sk),1);
            app.PR_methods.evaluation_sk.Method = app.PR_methods.name;
            app.PR_methods.evaluation_sk = app.PR_methods.evaluation_sk(:,[end-1:end, 1:end-2]);
            
            % add in file information to the results file
            app.PR_results.File = app.var.PRName;
            app.PR_results.TimeStamp = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z');
            app.PR_results.MicronPerPixel = app.param.MicronPerPixel;
            app.PR_results.DownSample = app.param.PRDownSample;
            %% set up blank results tables
            app.PR_results.F1 = array2table(nan(11,12));
            app.PR_results.F1.Properties.RowNames = [{'GT'};app.PR_methods.name];
            app.PR_results.FBeta2 = app.PR_results.F1;
            app.PR_results.F1_ratio = app.PR_results.F1;
            app.PR_results.FBeta2_ratio = app.PR_results.F1;
            app.StatusLabel.Text = 'PR Table initialised';
        end
        
        function funcPREnhanceSwitch(app, method)
            app.StatusLabel.Text = ['Enhancing images using method ' method '. Please wait...'];drawnow
            % normalise and enhance the images with variable thresholds
            switch method
                case {'CNN'}
                    % normalise the CNN image
                    app.PR_methods.enhanced.(method) = app.PR_methods.roi.cnn;
                case {'Vesselness';'FeatureType';'BowlerHat';'MFATl';'MFATp'}
                    % calculate the enhanced images using an inverted
                    % original
                    app.PR_methods.enhanced.(method) = funcPREnhance(app,app.PR_methods.roi.invert,method);
                otherwise
                    % copy the original image across for local intensity segmentation
                    app.PR_methods.enhanced.(method) = funcPREnhance(app,app.PR_methods.roi.im,method);
            end
            app.StatusLabel.Text = ['Images enhanced.'];
        end
        
        function im_out = funcPREnhance(app,im_in,method)
            minW = app.param.PRMinimumWidth;
            nLevels = app.param.PRnLevels;
            switch method
                case {'CNN'}
                    im_out = mat2gray(im_in);
                case {'im';'Midgrey';'Niblack';'Bernsen';'Sauvola';'Singh'}
                    im_out = imcomplement(mat2gray(im_in));
                case {'Vesselness';'FeatureType';'BowlerHat';'MFATl';'MFATp'} % use an image pyramid to span scales
                    % find the scales for the filter
                    minW = 3;
                    %                     minW = floor(9/app.param.PRDownSample);
                    %                     maxW = ceil(150/app.param.PRDownSample);
                    % set up an image pyramid
                    %                    nLevels = 4;
                    I = cell(nLevels,1);
                    I{1} = mat2gray(im_in);
                    for iL = 2:nLevels
                        I{iL} = impyramid(I{iL-1},'reduce');
                    end
                    im_out = zeros(size(im_in),'single');
                    D = ones(3);
            end
            switch method
                case 'Vesselness'
                    % filter each scale with an overlap
                    for iL = 1:nLevels
                        if verLessThan('MatLab','9.5')
                        else
                            % structureSensitivity defined differently in ver 9.5 and
                            % above - we need the old version calculated as:
                            s = 0.5*maxhessiannorm(I{iL});
                        end
                        for iW = minW:2*minW+1
                            if verLessThan('MatLab','9.5')
                                temp = fibermetric(I{iL}, iW,'ObjectPolarity', 'bright');
                            else
                                temp = fibermetric(I{iL}, iW, 'StructureSensitivity',s,'ObjectPolarity', 'bright');
                            end
                            temp = imclose(temp,D);
                            im_out = max(im_out,imresize(temp,size(im_in)));
                        end
                    end
                case 'FeatureType'
                    for iL = 1:2
                        [M,m,or,featType,pc,EO,T,pcSum] = phasecong3(I{iL}, ...
                            5, 6, minW, 2.1, 0.55, 2, 0.5, 10, -1);
                        temp = single((featType+pi/2)/pi);
                        temp = imclose(temp,D);
                        im_out = max(im_out,imresize(temp,size(im_in)));
                    end
                case 'BowlerHat'
                    for iL = 1:3 % nLevels-1
                        for iW = minW:2*minW+1
                            [temp,~] = Granulo2D(I{iL},iW*3,12);
                            temp = imclose(temp,D);
                            im_out = max(im_out,imresize(temp,size(im_out)));
                        end
                    end
                case 'MFATl'
                    for iL = 1:3 % nLevels - 1
                        % Parameters setting
                        sigmas = [1:1:3];
                        spacing = 1;
                        whiteondark = true;
                        %tau = 0.03; tau2 = 0.5; D = 0.01; default
                        tau = 0.2; tau2 = 0.5; D = 0.01;
                        % Proposed Method (Eigenvalue based version)
                        temp = FractionalIstropicTensor(I{iL},sigmas,tau,tau2,D,spacing,whiteondark);
                        im_out = max(im_out,imresize(temp,size(im_in)));
                    end
                case 'MFATp'
                    for iL = 1:3 % nLevels - 1
                        % Parameters setting
                        sigmas = [1:1:3];
                        spacing = 1;
                        whiteondark = true;
                        %tau = 0.05; tau2 = 0.25; D = 0.45; % default
                        tau = 0.05; tau2 = 0.5; D = 0.01;
                        % Proposed Method (probability based version)
                        temp = ProbabiliticFractionalIstropicTensor(I{iL},sigmas,tau,tau2,D,spacing,whiteondark);
                        im_out = max(im_out,imresize(temp,size(im_in)));
                    end
            end
            % normalise
            im_out = mat2gray(im_out);
        end
        
        function funcPRFullWidthSwitch(app,T,method)
            app.StatusLabel.Text = ['Calculating full-width binary image using method ' method '. Please wait...'];drawnow
            switch method
                case {'CNN';'Vesselness';'FeatureType';'BowlerHat';'MFATl';'MFATp'}
                    % use a hysteresis threshold for the enhanced images
                    app.PR_methods.fullwidth.(method) = funcPRFullWidth(app,app.PR_methods.enhanced.(method),T,'hysteresis');
                case {'Niblack';'Midgrey';'Bernsen';'Sauvola'}
                    % apply the local thresholding to the other images
                    app.PR_methods.fullwidth.(method) = funcPRFullWidth(app,app.PR_methods.enhanced.(method),T,method);
                otherwise
                    app.PR_methods.fullwidth.(method) = app.PR_methods.enhanced.(method);
            end
            app.StatusLabel.Text = 'Full-width binary image complete';
        end
        
        function bw_out =  funcPRFullWidth(app,im_in,T,method)
            roi = app.PR_methods.roi.bw;
            vein = app.PR_methods.roi.vein;
            mask = app.PR_methods.roi.mask;
            radius = app.param.PRRadius;
            se = strel('disk',radius);
            nT = length(T);
            bw_out = false([size(im_in) nT]);
            switch method
                case 'Niblack'
                    % T runs from -0.5 to +0.5
                    m = imfilter(im_in,fspecial('disk',radius),'replicate'); % local mean
                    s = stdfilt(im_in,getnhood(se)); % local std
                    for iT = 1:nT
                        level = m + T(iT) * s;
                        bw_out(:,:,iT) = im_in > level;
                    end
                case 'Midgrey'
                    % T runs from -0.5 to +0.5
                    lmin = imerode(im_in,se);
                    lmax = imdilate(im_in,se);
                    mg = (lmin + lmax)/2;
                    for iT = 1:nT
                        level =  mg + T(iT);
                        bw_out(:,:,iT) = im_in > level;
                    end
                case 'Bernsen'
                    se = strel('disk',round(radius/3));
                    lmin = imerode(im_in,se);
                    lmax = imdilate(im_in,se);
                    lc = lmax - lmin; % local contrast
                    mg = (lmin + lmax)/2; % mid grey
                    for iT = 1:nT
                        ix1 = lc < T(iT); % weight = contrast threshold in Bernsen algorithm
                        ix2 = lc >= T(iT);
                        temp = false(size(im_in));
                        temp(ix1) = mg(ix1) >= 0.5;
                        temp(ix2) = im_in(ix2) >= mg(ix2);
                        bw_out(:,:,iT) = temp;
                    end
                case 'Sauvola'
                    % t = mean * ( 1 + k * ( stdev / r - 1 ) ) )
                    m = imfilter(im_in,fspecial('disk',radius),'replicate'); % local mean
                    s = stdfilt(im_in,getnhood(se)); % local std
                    R = max(s(:)); % 0.5
                    for iT = 1:nT
                        level = m .* (1.0 + T(iT) * (s / R - 1.0));
                        bw_out(:,:,iT) = im_in > level;
                    end
                case 'Phansalkar'
                    % modification of Sauvola for low contrast images
                    % t = mean * (1 + p * exp(-q * mean) + k * ((stdev / r) - 1))
                    m = imfilter(im_in,fspecial('disk',radius)); % local mean
                    s = stdfilt(im_in,getnhood(se)); % local std
                    R = max(s(:));
                    p = 2;
                    q = 10;
                    for iT = 1:nT
                        level = m .* (1.0 + p.*exp(-q.*m) + T(iT) * (s / R - 1.0));
                        bw_out(:,:,iT) = im_in > level;
                    end
                case {'mean','median','Gaussian'}
                    % adative threshold based on local mean, median or a gaussian
                    % filter. Note Matlab internally rescales T to fit in the range 0.6 + (1-sensitivity)
                    for iT = 1:nT
                        % T controls the sensitivity
                        level = adaptthresh(im_in, T(iT), 'NeighbourhoodSize',radius, 'Statistic',method);
                        bw_out(:,:,iT) = im_in > level;
                    end
                otherwise
                    % hysteresis threshold using T as lower threshold and T+0.05 as
                    % upper threshold
                    for iT = 1:nT
                        aboveT2 = im_in > T(iT); % Edge points above the lower threshold.
                        Tmax = min(1,T(iT)+0.05);
                        [aboveT1r, aboveT1c] = find(im_in > Tmax);  % Row and column coords of points above upper threshold.
                        % Obtain all connected regions in aboveT2 that include a point that has a
                        % value above T1
                        bw_out(:,:,iT) = bwselect(aboveT2, aboveT1c, aboveT1r, 8);
                    end
            end
            for iT = 1:nT
                % add in the manual large vein if present to each image
                if ~isempty(vein)
                    bw_out(:,:,iT) = bw_out(:,:,iT) | vein;
                end
                % apply the masks if present to each image
                if ~isempty(mask)
                    temp = bw_out(:,:,iT);
                    temp(~mask) = 0;
                    bw_out(:,:,iT)=temp;
                end
                % constrain the result to the GT ROI
                if ~isempty(roi)
                    temp = bw_out(:,:,iT);
                    temp(~roi) = 0;
                    bw_out(:,:,iT) = temp;
                end
            end
        end
        
        function funcPRFullWidthEvaluate(app, T, method)
            app.StatusLabel.Text = ['Precision-Recall analysis for ' method ' full-width against ground-truth. Please wait...'];drawnow
            % Precision-Recall Comparison against the full-width ground truth
            [app.PR_methods.results_fw.(method), app.PR_methods.images_fw.(method)] = funcPRFullWidthPRC(app,app.PR_methods.fullwidth.(method),T);
            % get the best values from the results table
            [app.PR_methods.evaluation_fw{method,'F1'}, app.PR_methods.evaluation_fw{method,'F1_idx'}] = max(app.PR_methods.results_fw.(method){:,'F1'});
            app.PR_methods.evaluation_fw{method,'F1_threshold'} = T(app.PR_methods.evaluation_fw{method,'F1_idx'});
            [app.PR_methods.evaluation_fw{method,'FBeta2'}, app.PR_methods.evaluation_fw{method,'FBeta2_idx'}] = max(app.PR_methods.results_fw.(method){:,'FBeta2'});
            app.PR_methods.evaluation_fw{method,'FBeta2_threshold'} = T(app.PR_methods.evaluation_fw{method,'FBeta2_idx'});
            % keep the best full width images
            F1_idx_fw = app.PR_methods.evaluation_fw{method,'F1_idx'};
            FBeta2_idx_fw = app.PR_methods.evaluation_fw{method,'FBeta2_idx'};
            app.PR_methods.F1_fw.(method) = app.PR_methods.fullwidth.(method)(:,:,F1_idx_fw);
            app.PR_methods.FBeta2_fw.(method) = app.PR_methods.fullwidth.(method)(:,:,FBeta2_idx_fw);
            app.StatusLabel.Text = 'Full-width PR analysis complete';
        end
        
        function [PR_results,PR_im] = funcPRFullWidthPRC(app,bw_in,T)
            roi_GT = app.PR_methods.roi.GT;
            [nY,nX,nT] = size(bw_in);
            PR_im = zeros(nY,nX,3,nT,'single');
            PR_results = array2table(zeros(nT,5,'single'));
            PR_results.Properties.VariableNames = {'Threshold' 'Precision' 'Recall' 'F1' 'FBeta2'};
            for iT = 1:size(bw_in,3)
                % calculate true positives present in GT and binary image
                TP = bw_in(:,:,iT) & roi_GT;
                % false positives are in the binary image; but not GT
                FP = bw_in(:,:,iT) & ~roi_GT;
                % false negatives are in the ground truth, but not the
                % binary image
                FN = roi_GT & ~bw_in(:,:,iT);
                % true negatives are all the pixels that are not in either
                TN = ~roi_GT & ~bw_in(:,:,iT);
                % sum the quantities across the whole image
                STP = sum(TP(:));
                SFP = sum(FP(:));
                STN = sum(TN(:));
                SFN = sum(FN(:));
                % precision is the number of true positives compared to true
                % plus false positive
                precision = STP/(STP+SFP);
                % recall is the number of true positive compared to the number
                % of true positive and false negatives. It is the same as the
                % true positive rate (TPR)
                recall = STP/(STP+SFN);
                % F1 statistic
                F = (2.*precision.*recall)./(precision + recall);
                % Fbeta2 statistic
                beta = 2;
                Fbeta2 = (1+beta^2).*((precision.*recall)./((beta^2.*precision)+recall));
                % results table
                PR_results{iT,:} = [T(iT) precision, recall, F, Fbeta2];
                temp = single(cat(3,FN,TP,FP));
                back = repmat(1-max(temp,[],3),1,1,3);% to give a white background
                PR_im(:,:,:,iT) = temp + back;
            end
        end
        
        function [sk_out] = funcPRSkeletonSwitch(app,im_in,T,method)
            app.StatusLabel.Text = ['Skeleton extraction for ' method '. Please wait...'];drawnow
            roi = app.PR_methods.roi.bw;
            vein = app.PR_methods.roi.vein;
            % calculate the skeleton
            nT = length(T);
            sk_out = false([size(im_in,1) size(im_in,2) nT]);
            im_in = mat2gray(im_in);
            if ~isempty(vein)
                im_in = max(im_in,255.*double(vein));
            end
            switch method
                case {'CNN';'Vesselness';'FeatureType';'BowlerHat';'MFATl';'MFATp'}
                    if ~isempty(roi)
                        im_in(~roi) = 0;
                    end
            end
            switch method
                case 'GT'
                    % thin the binary image to a single pixel skeleton
                    sk_out = bwmorph(im_in,'thin',inf);
                    % fill in any single pixel holes
                    sk_out = bwmorph(sk_out,'fill');
                    % repeat to ensure a single pixel skeleton
                    sk_out = bwmorph(sk_out,'thin',inf);
                otherwise
                    for iT = 1:nT
                        [~, sk_out(:,:,iT)] = funcPRSkeleton(app,im_in(:,:,iT),T(iT));
                    end
            end
            app.StatusLabel.Text = 'Skeleton extracted';
        end
        
        function [bw_im, sk_out] = funcPRSkeleton(app,im_in,threshold)
            vein = app.PR_methods.roi.vein;
            GT_mask = app.PR_methods.roi.mask;
            if islogical(im_in)
                % the input image is already a binary image
                bw_im = im_in;
            else
                % impose local minima to smooth out background noise
                exmin = imextendedmin(mat2gray(im_in),0.2);
                im = imimposemin(mat2gray(im_in),exmin);
                % convert to a binary image
                bw_im = imbinarize(im,threshold);
            end
            % add in the big vein image if present
            if ~isempty(vein)
                bw_im = bw_im | vein;
            end
            % smooth the binary image
            bw_im = medfilt2(bw_im,[3 3]);
            % thin the binary image to a single pixel skeleton
            sk_out = bwmorph(im_in,'thin',inf);
            % fill in any single pixel holes
            sk_out = bwmorph(sk_out,'fill');
            % repeat to ensure a single pixel skeleton
            sk_out = bwmorph(sk_out,'thin',inf);
            % mask out non-GT regions
            sk_out(GT_mask == 0) = 0;
        end
        
        function funcPRSkeletonEvaluate(app,T,method)
            app.StatusLabel.Text = ['Precision-Recall Analysis for ' method ' skeleton. Please wait...'];drawnow
            % Precision Recall Comparison against the ground truth skeleton within a given tolerance (in pixels)
            [app.PR_methods.results_sk.(method),app.PR_methods.images_sk.(method)] = funcPRSkeletonPRC(app,app.PR_methods.sk.(method),T);
            % get the best values from the results table
            [app.PR_methods.evaluation_sk{method,'F1'}, app.PR_methods.evaluation_sk{method,'F1_idx'}] = max(app.PR_methods.results_sk.(method){:,'F1'});
            app.PR_methods.evaluation_sk{method,'F1_threshold'} = T(app.PR_methods.evaluation_sk{method,'F1_idx'});
            [app.PR_methods.evaluation_sk{method,'FBeta2'}, app.PR_methods.evaluation_sk{method,'FBeta2_idx'}] = max(app.PR_methods.results_sk.(method){:,'FBeta2'});
            app.PR_methods.evaluation_sk{method,'FBeta2_threshold'} = T(app.PR_methods.evaluation_sk{method,'FBeta2_idx'});
            % keep the best skeleton
            F1_idx_sk = app.PR_methods.evaluation_sk{method,'F1_idx'};
            FBeta2_idx_sk = app.PR_methods.evaluation_sk{method,'FBeta2_idx'};
            app.PR_methods.F1_sk.(method) = app.PR_methods.sk.(method)(:,:,F1_idx_sk);
            app.PR_methods.FBeta2_sk.(method) = app.PR_methods.sk.(method)(:,:,FBeta2_idx_sk);
            app.StatusLabel.Text = 'Skeleton PR comparison complete';
        end
        
        function [PR_results,PR_im] = funcPRSkeletonPRC(app,sk_in,T)
            sk_GT = app.PR_methods.sk.('GT');
            tolerance = app.param.PRTolerance;
            [nY,nX,nT] = size(sk_in);
            PR_im = zeros(nY,nX,3,nT,'single');
            PR_results = array2table(zeros(nT,7,'single'));
            PR_results.Properties.VariableNames = {'Threshold' 'Precision' 'Recall' 'F1' 'FBeta2' 'Distance_error' 'DE_sd'};
            for iT = 1:size(sk_in,3)
                % get the distance away from the skeleton
                sk_distance = bwdist(sk_in(:,:,iT),'euclidean');
                % get the distance away from the ground_truth skeleton
                GT_distance = bwdist(sk_GT,'euclidean');
                % calculate whether the skeleton pixels are within the
                % tolerance limits for a true positive
                TP = sk_in(:,:,iT) & GT_distance<=tolerance;
                % false positives are in the skeleton but not within the
                % tolerance of the gold standard
                FP = sk_in(:,:,iT) & GT_distance>tolerance;
                % false negatives are in the gold standard, but not the
                % skeleton within the tolerance
                FN = sk_GT & sk_distance>tolerance;
                % true negatives are all the pixels that have not been assigned
                TN = ~(TP  | FP | FN);
                % the distance arror is distance of each pixel in the skeleton
                % away from the gold standard
                distance_error = mean(GT_distance(sk_in(:,:,iT)));
                distance_error_SD = std(GT_distance(sk_in(:,:,iT)));
                % sum the quantities across the whole image
                STP = sum(TP(:));
                SFP = sum(FP(:));
                STN = sum(TN(:));
                SFN = sum(FN(:));
                % precision is the number of true positives compared to true
                % plus false positive
                precision = STP/(STP+SFP);
                % recall is the number of true positive compared to the number
                % of true positive and false negatives. It is the same as the
                % true positive rate (TPR)
                recall = STP/(STP+SFN);
                % F1 statistic
                F = (2.*precision.*recall)./(precision + recall);
                % Fbeta2 statistic
                beta = 2;
                Fbeta2 = (1+beta^2).*((precision.*recall)./((beta^2.*precision)+recall));
                % results table
                PR_results{iT,:} = [T(iT) precision, recall, F, Fbeta2,distance_error,distance_error_SD];
                % PR image
                temp = imdilate(single(cat(3,FN,TP,FP)),ones(app.param.PRWidth));
                back = repmat(1-max(temp,[],3),1,1,3);% to give a white background
                PR_im(:,:,:,iT) = imadd(temp,back);
            end
        end
        
        function results = funcPRSkeletonAnalysis(app,sk_in,T)
            app.StatusLabel.Text = ['Graph metric comparison. Please wait...'];drawnow
            results = table;
            ROIArea = sum(app.PR_methods.roi.bw(:));
            for iT = 1:size(sk_in,3)
                sk = sk_in(:,:,iT);
                bp = bwmorph(sk,'branchpoints');
                ep = bwmorph(sk,'endpoints');
                nodes = bwmorph(bp|ep,'thicken',1);
                edges = bwconncomp(sk&~nodes,8);
                results.Threshold(iT) = T(iT);
                results.Edges(iT) = edges.NumObjects;
                results.Length(iT) = sum(sum(sk));
                results.LengthDensity(iT) = sum(sum(sk))/ROIArea;
                results.Nodes(iT) = sum(bp(:)) + sum(ep(:));
                results.NodeDensity(iT) = results.Nodes(iT)/ROIArea;
                results.Junctions(iT) = sum(bp(:));
                results.FreeEnds(iT) = sum(ep(:));
                results.FreeEndRatio(iT) = results.FreeEnds(iT)/results.Edges(iT);
                areas = bwconncomp(imclearborder(~sk,4),4);
                stats = regionprops(areas,'Area','Eccentricity');
                results.AreaCount(iT) = areas.NumObjects;
                results.AreaMean(iT) = mean([stats.Area]);
                results.AreaEccentricity(iT) = mean([stats.Eccentricity]);
            end
            app.StatusLabel.Text = 'Graph metric comparison complete';
        end
        
        function funcPRImageDisplay(app)
            method = app.PRImageDropDown.Value;
            type = app.PRTypeDropDown.Value;
            metric = app.PRMetricDropDown.Value;
            metric = strrep(metric,'F2','FBeta2');
            switch type
                case 'FWB'
                    app.images.PR = app.PR_methods.images_fw.(method)(:,:,:,app.PR_methods.evaluation_fw{method,[metric '_idx']});
                case 'Skeleton'
                    app.images.PR = app.PR_methods.images_sk.(method)(:,:,:,app.PR_methods.evaluation_sk{method,[metric '_idx']});
            end
            funcImageDisplay(app,'PR')
            app.DisplayAxes.Title.String = ['PR ' method ' ' type ' ' metric];
        end
        
        function funcPRPlotPRC(app)
            idx = find(app.PR_methods.select);
            methods = app.PR_methods.name(idx);
            cols = app.PRGraphCMap;
            style = repmat('-',11,1);
            F1 = repmat('*',11,1);
            FBeta2 = repmat('o',11,1);
            mrks = repmat('.',11,1);
            switch app.PRGraphDropDown.Value
                case 'PR FWB'
                    type = 'fw';
                    titletext = 'full-width Binary';
                case 'PR Skeleton'
                    type = 'sk';
                    titletext = 'skeleton';
            end
            % plot the precision-recall plots and mark the optimum
            cla(app.PRAxes)
            legend(app.PRAxes, 'off')
            axis(app.PRAxes,'auto')
            for iM = 1:numel(methods)
                x = app.PR_methods.(['results_' type]).(methods{iM}).Recall;
                y = app.PR_methods.(['results_' type]).(methods{iM}).Precision;
                plot(app.PRAxes,x,y,'Color',cols(iM,:),'LineStyle',style(iM,:),'Marker',mrks(iM,:));
                hold(app.PRAxes,'on')
            end
          
            for iM = 1:numel(methods)
                xF1 = app.PR_methods.(['results_' type]).(methods{iM}).Recall(app.PR_methods.(['evaluation_' type]){methods{iM},'F1_idx'});
                yF1 = app.PR_methods.(['results_' type]).(methods{iM}).Precision(app.PR_methods.(['evaluation_' type]){methods{iM},'F1_idx'});
                plot(app.PRAxes,xF1,yF1,'MarkerEdgeColor',cols(iM,:),'Marker',F1(iM,:))
                xF2 = app.PR_methods.(['results_' type]).(methods{iM}).Recall(app.PR_methods.(['evaluation_' type]){methods{iM},'FBeta2_idx'});
                yF2 = app.PR_methods.(['results_' type]).(methods{iM}).Precision(app.PR_methods.(['evaluation_' type]){methods{iM},'FBeta2_idx'});
                plot(app.PRAxes,xF2,yF2,'MarkerEdgeColor',cols(iM,:),'Marker',FBeta2(iM,:))
            end
            xlabel('Recall','Parent',app.PRAxes)
            ylabel('Precision','Parent',app.PRAxes)
            title(['Precision-Recall for ' titletext ' image'],'Parent',app.PRAxes)
            app.PRAxes.XLim = [0 1];
            app.PRAxes.YLim = [0 1];
            app.PRAxes.FontUnits = 'points';
            app.PRAxes.FontSize = 9;
            app.PRAxes.Box = 'on';
            legend(app.PRAxes,methods{1:length(idx)},'Location','SouthWest','FontSize',7)              
        end
               
        function funcPRPlotMetric(app)
            idx = find(app.PR_methods.select);
            switch app.PRGraphDropDown.Value
                case 'F1 FWB'
                    type = 'fw';
                    titletext = 'F1 metric for full-width binary';
                case 'F2 FWB'
                    type = 'fw';
                    titletext = 'F2 metric for full-width binary';
                case 'F1 Skeleton'
                    type = 'sk';
                    titletext = 'F1 metric for skeleton';                    
                case 'F2 Skeleton'
                    type = 'sk';
                    titletext = 'F2 metric for skeleton';
            end
            % plot the precision-recall plots and mark the optimum
            cla(app.PRAxes)
            legend(app.PRAxes, 'off')
            hold(app.PRAxes,'off')
            axis(app.PRAxes,'auto')
            x = categorical(app.PR_methods.(['evaluation_' type]).Properties.RowNames);
            x = reordercats(x,app.PR_methods.(['evaluation_' type]).Properties.RowNames);
            if contains(app.PRGraphDropDown.Value, 'F1')
                y = app.PR_methods.(['evaluation_' type]){idx,'F1'};
                
            elseif contains(app.PRGraphDropDown.Value, 'F2')
                y = app.PR_methods.(['evaluation_' type]){idx,'FBeta2'};
            end
            bar(app.PRAxes,x(idx),y)
            title(titletext,'Parent',app.PRAxes)
            xlabel([],'Parent',app.PRAxes)
            ylabel([],'Parent',app.PRAxes)
            %                         app.PRAxes.XLim = [0 1];
            app.PRAxes.YLim = [0 1];
            app.PRAxes.FontUnits = 'points';
            app.PRAxes.FontSize = 9;
            app.PRAxes.Box = 'on';
        end
                       
        function funcPRPlotNetwork(app)
            idx = find(app.PR_methods.select);
            %cols = app.PRGraphCMap;
            switch app.PRGraphDropDown.Value
                case 'F1 Network'
                    type = 'F1';
                    titletext = 'F1 network metrics';
                case 'F2 Network'
                    type = 'FBeta2';
                    titletext = 'F2 network metrics';
                case 'F1 Ratio'
                    type = 'F1_ratio';
                    titletext = 'F1 network normalised';                    
                case 'F2 Ratio'
                    type = 'FBeta2_ratio';
                    titletext = 'F2 network normalised';
                otherwise
                    type = 'F1_ratio';
                    titletext = 'F1 network metrics';                    
            end
            % plot the precision-recall plots and mark the optimum
            cla(app.PRAxes)
            hold(app.PRAxes, 'off')
            legend(app.PRAxes, 'off')
            axis(app.PRAxes,'auto')
            x = categorical(app.PR_results.(type).Properties.VariableNames(2:end));
            x = reordercats(x,app.PR_results.(type).Properties.VariableNames(2:end));
            y = app.PR_results.(type){idx+1,2:end}';
            bar(app.PRAxes,x,y,'FaceColor',"flat")
            title(titletext,'Parent',app.PRAxes)
            xlabel([],'Parent',app.PRAxes)
            ylabel([],'Parent',app.PRAxes)
            app.PRAxes.FontUnits = 'points';
            app.PRAxes.FontSize = 9;
            app.PRAxes.Box = 'on';
            %legend(app.PRAxes,app.PR_results.(type).Properties.RowNames(idx+1),'Location','SouthWest','FontSize',7)  
        end
 
        function funcPRTableUpdate(app)
            Data = app.PRResultsTable.Data;
            Data(:,2:5) = num2cell(app.PR_methods.evaluation_fw{:,[3,5,6,8]});
            Data(:,6:9) = num2cell(app.PR_methods.evaluation_sk{:,[3,5,6,8]});
            app.PRResultsTable.Data = Data;
        end
        
        function funcCLAHE(app)
            % normalise
            numTiles = max([2,2],floor(size(app.images.crop)./app.param.ClaheTileSize));
            % enhance contrast using an adaptive histogram equalisation
            app.images.CLAHE = adapthisteq(imadjust(app.images.crop), 'NumTiles', numTiles, 'ClipLimit', app.param.ClaheClipLimit);
        end
        
        function funcCNN(app)
            app.StatusLabel.Text = 'Initialising the CNN';
            tic
            funcGetModels(app);
            nModels = numel(app.var.CNNModelNames);
            app.images.CNN = zeros([size(app.images.crop) nModels]);
            stride = round(app.CNNStrideSlider.Value);
            UseParallel = app.CNNParallelCheckBox.Value;
            if mod(stride,2) == 1
                stride = stride+1;
            end
            
            for iF = 1:nModels
                fname = app.var.CNNModelNames{iF};
                app.StatusLabel.Text = ['Calculating the CNN prediction for iteration ' num2str(iF) ' using network ' fname '. This will take some time. Please wait...'];drawnow
                temp = load(fullfile(app.var.CNNModelDir,fname));
                net = temp.net;
                myfun = @(block_struct) predict(net,block_struct.data);
                border_size = [(256-stride)/2 (256-stride)/2];
                cnn = blockproc(app.images.resized,[stride stride],myfun,'PadPartialBlocks',true,'PadMethod','symmetric','BorderSize',border_size,'UseParallel',UseParallel,'DisplayWaitBar',true);
                % shrink the CNN prediction to the original size of the
                % image
                cnn1 = imresize(cnn(1:size(app.images.resized,1),1:size(app.images.resized,2)),size(app.images.CLAHE),'bilinear');
                if app.CNNPyramidCheckBox.Value
                    im = impyramid(app.images.resized,'expand');
                    cnn = blockproc(im,[stride stride],myfun,'PadPartialBlocks',true,'PadMethod','symmetric','BorderSize',border_size,'UseParallel',UseParallel,'DisplayWaitBar',true);
                    cnn2 = imresize(cnn(1:size(im,1),1:size(im,2)),size(app.images.CLAHE),'bilinear');
                    app.images.CNN(:,:,iF) = max(cnn1,cnn2);
                else
                    app.images.CNN(:,:,iF) = cnn1;
                end
            end
            app.images.CNNmean = mean(app.images.CNN,3);
            funcImageDisplay(app,'CNN')
            app.DisplayImageDropDown.Items = unique([app.DisplayImageDropDown.Items, 'CNNmean'],'stable');
            app.DisplayImageDropDown.Items = unique([app.DisplayImageDropDown.Items, 'CNNoverlay'],'stable');
            funcEnablePanels(app,'on',{'Petiole','Segmentation'})
            t = toc;
            app.StatusLabel.Text = ['CNN Complete in ' num2str(t,0) ' s.'];
        end
        
        function funcPR(app)
            app.PR_methods = [];
            app.PR_results = [];
            app.PR_methods.select = logical([app.PRResultsTable.Data{:,1}])';
            funcPRParameters(app)
            funcPRExtractROI(app)
            funcPRSetupResults(app)
            funcPRAnalysis(app)
            % calcuate the ratio values against the ground truth
            app.PR_results.F1_ratio([true; app.PR_methods.select],:) = array2table(log(app.PR_results.F1{[true; app.PR_methods.select],:}./app.PR_results.F1{1,:}));
            app.PR_results.F1_ratio.Properties.VariableNames = app.PR_results.F1.Properties.VariableNames;
            app.PR_results.F1_ratio.Properties.RowNames = app.PR_results.F1.Properties.RowNames;
            app.PR_results.FBeta2_ratio([true; app.PR_methods.select],:) = array2table(log(app.PR_results.FBeta2{[true; app.PR_methods.select],:}./app.PR_results.FBeta2{1,:}));
            app.PR_results.FBeta2_ratio.Properties.VariableNames = app.PR_results.FBeta2.Properties.VariableNames;
            app.PR_results.FBeta2_ratio.Properties.RowNames = app.PR_results.FBeta2.Properties.RowNames;
            funcPRTableUpdate(app)
        end
        
        function funcRescale(app)
            if app.MicronPerPixelEditField.Value == 0
                uialert(app.LeafVeinGUI,'Please enter a value for the calibration','Calibration missing');
            else
                app.StatusLabel.Text = 'Resizing the image. Please wait...';drawnow
                if app.param.ScalingBlurSigma ~= 0
                    im = imgaussfilt(app.images.CLAHE,app.param.ScalingBlurSigma);
                else
                    im = app.images.CLAHE;
                end
                app.images.resized = funcImageResize(app,im);
                if app.param.ScalingUpSample == 1 
                    app.images.resized = imresize(app.images.resized,size(app.images.CLAHE));
                end
                
            end
        end
        
        function funcPRSensitivity(app)
            app.StatusLabel.Text = 'Precision-Recall sensitivity analysis. Please wait...';drawnow
            % clear the results and set up new arrays
            app.PR_sensitivity = [];
            app.PR_sensitivity.results.summary = array2table(zeros(0,30));
            FWB_headings = cellfun(@(x) ['FWB_' x],app.PR_methods.evaluation_fw.Properties.VariableNames(3:8),'UniformOutput',0);
            sk_headings = cellfun(@(x) ['sk_' x],app.PR_methods.evaluation_sk.Properties.VariableNames(3:8),'UniformOutput',0);
            network_headings = app.PR_results.F1.Properties.VariableNames;
            app.PR_sensitivity.results.summary.Properties.VariableNames = [{'Scale','CLAHE','ContrastMin','ContrastMax','Blur','Upsample'}, FWB_headings, sk_headings, network_headings];
            writetable(app.PR_sensitivity.results.summary,[app.var.dir_out_PR_results [app.var.PRName '_sensitivity.xlsx']],'FileType','Spreadsheet','Sheet','Sensitivity','WriteVariableNames',true,'WriteRowNames',false,"WriteMode","replacefile")
            %CLAHEValues = [0,0.001,0.005,0.01,0.001,0.001,0.001,0.001,0.001,0.001,0.001,0.001,0.001];
            CLAHEValues = [0,0.005,0.01,0.05,0,0,0,0.01,0.01,0.01,0.01,0.01,0.01,0.01,0.01,0.01,0.01,0.01,0.01];
            ContrastMin = [0,0,0,0,0.1,0.2,0.3,0.1,0.2,0.3,0,0,0,0,0,0,0,0,0];
            ContrastMax = [1,1,1,1,0.9,0.8,0.7,0.9,0.8,0.7,1,1,1,1,1,1,1,1,1];
            BlurValues = [0,0,0,0,0,0,0,0,0,0,3,5,7,0,0,0,0,0,0];
            ScaleValues = [1,1,1,1,1,1,1,1,1,1,1,1,1,0.5,0.33,0.25,0.5,0.33,0.25];
            UpSampleValues = [0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,0,1,1,1];
            n = length(CLAHEValues);
            % dimension the arrays
            app.PR_sensitivity.images.initial = zeros([size(app.images.CLAHE) n],'like',app.images.CLAHE);
            app.PR_sensitivity.images.CNN = zeros([size(app.images.CLAHE) n],'like',app.images.CNNmean);
            app.PR_sensitivity.images.PR_FWB = zeros([size(app.images.CLAHE) 3 n]);
            app.PR_sensitivity.images.PR_sk = zeros([size(app.images.CLAHE) 3 n]);
            app.PRWidthDropDown.Value = num2str(5);
            app.param.PRWidth = 5;
            app.images.backup = app.images.crop;
            for i = 1:n
                % Set the parameters and update the GUI
                app.param.ClaheClipLimit = CLAHEValues(i);
                app.param.ScalingFactor = ScaleValues(i);
                app.param.ScalingBlurSigma = BlurValues(i);
                app.param.ScalingUpSample = UpSampleValues(i);
                % update the visible controls
                app.ClaheClipLimitEditField.Value = CLAHEValues(i);
                app.ScalingFactorEditField.Value = ScaleValues(i);
                app.CNNStrideSlider.Value = max(50,round(250*ScaleValues(i)));
                drawnow;
                % change the contrast of the cropped image
                app.images.crop = imadjust(app.images.backup,[0 1],[ContrastMin(i) ContrastMax(i)]);
                % run CLAHE
                if CLAHEValues(i) > 0
                    funcCLAHE(app)
                else
                    app.images.CLAHE = app.images.crop;
                end
                % resize and blur the image
                funcRescale(app)
                % upsample the resized image back to the initial size for
                % dispay
                app.PR_sensitivity.images.initial(:,:,i) = imresize(app.images.resized,size(app.images.CLAHE),'bilinear');
                % run the CNN
                funcCNN(app)
                app.PR_sensitivity.images.CNN(:,:,i) = app.images.CNNmean;
                % run the precision recall
                funcPR(app)
                % display and save the images for FWB and skeleton
                app.PRTypeDropDown.Value = 'FWB';
                funcPRImageDisplay(app)
                app.PR_sensitivity.images.PR_FWB(:,:,:,i) = imresize(app.images.PR,size(app.images.CLAHE),'nearest');
                app.PRTypeDropDown.Value = 'Skeleton';
                funcPRImageDisplay(app)
                app.PR_sensitivity.images.PR_sk(:,:,:,i) = imresize(app.images.PR,size(app.images.CLAHE),'nearest');
                % extract the results
                app.PR_sensitivity.results.summary(i,:) = array2table([app.param.ScalingFactor app.param.ClaheClipLimit ContrastMin(i) ContrastMax(i) app.param.ScalingBlurSigma app.param.ScalingUpSample app.PR_methods.evaluation_fw{'CNN',3:8} app.PR_methods.evaluation_sk{'CNN',3:8} app.PR_results.F1_ratio{'CNN',:}]);
                app.PR_sensitivity.results.FWB{i} = app.PR_methods.results_fw.CNN;
                app.PR_sensitivity.results.sk{i} = join(app.PR_methods.results_sk.CNN, app.PR_results.CNN);
            end
                writetable(app.PR_sensitivity.results.summary,[app.var.dir_out_PR_results [app.var.PRName '_sensitivity.xlsx']],'FileType','Spreadsheet','Sheet','Sensitivity','WriteVariableNames',false,'WriteRowNames',false,"WriteMode","append")
            app.StatusLabel.Text = 'Precision-Recall sensitivity analysis complete';drawnow
        end
        
        
        function hfig = funcPRSensitivityFigure(app,idx,code)
            fontsz = 14;
            hfig = figure('Renderer','painters');
            hfig.Units = 'normalized';
            W = 0.7;
            H = 1;
            hfig.Position = [0 0 W H];
            hfig.Color = 'w';
            tiledlayout(hfig,4,4,'TileSpacing','compact','Padding','none');
            % choose the orientation to be landscape
            if size(app.PR_methods.roi.im,1) > size(app.PR_methods.roi.im,2)
                rotate_angle = 90;
            else
                rotate_angle = 0;
            end
            % display the modified input image
            switch code
                case 'CLAHE'
                    titletext = 'clip limit ';
                case 'Blur'
                    titletext = 'sigma ';
                case 'Contrast'
                    titletext = 'contrast ';
                case 'Scale'
                    titletext = 'scale ';
                case 'Upsample'
                    titletext = 'scale ';
            end
            for i = 1:4
                im = imrotate(app.PR_sensitivity.images.initial(:,:,idx(i)),rotate_angle);
                nexttile
                imshow(im,[0 255])
                switch code
                    case 'Contrast'
                        title([titletext  num2str(app.PR_sensitivity.results.summary{idx(i),'ContrastMin'}) ':' num2str(app.PR_sensitivity.results.summary{idx(i),'ContrastMax'})])
                    case 'Upsample'
                        title([titletext num2str(app.PR_sensitivity.results.summary{idx(i),'Scale'})])
                    otherwise
                        title([titletext num2str(app.PR_sensitivity.results.summary{idx(i),code})])
                end
                axis off
            end
            for i = 1:4
                im = imrotate(app.PR_sensitivity.images.CNN(:,:,idx(i)),rotate_angle);
                nexttile
                imshow(im,[0 1])
%                 switch code
%                     case 'Contrast'
%                         title([titletext  num2str(app.PR_sensitivity.results.summary{idx(i),'ContrastMin'}) ':' num2str(app.PR_sensitivity.results.summary{idx(i),'ContrastMax'})])
%                     case 'Upsample'
%                         title([titletext num2str(app.PR_sensitivity.results.summary{idx(i),'Scale'})])
%                     otherwise
%                         title([titletext num2str(app.PR_sensitivity.results.summary{idx(i),code})])
%                 end
                axis off
            end
            for i = 1:4
                im = imrotate(app.PR_sensitivity.images.PR_FWB(:,:,:,idx(i)),rotate_angle);
                nexttile
                imshow(im,[])
                title(['F1='  num2str(app.PR_sensitivity.results.summary{idx(i),'FWB_F1'},2) ', F2=' num2str(app.PR_sensitivity.results.summary{idx(i),'FWB_FBeta2'},2)])
                axis off
            end
            for i = 1:4
                im = imrotate(app.PR_sensitivity.images.PR_sk(:,:,:,idx(i)),rotate_angle);
                nexttile
                imshow(im,[])
                title(['F1='  num2str(app.PR_sensitivity.results.summary{idx(i),'sk_F1'},2) ', F2=' num2str(app.PR_sensitivity.results.summary{idx(i),'sk_FBeta2'},2)])
                axis off
            end
            for ia = 1:16
                ax = nexttile(ia);
                ax.XTick = [];
                ax.YTick = [];
                axis off
                box on
                ax.Title.FontWeight = 'normal';
                ax.Title.FontUnits = 'normalized';
                ax.Title.HorizontalAlignment = 'center';
                limits = axis;
                ax.Title.FontSize = fontsz/140;
                if ia == 1
                    text(-limits(2)*0.1,limits(3),'(a)','FontUnits','normalized','FontSize',fontsz/100,'VerticalAlignment','bottom','FontWeight','bold')
                    ylabel(ax,'original','FontSize',fontsz);
                elseif ia==5
                    text(-limits(2)*0.1,limits(3),'(b)','FontUnits','normalized','FontSize',fontsz/100,'VerticalAlignment','bottom','FontWeight','bold')
                    ylabel(ax,'mean CNN','FontSize',fontsz)
                elseif ia==9
                    text(-limits(2)*0.1,limits(3),'(c)','FontUnits','normalized','FontSize',fontsz/100,'VerticalAlignment','bottom','FontWeight','bold')
                    ylabel(ax,'PR FWB','FontSize',fontsz);
                elseif ia==13
                    text(-limits(2)*0.1,limits(3),'(d)','FontUnits','normalized','FontSize',fontsz/100,'VerticalAlignment','bottom','FontWeight','bold')
                    ylabel(ax,'PR skeleton','FontSize',fontsz);
                end
            end
        end
        
        
        function funcPRPlotSensitivity(app,mode,idx,code)
            n = length(idx);
            cols = app.PRGraphCMap(1:3:10,:);
            switch mode
                case 'FWB'
                    mode2 = 'FWB';
                    titletext = 'full-width Binary';
                case 'sk'
                    mode2 = 'sk';
                    titletext = 'skeleton';
            end
            switch code
                case 'CLAHE'
                    legendtext = [repmat('sigma ',4,1) num2str(app.PR_sensitivity.results.summary{idx,code})];
                case 'Blur'
                    legendtext = [repmat('sigma ',4,1) num2str(app.PR_sensitivity.results.summary{idx,code})];
                case 'Contrast'
                    legendtext = [repmat('contrast ',4,1) num2str(app.PR_sensitivity.results.summary{idx,'ContrastMin'}) repmat(':',4,1) num2str(app.PR_sensitivity.results.summary{idx,'ContrastMax'})];
                case 'Scale'
                    legendtext = [repmat('scale ',4,1) num2str(app.PR_sensitivity.results.summary{idx,code})];
                  case 'Upsample'
                    legendtext = [repmat('scale ',4,1) num2str(app.PR_sensitivity.results.summary{idx,'Scale'})];
            end
            % plot the precision-recall plots and mark the optimum
            cla(app.PRAxes)
            legend(app.PRAxes, 'off')
            axis(app.PRAxes,'auto')
            for iM = 1:n
                x = app.PR_sensitivity.results.(mode){1,idx(iM)}.Recall;
                y = app.PR_sensitivity.results.(mode){1,idx(iM)}.Precision;
                plot(app.PRAxes,x,y,'Color',cols(iM,:),'LineStyle','-');
                hold(app.PRAxes,'on')
            end
            for iM = 1:n
                F1_idx = app.PR_sensitivity.results.summary{idx(iM),[mode2 '_F1_idx']};
                xF1 = app.PR_sensitivity.results.(mode){1,idx(iM)}.Recall(F1_idx);
                yF1 = app.PR_sensitivity.results.(mode){1,idx(iM)}.Precision(F1_idx);
                plot(app.PRAxes,xF1,yF1,'MarkerEdgeColor',cols(iM,:),'Marker','o')
                F2_idx = app.PR_sensitivity.results.summary{idx(iM),[mode2 '_FBeta2_idx']};
                xF2 = app.PR_sensitivity.results.(mode){1,idx(iM)}.Recall(F2_idx);
                yF2 = app.PR_sensitivity.results.(mode){1,idx(iM)}.Precision(F2_idx);
                plot(app.PRAxes,xF2,yF2,'MarkerEdgeColor',cols(iM,:),'Marker','*')
            end
            xlabel('Recall','Parent',app.PRAxes)
            ylabel('Precision','Parent',app.PRAxes)
            %title(['PR for ' titletext ' - ' lower(code) ' sensitivity'],'Parent',app.PRAxes)
            title('','Parent',app.PRAxes);
            app.PRAxes.XLim = [0.5 1];
            app.PRAxes.YLim = [0.5 1];
            app.PRAxes.FontUnits = 'points';
            app.PRAxes.FontSize = 9;
            app.PRAxes.Box = 'on';
            legend(app.PRAxes,legendtext,'Location','SouthWest','FontSize',7)
        end
    end
    
    methods (Access = public)
        
        function funcUpdateCNNSelectTable(app,IdxTable)
            app.CNNModelTable.Data = IdxTable;
            data = cell2mat(IdxTable);
            IdxList = reshape(data',[],1);
            app.var.CNNModelIndex = IdxList(1:38);
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.param.Channel = 'mean';
            app.param.CropSkip = 0;
            app.param.ClaheSkip = 0;
            app.param.ClaheTileSize = 400;
            app.param.ClaheClipLimit = 0.01;
            app.param.ScalingFactor = 1;
            app.param.ScalingBlurSigma = 0;
            app.param.ScalingUpSample = 0;
            app.param.CNNEnsemble = 3;
            app.param.CNNStride = 200;
            app.param.SegmentationThreshold = 0.38;
            app.param.AnalysisFullLeaf = 0;
            app.param.AnalysisEdgeWidth = 1;
            app.param.AnalysisSkeletonWidth = 1;
            app.var.CropROI = [1 1 1 1];
            app.param.PRWidth = 1;
            % update the processing controls
            app.ChannelDropDown.Value = app.param.Channel;
            app.CropSkipCheckBox.Value = app.param.CropSkip;
            funcCropSkip(app)
            app.ClaheSkipCheckBox.Value = app.param.ClaheSkip;
            app.ClaheTileSizeEditField.Value = app.param.ClaheTileSize;
            app.ClaheClipLimitEditField.Value = app.param.ClaheClipLimit;
            funcClaheSkip(app)
            % update the scaling controls
            app.ScalingFactorEditField.Value = app.param.ScalingFactor;
            % update the CNN controls
            app.CNNEnsembleDropDown.Items = string(1:38);
            app.CNNEnsembleDropDown.Value = num2str(app.param.CNNEnsemble);
            app.CNNStrideSlider.Value = app.param.CNNStride;
            h = app.CNNPredictTab.Children;
            set(h,'Enable','off')
            % update the Segmentation controls
            app.SegmentationThresholdEditField.Value = app.param.SegmentationThreshold;
            app.SegmentationThresholdSlider.Value = app.param.SegmentationThreshold;
            % update the PR controls on the PR tab
            app.PRWidthDropDown.Value = num2str(app.param.PRWidth);
            % update the display controls (on output tab)
            app.FigureEdgeWidthDropDown.Value = num2str(app.param.AnalysisEdgeWidth);
            app.FigureSkeletonWidthDropDown.Value = num2str(app.param.AnalysisSkeletonWidth);
            app.HLDFullLeafCheckBox.Value = app.param.AnalysisFullLeaf;
            % display options
            cmaps = {'parula';'jet';'hsv';'cool'; ...
                'spring';'summer';'autumn';'winter';'gray';'bone';'copper'; ...
                'pink'; 'lines';'colorcube';'prism';'flag';'black';'white'; ...
                'L1';'L3';'L7';'L8';'L9';'D2';'D3';'D7';'R2'};
            app.GraphEdgeColorMapDropDown.Items = cmaps;
            app.GraphEdgeColorMapDropDown.Value = 'R2';
            funcSetUpEdgeColormap(app)
            app.GraphNodeColorMapDropDown.Items = cmaps;
            app.GraphNodeColorMapDropDown.Value = 'parula';
            funcSetUpNodeColormap(app)
            app.PolygonNodeColorMapDropDown.Items = cmaps;
            app.PolygonNodeColorMapDropDown.Value = 'cool';
            funcSetUpPolygonColormap(app)
            % set up graph colours
            % set up the display colors and fonts
            app.PRGraphCMap = [255 0 0;
                216 144 0;
                163 165 0;
                57 182 0;
                0 191 125;
                0 191 196;
                0 176 246;
                149 144 255;
                231 107 243;
                255 98 188]./255;
            % start off with controls disabled
            funcEnablePanels(app,'off',{'Scaling','Channel','Crop','CLAHE','CNN','Petiole','Segmentation','Analysis','HLD','Results','DisplayControls','GraphDisplayControls'})
            % load in the reference table
            if isdeployed
                if ispc
                    app.ReferenceTable  = readtable('C:\Program Files\MDF\LeafVeinGUI\application\ReferenceTableLeaf.xlsx','FileType','spreadsheet','ReadVariableNames',1);
                elseif ismac
                    app.ReferenceTable  = readtable('C:/Applications/MDF/LeafVeinGUI/application/ReferenceTableLeaf.xlsx','FileType','spreadsheet','ReadVariableNames',1);
                elseif isunix
                    app.ReferenceTable  = readtable('/usr/MDF/LeafVeinGUI/application/ReferenceTableLeaf.xlsx','FileType','spreadsheet','ReadVariableNames',1);
                end
            else
                app.ReferenceTable = readtable('ReferenceTableLeaf.xlsx','FileType','spreadsheet','ReadVariableNames',1);
            end
            % now update the various dropdown menu boxes using the reference table
            funcResultsTarget(app)
            funcGraphOptionsUpdate(app)
            funcPolygonOptionsUpdate(app)
            
            % set up the CNN select panel
            data = {true,true,true,true,true,true; ...
                true,true,true,true,true,true; ...
                true,true,true,true,true,true; ...
                true,true,true,true,true,true; ...
                true,true,true,true,true,true; ...
                true,true,true,true,true,true; ...
                true,true,false,false,false,false};
            app.CNNModelTable.Data = data;
            app.CNNModelTable.ColumnWidth = {27,27,27,27,27,27};
            app.CNNModelTable.ColumnName = {'1';'2';'3';'4';'5';'6'};
            app.CNNModelTable.RowName = {'f0';'f1';'f2';'f3';'f4';'f5';'f6'};
            app.CNNModelTable.FontSize = 10;
            % set up the PR Table
            data = { ...
                true,0,0,0,0; ...
                false,0,0,0,0; ...
                false,0,0,0,0; ...
                false,0,0,0,0; ...
                false,0,0,0,0; ...
                false,0,0,0,0; ...
                false,0,0,0,0; ...
                false,0,0,0,0; ...
                false,0,0,0,0; ...
                false,0,0,0,0};
            app.PRResultsTable.Data = data;
            app.PRResultsTable.ColumnWidth = {40,55,55,55,55};
            app.PRResultsTable.ColumnName = {'use';'F1 FWB';'F1 T FWB';'F2 FWB';'F2 T FWB';'F1 Sk';'F1 T Sk';'F2 Sk';'F2 T Sk'};
            app.PRResultsTable.ColumnEditable = [true,false,false,false,false];
            app.PRResultsTable.RowName = {'CNN';'Midgrey';'Niblack';'Bernsen';'Sauvola';'Vesselness';'MFATl';'MFATp';'FeatureType';'BowlerHat'};;
            app.PRResultsTable.FontSize = 10;
            % position the GUI in the middle of the screen
            r = groot;
            s = r.ScreenSize;
            p = app.LeafVeinGUI.Position;
            app.LeafVeinGUI.Position = [round((s(3)-p(3))/2) round((s(4)-p(4))/2) p(3) p(4)];
            % set the default interactions for the display
            app.DisplayAxes.Interactions = [zoomInteraction regionZoomInteraction rulerPanInteraction];
            app.DisplayAxes.Clipping = 'off';
        end

        % Value changed function: TargetDropDown
        function TargetDropDownValueChanged(app, event)
            funcEnablePanels(app,'off',{'Scaling','Channel','Crop','CLAHE','CNN','Petiole','Analysis','HLD','Results','DisplayControls','GraphDisplayControls'})
            switch app.TargetDropDown.Value
                case 'original'
                    app.DisplayImageDropDown.Items = {'original'};
                    app.DisplayImageDropDown.Value = {'original'};
                    funcEnablePanels(app,'on',{'Scaling'})
                case 'CLAHE'
                    app.DisplayImageDropDown.Items = {'crop','CLAHE'};
                    app.DisplayImageDropDown.Value = 'CLAHE';
                    funcEnablePanels(app,'on',{'CNN'})
                case 'CNN'
                    app.DisplayImageDropDown.Items = {'crop';'CLAHE';'CNN';'CNNmean'};
                    app.DisplayImageDropDown.Value = 'CNN';
                    funcEnablePanels(app,'on',{'Petiole','Segmentation','Analysis'})
            end
        end

        % Button pushed function: LoadImageButton
        function LoadImageButtonPushed(app, event)
            target = app.TargetDropDown.Value;
            funcGetFileInfo(app,target)
            if ~app.var.fname == 0
                funcGetCoreFilename(app)
                funcLoadImage(app,target)
                funcUpdateCropROI(app)
                % input the pixel calibration
                answer = inputdlg('Please enter a value for the calibration in microns per pixel','Pixel calibration',1,{'1'});
                app.param.MicronPerPixel = str2double(answer);
                app.MicronPerPixelEditField.Value = app.param.MicronPerPixel;
                app.ScalingValue.Text = num2str(round(app.param.MicronPerPixel/1.6807,2));
                funcEnablePanels(app,'on',{'Channel','DisplayControls'})
            end
        end

        % Button pushed function: LoadMaskButton
        function LoadMaskButtonPushed(app, event)
            funcGetFileInfo(app,'mask')
            funcLoadImage(app,'mask')
        end

        % Button pushed function: LoadVeinButton
        function LoadVeinButtonPushed(app, event)
            funcGetFileInfo(app,'vein')
            funcLoadImage(app,'vein')
        end

        % Value changed function: MicronPerPixelEditField
        function MicronPerPixelEditFieldValueChanged(app, event)
            % set the scaling factor between the current image and the resolution used for training the CNN
            app.param.MicronPerPixel = app.MicronPerPixelEditField.Value;
            app.ScalingValue.Text = num2str(round(app.param.MicronPerPixel/1.6807,2));
            app.ScalingFactorEditField.Enable = 'on';
            app.StatusLabel.Text = 'Please set the Scaling Value';
        end

        % Button pushed function: ChannelButton
        function ChannelButtonPushed(app, event)
            app.StatusLabel.Text = 'Calculating the channel. Please wait...';drawnow
            app.images.initial = funcChannelSelect(app,app.images.original);
            funcImageDisplay(app, 'initial');
            funcEnablePanels(app,'on',{'Crop'})
            app.CropSkipCheckBox.Value = 0;
            app.CropButton.Enable = 0;
            app.StatusLabel.Text = 'Channel selected';drawnow
        end

        % Button pushed function: CropButton
        function CropButtonPushed(app, event)
            funcImageDisplay(app, 'initial')
            app.images.crop  = app.images.initial(app.var.CropROI(2):app.var.CropROI(2)+app.var.CropROI(4), app.var.CropROI(1):app.var.CropROI(1)+app.var.CropROI(3));
            funcImageDisplay(app, 'crop');
            app.images.mask = app.images.mask_original(app.var.CropROI(2):app.var.CropROI(2)+app.var.CropROI(4), app.var.CropROI(1):app.var.CropROI(1)+app.var.CropROI(3));
            if isfield(app.images,'vein_original')
                app.images.vein = app.images.vein_original(app.var.CropROI(2):app.var.CropROI(2)+app.var.CropROI(4), app.var.CropROI(1):app.var.CropROI(1)+app.var.CropROI(3));
            end
            app.DimensionsTextArea.Value = {['Width: ' num2str(app.var.CropROI(3))]; ['Height: ' num2str(app.var.CropROI(4))]; ['BitDepth: ' num2str(app.var.info.BitDepth)]};
            funcEnablePanels(app,'on',{'CLAHE'});
            app.ClaheSkipCheckBox.Value = 0;
            app.LeafVeinGUI.Pointer = 'arrow';
        end

        % Button pushed function: CropSetRegionButton
        function CropSetRegionButtonPushed(app, event)
            funcImageDisplay(app, 'initial')
            app.StatusLabel.Text = 'Please draw a Region-Of-Interest on the image';
            app.LeafVeinGUI.Pointer = 'crosshair';
            hROI = drawrectangle(app.DisplayAxes);
            wait(hROI);
            app.var.CropROI = round(hROI.Position);
            delete(hROI);
            app.LeafVeinGUI.Pointer = 'arrow';
            figure(app.LeafVeinGUI)
            funcUpdateCropROI(app);
            app.CropButton.Enable = 1;
            app.StatusLabel.Text = 'Crop region saved. Please press Crop to apply';
        end

        % Value changed function: CropTopEditField
        function CropTopEditFieldValueChanged(app, event)
            app.var.CropROI(2) = app.CropTopEditField.Value;
            app.CropButton.Enable = 1;
        end

        % Value changed function: CropLeftEditField
        function CropLeftEditFieldValueChanged(app, event)
            app.var.CropROI(1) = app.CropLeftEditField.Value;
            app.CropButton.Enable = 1;
        end

        % Value changed function: CropWidthEditField
        function CropWidthEditFieldValueChanged(app, event)
            app.var.CropROI(3) = app.CropWidthEditField.Value;
            app.CropButton.Enable = 1;
        end

        % Value changed function: CropHeightEditField
        function CropHeightEditFieldValueChanged(app, event)
            app.var.CropROI(4) = app.CropHeightEditField.Value;
            app.CropButton.Enable = 1;
        end

        % Button pushed function: CropSaveButton
        function CropSaveButtonPushed(app, event)
            funcCropSave(app)
            funcCropMaskSave(app)
            funcCropVeinSave(app)
        end

        % Value changed function: CropSkipCheckBox
        function CropSkipCheckBoxValueChanged(app, event)
            funcCropSkip(app)
        end

        % Value changed function: ChannelDropDown
        function ChannelDropDownValueChanged(app, event)
            app.param.Channel = app.ChannelDropDown.Value;
        end

        % Value changed function: ClaheSkipCheckBox
        function ClaheSkipCheckBoxValueChanged(app, event)
            funcClaheSkip(app)
        end

        % Value changed function: ClaheTileSizeEditField
        function ClaheTileSizeEditFieldValueChanged(app, event)
            app.param.ClaheTileSize = app.ClaheTileSizeEditField.Value;
        end

        % Value changed function: ClaheClipLimitEditField
        function ClaheClipLimitEditFieldValueChanged(app, event)
            app.param.ClaheClipLimit = app.ClaheClipLimitEditField.Value;
        end

        % Button pushed function: ClaheSaveButton
        function ClaheSaveButtonPushed(app, event)
            funcClaheSave(app)
            % save copies of any cropped images if present
            funcCropSave(app)
            funcCropMaskSave(app)
            funcCropVeinSave(app)
        end

        % Button pushed function: ClaheButton
        function ClaheButtonPushed(app, event)
            app.StatusLabel.Text = 'adjusting image contrast. Please wait...';drawnow
            funcCLAHE(app)
            funcImageDisplay(app, 'CLAHE')
            funcEnablePanels(app,'on',{'Scaling'})
        end

        % Button pushed function: ScalingButton
        function ScalingButtonPushed(app, event)
            funcRescale(app)
            funcImageDisplay(app,'resized')
            app.StatusLabel.Text = 'Image resized';drawnow
            funcEnablePanels(app,'on',{'CNN'})
        end

        % Value changed function: ScalingFactorEditField
        function ScalingFactorEditFieldValueChanged(app, event)
            app.param.ScalingFactor = app.ScalingFactorEditField.Value;
        end

        % Value changed function: CNNModeDropDown
        function CNNModeDropDownValueChanged(app, event)
            value = app.CNNModeDropDown.Value;
            switch value
                case 'random'
                    app.CNNEnsembleDropDown.Enable = 'on';
                    app.EnsembleDropDownLabel.Enable = 'on';
                case 'fixed'
                    app.CNNEnsembleDropDown.Enable = 'off';
                    app.EnsembleDropDownLabel.Enable = 'off';
                case 'select'
                    app.CNNEnsembleDropDown.Enable = 'on';
                    app.EnsembleDropDownLabel.Enable = 'on';
            end
        end

        % Value changed function: CNNEnsembleDropDown
        function CNNEnsembleDropDownValueChanged(app, event)
            app.param.CNNEnsemble = app.CNNEnsembleDropDown.Value;
            
        end

        % Button pushed function: CNNRunButton
        function CNNRunButtonPushed(app, event)
            funcCNN(app)
        end

        % Button pushed function: CNNSelectButton
        function CNNSelectButtonPushed(app, event)
            app.StatusLabel.Text = ['Loading CNN Selection GUI. Please wait...'];drawnow
            app.var.CNNSelectApp = CNNselect_v2(app, app.var, app.images.CLAHE);
            app.StatusLabel.Text = ['CNN selection complete'];drawnow
        end

        % Button pushed function: CNNSelectSave
        function CNNSelectSaveButtonPushed(app, event)
            cd(app.var.dirCNN)
            [file,path] = uiputfile('*.mat','Save CNN Selection',fullfile(app.var.dirCNN,[app.var.fnameCore '_CNNSelect']));
            [IdxList,IdxTable] = funcGetCNNModelIndex(app);
            save(fullfile(path,file),'IdxList','IdxTable')
            figure(app.LeafVeinGUI)
        end

        % Button pushed function: CNNSelectLoad
        function CNNSelectLoadButtonPushed(app, event)
            funcCNNSelectLoad(app)
            figure(app.LeafVeinGUI)
        end

        % Button pushed function: CNNSelectAll
        function CNNSelectAllButtonPushed(app, event)
            app.CNNModelTable.Data = num2cell(true(7,6));
        end

        % Button pushed function: CNNSelectNone
        function CNNSelectNoneButtonPushed(app, event)
            app.CNNModelTable.Data = num2cell(false(7,6));
        end

        % Button pushed function: CNNSelectLoad_2
        function CNNSelectLoad_2ButtonPushed(app, event)
            funcCNNSelectLoad(app)
            figure(app.LeafVeinGUI)
        end

        % Button pushed function: CNNSaveButton
        function CNNSaveButtonPushed(app, event)
            cd(app.var.dirCNN)
            [file,path] = uiputfile('*.png','Save CNN images',fullfile(app.var.dirCNN,[app.var.fnameCore '_crop_CNN']));
            [~,name,~] = fileparts(file);
            if ~contains(name,'_crop_CNN')
                name = [name '_crop_CNN'];
            end
            for i = 1:size(app.images.CNN,3)
                imwrite(app.images.CNN(:,:,i),fullfile(path,[name '_' num2str(i) '.png']),'png')
            end
            % also save the processing images
            name = strrep(name,'_crop_CNN','_CLAHE');
            imwrite(app.images.CLAHE,fullfile(path,[name '.png']),'png')
            name = strrep(name,'_CLAHE','_crop');
            imwrite(app.images.crop,fullfile(path,[name '.png']),'png')
            if isfield(app.images,[app.var.fnameCore '_mask'])
                app.images.mask = imwrite(fullfile(path,[file '_mask']));
            end
            if isfield(app.images, [app.var.fnameCore '_vein'])
                app.images.vein = imwrite(fullfile(path,[file '_vein']));
            end
            figure(app.LeafVeinGUI)
        end

        % Button pushed function: PetioleSetButton
        function PetioleSetButtonPushed(app, event)
            app.images.petiole = false(size(app.images.CNNmean));
            funcImageDisplay(app,'CNNmean')
            app.LeafVeinGUI.Pointer = 'crosshair';
            drawnow()
            app.StatusLabel.Text = 'Please draw a box covering the base of the petiole';
            %hROI = drawrectangle(app.DisplayAxes);
            hROI = drawpolygon(app.DisplayAxes);
            wait(hROI);
            app.images.petiole = createMask(hROI);
            delete(hROI);
            app.images.petiole = app.images.petiole;
            app.LeafVeinGUI.Pointer = 'arrow';
            app.StatusLabel.Text = 'Petiole set.';
            app.PetioleUseCheckBox.Value = 1;
            app.DisplayImageDropDown.Items = unique([app.DisplayImageDropDown.Items, 'petiole'],'stable');
        end

        % Button pushed function: SegmentationButton
        function SegmentationButtonPushed(app, event)
            app.LeafVeinGUI.Pointer = 'arrow';
            threshold = app.SegmentationThresholdEditField.Value;
            % threshold the CNN image to give a full width binary image
            app.images.CNNfw = funcCNNFullWidth(app);
            funcImageDisplay(app,'CNNfw')
            % skeleton extraction
            app.StatusLabel.Text = ['Skeleton extraction using threshold ' num2str(threshold) '. Please wait ...']; drawnow
            [app.images.skeleton, app.images.skLoop, app.images.skTree, app.images.skTruncEdge] = fnc_skeleton(app);
            funcImageDisplay(app,'skeleton')
            % create an origin node in the petiole if present
            if app.PetioleUseCheckBox.Value == 1 && isfield(app.images,'petiole') && ~isempty(app.images.petiole)
                [app.images.skeleton, ...
                    app.images.skLoop, ...
                    app.images.skTree, ...
                    app.images.petiole, ...
                    app.images.petiole_node, ...
                    app.images.perimeter_node] = funcPetioleSegment(app, ...
                    app.images.skeleton, ...
                    app.images.skLoop, ...
                    app.images.skTree, ...
                    app.images.petiole);
            end
            app.StatusLabel.Text = ['Skeleton extraction complete.']; drawnow
            app.var.G_veins = [];
            app.var.edgelist = [];
            app.images.edgeim = [];
            funcEnablePanels(app,'on',{'Analysis'})
        end

        % Value changing function: SegmentationThresholdSlider
        function SegmentationThresholdSliderValueChanging(app, event)
            changingValue = event.Value;
            app.SegmentationThresholdEditField.Value =  round(changingValue/100,2);
            bw = app.images.CNNmean>changingValue/100;
            im = cat(3,app.images.CLAHE,bw.*255,bw);
            imshow(im,[],'Parent',app.DisplayAxes);drawnow
        end

        % Value changed function: SegmentationThresholdSlider
        function SegmentationThresholdSliderValueChanged(app, event)
            app.SegmentationThresholdEditField.Value =  round(app.SegmentationThresholdSlider.Value/100,2);
        end

        % Value changed function: SegmentationThresholdEditField
        function SegmentationThresholdEditFieldValueChanged(app, event)
            app.param.SegmentationThreshold = app.SegmentationThresholdEditField.Value;
        end

        % Button pushed function: AnalysisButton
        function AnalysisButtonPushed(app, event)
            funcSetUpOutputFolders(app)
            % setup
            dirInitial = app.var.dirInitial;
            micron_per_pixel = app.MicronPerPixelEditField.Value;
            cd(dirInitial)
            app.StatusLabel.Text = 'Width estimation'; drawnow
            % extract the initial width along the skeleton from the distance transform
            [im_distance, ~] = bwdist(~app.images.CNNfw,'Euclidean');
            app.images.WidthPixels = zeros(size(im_distance),'single');
            app.images.WidthPixels(app.images.skeleton) = single(im_distance(app.images.skeleton).*2);
% % %             app.images.WidthGranulometry = fnc_granulometry(app);
% % %             funcImageDisplay(app,'WidthGranulometry')
% % %             app.images.WidthPixels(app.images.skeleton) = app.images.WidthGranulometry(app.images.skeleton);
            % Extract pixels in each edge
            app.StatusLabel.Text = 'Extracting edges. Please wait...';drawnow
            [app.var.edgelist, app.images.edgeim] = edgelink(app.images.skeleton);
            % find any self loops and split them
            app.StatusLabel.Text = 'Resolving self loops. Please wait...';drawnow
            [app.var.edgelist, app.images.edgeim] = fnc_resolve_self_loops(app);
            % resolve duplicate edges
            app.StatusLabel.Text = 'Resolving duplicate edges. Please wait...';drawnow
            [app.var.edgelist, ~] = fnc_resolve_duplicates(app);
            % construct the weighted graph
            app.StatusLabel.Text = 'Constructing the weighted graph. Please wait...';drawnow
            [app.var.G_veins,app.var.edgelist] = fnc_weighted_graph(app);
            % add in the petiole
            if isfield(app.images,'petiole') && app.PetioleUseCheckBox.Value == 1
                app.StatusLabel.Text = 'Adding the Petiole Node. Please wait...';drawnow
                [app.var.edgelist, app.images.edgeim, app.images.sk_petiole, app.var.G_veins] = funcAnalysisPetiole(app, ...
                    app.images.skeleton, ...
                    app.images.petiole_node, ...
                    app.images.perimeter_node, ...
                    app.var.edgelist, ...
                    app.images.edgeim, ...
                    app.images.skeleton, ...
                    app.var.G_veins);
            end
            % match the edgelist to the graph
            [app.var.edgelist, app.images.edgeim] = funcMatchEdgelist2Graph(app, ...
                app.var.G_veins, ...
                app.var.edgelist, ...
                app.images.edgeim);
            % refine the center width
            app.StatusLabel.Text = 'Refining the width. Please wait...';drawnow
            [app.var.G_veins,app.var.edgelist_center] = fnc_refine_width(app,app.var.G_veins,app.var.edgelist,app.images.crop,app.images.CNNmean,app.images.WidthPixels);
            % calculate distances and accessibility to the petiole
            if isfield(app.images,'petiole') && app.PetioleUseCheckBox.Value == 1
                [app.var.G_veins, app.images.distance, app.images.geodesic] = funcDistance(app, ...
                    app.var.G_veins, ...
                    app.var.edgelist);
                app.var.G_veins = funcHydraulicAccessibility(app,app.var.G_veins);
            end
            % calculate the coded image
            app.StatusLabel.Text = 'Calculating coded image. Please wait...';drawnow
            skWidth = app.param.AnalysisSkeletonWidth;
            [CW_pixels,im_width,coded_CW,coded_FW] = fnc_coded_skeleton(app,app.images.crop,app.images.skeleton,app.images.mask,app.var.G_veins,app.var.edgelist,app.var.edgelist_center,skWidth);
            app.images.coded = coded_FW;
            funcImageDisplay(app,'coded')
            % find the areoles and polygons
            app.StatusLabel.Text = 'Finding the polygon and areole areas. Please wait...';drawnow
            [app.var.G_veins, app.images.sk_polygon, app.images.bw_polygons, app.images.bw_areoles, app.images.total_area_mask, app.images.polygon_LM] = fnc_polygon_find(app,app.var.G_veins,app.images.CNNfw,app.images.skeleton,app.images.skLoop,app.images.mask);
            app.StatusLabel.Text = 'Polygon analysis. Please wait...';drawnow
            % get the areole stats
            FullMetrics = 1;
            [app.var.areole_stats,app.var.polygon_stats] = fnc_polygon_analysis(app,app.images.bw_polygons,app.images.bw_areoles, app.images.polygon_LM, FullMetrics);
            % construct the areole image
            app.StatusLabel.Text = 'Constructing colour-coded polygon image. Please wait...';drawnow
            app.images.polygonsRGB = fnc_polygon_image(app,app.var.polygon_stats, app.images.sk_polygon, app.images.total_area_mask);
            funcImageDisplay(app,'polygonsRGB')
            app.images.areolesRGB = fnc_polygon_image(app,app.var.areole_stats, app.images.sk_polygon, app.images.total_area_mask);
            funcImageDisplay(app,'areolesRGB')
            % get the dual-graph metrics
            app.StatusLabel.Text = 'Extracting the areole and polygon graph metrics. Please wait...';drawnow
            [app.var.G_polygons,app.images.polygon_LM2] = fnc_area_graph(app,app.var.G_veins,app.var.polygon_stats,app.images.polygon_LM,'polygons');
            [app.var.G_areoles,~] = fnc_area_graph(app,app.var.G_veins,app.var.areole_stats,app.images.polygon_LM,'areoles');
            % calculate the summary statistics
            app.StatusLabel.Text = 'Calculating summary statistics. Please wait...';drawnow
            total_area = sum(app.images.mask(:));
            polygon_area = sum(app.var.G_polygons.Nodes.Area);
            veins = fnc_summary_veins(app,app.var.G_veins,total_area,polygon_area,micron_per_pixel);
            areoles = fnc_summary_areoles(app,app.var.G_areoles,polygon_area,micron_per_pixel,FullMetrics);
            polygons = fnc_summary_polygons(app,app.var.G_polygons,micron_per_pixel,FullMetrics);
            results = [veins areoles polygons];
            % add in the file information
            results.File = app.var.fname;
            results.TimeStamp = datetime('now','TimeZone','local','Format','d-MMM-y HH:mm:ss Z');
            results.MicronPerPixel = micron_per_pixel;
            results.Threshold = app.SegmentationThresholdEditField.Value;
            % reorder the table to get the file info first
            app.var.Results = results(:, [end-3:end 1:end-4]);
            if app.AnalysisAutoSaveCheckBox.Value
                funcAnalysisSaveData(app)
                funcAnalysisSaveFigure(app)
            end
            funcEnablePanels(app,'on',{'HLD','Results','GraphDisplayControls'})
            app.StatusLabel.Text = 'Analysis complete. Hooray!';drawnow
        end

        % Button pushed function: AnalysisSaveDataButton
        function AnalysisSaveDataButtonPushed(app, event)
            funcAnalysisSaveData(app)
        end

        % Button pushed function: HLDRunButton
        function HLDRunButtonPushed(app, event)
            [~,FolderName,~] = fileparts(app.var.fname) ;% not actually a folder - just the root of the filename at the moment
            app.StatusLabel.Text = 'Hierarchical Loop Decomposition. Please wait...';drawnow
            [G_HLD, HLD_image, parent] = fnc_HLD(app,app.var.G_veins, app.var.G_polygons, app.var.polygon_stats, app.var.areole_stats, app.images.polygon_LM2, app.images.bw_polygons, app.MicronPerPixelEditField.Value);
            app.var.G_HLD = G_HLD;
            app.images.HLD_image = HLD_image;
            app.var.HLD_parent = parent;
            if app.HLDSaveFigureCheckBox.Value
                save([app.var.dir_out_HLD FolderName '_HLD_results.mat'],'G_HLD','HLD_image','parent')
                funcSave2Excel(app,'G_HLD','Nodes')
                funcHLDSaveFigure(app)
            end
            app.StatusLabel.Text = 'Hierarchical Loop Decomposition completed. Hooray!';drawnow
        end

        % Value changed function: HLDFullLeafCheckBox
        function HLDFullLeafCheckBoxValueChanged(app, event)
            app.param.AnalysisFullLeaf = app.HLDFullLeafCheckBox.Value;
        end

        % Button pushed function: HLDSaveDataButton
        function HLDSaveDataButtonPushed(app, event)
            app.StatusLabel.Text = 'Saving HLD data. Please wait...';drawnow
            funcSave2Excel(app,'G_HLD','Nodes')
            app.StatusLabel.Text = 'HLD Data saved';drawnow
        end

        % Button pushed function: FigureSaveButton
        function FigureSaveButtonPushed(app, event)
            app.StatusLabel.Text = 'Saving Analysis Figure. Please wait...';drawnow
            % compile the figure
            funcAnalysisSaveFigure(app)
            app.StatusLabel.Text = 'Analysis data saved';drawnow
        end

        % Value changed function: FigureEdgeWidthDropDown
        function FigureEdgeWidthDropDownValueChanged(app, event)
            app.param.AnalysisEdgeWidth = str2double(app.FigureEdgeWidthDropDown.Value);
        end

        % Value changed function: FigureSkeletonWidthDropDown
        function FigureSkeletonWidthDropDownValueChanged(app, event)
            app.param.AnalysisSkeletonWidth = str2double(app.FigureSkeletonWidthDropDown.Value);
        end

        % Button pushed function: HLDFigureSaveButton
        function HLDFigureSaveButtonPushed(app, event)
            app.StatusLabel.Text = 'Saving HLD Figure. Please wait...';drawnow
            funcHLDSaveFigure(app)
            app.StatusLabel.Text = 'HLD Figure saved';drawnow
        end

        % Value changed function: DisplayImageDropDown
        function DisplayImageDropDownValueChanged(app, event)
            target = app.DisplayImageDropDown.Value;
            funcImageDisplay(app,target)
        end

        % Value changed function: GraphImageDropDown
        function GraphImageDropDownValueChanged(app, event)
            target = app.GraphImageDropDown.Value;
            funcAnalysisDisplay(app,target)
        end

        % Value changed function: GraphUnitsDropDown
        function GraphUnitsDropDownValueChanged(app, event)
            app.display.GraphUnits = app.GraphUnitsDropDown.Value;
            if app.GraphEdgePlotCheckBox.Value || app.GraphNodePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphEdgeColorMapDropDown
        function GraphEdgeColorMapDropDownValueChanged(app, event)
            funcSetUpEdgeColormap(app)
            if app.GraphEdgePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphEdgeDropDown
        function GraphEdgeDropDownValueChanged(app, event)
            app.display.GraphEdge.Value = app.GraphEdgeDropDown.Value;
            if app.GraphEdgePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphEdgePlotCheckBox
        function GraphEdgePlotCheckBoxValueChanged(app, event)
            if app.GraphEdgePlotCheckBox.Value
                funcDisplayGraph(app)
            else
                funcClearGraph(app,{'Edges'})
            end
        end

        % Value changed function: GraphEdgeStyleDropDown
        function GraphEdgeStyleDropDownValueChanged(app, event)
            app.display.GraphEdgeStyle.Value = app.GraphEdgeStyleDropDown.Value;
            if app.GraphEdgePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphEdgeLogUseCheckBox
        function GraphEdgeLogUseCheckBoxValueChanged(app, event)
            app.display.GraphEdgeLogUse = app.GraphEdgeLogUseCheckBox.Value;
            if app.GraphEdgePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphEdgeWidthDropDown
        function GraphEdgeWidthDropDownValueChanged(app, event)
            app.display.GraphEdgeWidth = str2double(app.GraphEdgeWidthDropDown.Value);
            if app.GraphEdgePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphEdgeLabelCheckBox
        function GraphEdgeLabelCheckBoxValueChanged(app, event)
            app.display.GraphEdgeLabel = app.GraphEdgeLabelCheckBox.Value;
            if app.GraphEdgePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphEdgeLabelColourDropDown
        function GraphEdgeLabelColourDropDownValueChanged(app, event)
            app.display.GraphEdgeLabelColour = app.GraphEdgeLabelColourDropDown.Value;
            if app.GraphEdgePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphNodeDropDown
        function GraphNodeDropDownValueChanged(app, event)
            app.display.GraphNode = app.GraphNodeDropDown.Value;
            if app.GraphNodePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphNodeColorMapDropDown
        function GraphNodeColorMapDropDownValueChanged(app, event)
            funcSetUpNodeColormap(app)
            if app.GraphNodePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphNodePlotCheckBox
        function GraphNodePlotCheckBoxValueChanged(app, event)
            app.display.GraphNodeDisplay = app.GraphNodePlotCheckBox.Value;
            if app.GraphNodePlotCheckBox.Value
                funcDisplayGraph(app)
            else
                funcClearGraph(app,{'Nodes'})
            end
        end

        % Value changed function: GraphNodeLogUseCheckBox
        function GraphNodeLogUseCheckBoxValueChanged(app, event)
            app.display.GraphNodeLogUse = app.GraphNodeLogUseCheckBox.Value;
            if app.GraphNodePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphNodeSizeDropDown
        function GraphNodeSizeDropDownValueChanged(app, event)
            app.display.GraphNodeSize = app.GraphNodeSizeDropDown.Value;
            if app.GraphNodePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphNodeLabelCheckBox
        function GraphNodeLabelCheckBoxValueChanged(app, event)
            app.display.GraphNodeLabel = app.GraphNodeLabelCheckBox.Value;
            if app.GraphNodePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: GraphNodeLabelColourDropDown
        function GraphNodeLabelColourDropDownValueChanged(app, event)
            app.display.GraphNodeLabelColour = app.GraphNodeLabelColourDropDown.Value;
            if app.GraphNodePlotCheckBox.Value
                funcDisplayGraph(app)
            end
        end

        % Value changed function: PolygonModeDropDown
        function PolygonModeDropDownValueChanged(app, event)
            funcPolygonOptionsUpdate(app)
        end

        % Value changed function: PolygonNodeDropDown
        function PolygonNodeDropDownValueChanged(app, event)
            app.display.GraphPolygon = app.PolygonNodeDropDown.Value;
            if app.PolygonNodePlotCheckBox.Value
                funcDisplayPolygonNodes(app)
            end
        end

        % Value changed function: PolygonNodePlotCheckBox
        function PolygonNodePlotCheckBoxValueChanged(app, event)
            app.display.GraphPolygonDisplay = app.PolygonNodePlotCheckBox.Value;
            if app.PolygonNodePlotCheckBox.Value
                funcDisplayPolygonNodes(app)
            else
                funcClearGraph(app,{'PolygonNodes';'DualGraph'})
            end
        end

        % Value changed function: PolygonLogUseCheckBox
        function PolygonLogUseCheckBoxValueChanged(app, event)
            app.display.GraphPolygonLogUse = app.PolygonLogUseCheckBox.Value;
            if app.PolygonNodePlotCheckBox.Value
                funcDisplayPolygonNodes(app)
            end
        end

        % Value changed function: PolygonSizeDropDown
        function PolygonSizeDropDownValueChanged(app, event)
            app.display.GraphPolygonSize = app.PolygonSizeDropDown.Value;
            if app.PolygonNodePlotCheckBox.Value
                funcDisplayPolygonNodes(app)
            end
        end

        % Value changed function: PolygonNodeLabelCheckBox
        function PolygonNodeLabelCheckBoxValueChanged(app, event)
            app.display.GraphPolygonLabel =  app.PolygonNodeLabelCheckBox.Value;
            if app.PolygonNodePlotCheckBox.Value
                funcDisplayPolygonNodes(app)
            end
        end

        % Value changed function: PolygonLabelColourDropDown
        function PolygonLabelColourDropDownValueChanged(app, event)
            app.display.GraphPolygonLabelColour = app.PolygonLabelColourDropDown.Value;
            if app.PolygonNodePlotCheckBox.Value
                funcDisplayPolygonNodes(app)
            end
        end

        % Value changed function: PolygonDualGraphPlotCheckBox
        function PolygonDualGraphPlotCheckBoxValueChanged(app, event)
            app.display.GraphPolygonNodeDisplay = app.PolygonDualGraphPlotCheckBox.Value;
            if app.PolygonDualGraphPlotCheckBox.Value
                funcDisplayPolygonDualGraph(app)
            else
                funcClearGraph(app,{'DualGraph'})
            end
        end

        % Value changed function: PolygonNodeColorMapDropDown
        function PolygonNodeColorMapDropDownValueChanged(app, event)
            funcSetUpPolygonColormap(app)
            if app.PolygonNodePlotCheckBox.Value
                funcDisplayPolygonNodes(app)
            end
        end

        % Button pushed function: ResultsClearButton
        function ResultsClearButtonPushed(app, event)
            h = findobj(app.ResultsAxes,'-not','type', 'axes','-not','type','image');
            delete(h)
        end

        % Value changed function: ResultsTargetDropDown
        function ResultsTargetDropDownValueChanged(app, event)
            funcResultsTarget(app)
        end

        % Value changed function: ResultsYaxisDropDown
        function ResultsYaxisDropDownValueChanged(app, event)
            funcResultsPlot(app)
        end

        % Value changed function: ResultsXaxisDropDown
        function ResultsXaxisDropDownValueChanged(app, event)
            funcResultsPlot(app)
        end

        % Value changed function: ResultsHoldCheckBox
        function ResultsHoldCheckBoxValueChanged(app, event)
            
        end

        % Value changed function: ResultsPlotTypeDropDown
        function ResultsPlotTypeDropDownValueChanged(app, event)
            funcResultsPlot(app)
        end

        % Value changed function: ResultsYlogCheckBox
        function ResultsYlogCheckBoxValueChanged(app, event)
            funcResultsPlot(app)
        end

        % Value changed function: ResultsXlogCheckBox
        function ResultsXlogCheckBoxValueChanged(app, event)
            funcResultsPlot(app)
        end

        % Value changed function: ResultsExcludeBoundaryCheckBox
        function ResultsExcludeBoundaryCheckBoxValueChanged(app, event)
            funcResultsPlot(app)
        end

        % Value changed function: ResultsUnitsDropDown
        function ResultsUnitsDropDownValueChanged(app, event)
            funcResultsPlot(app)
        end

        % Button pushed function: TestButton
        function TestButtonPushed(app, event)
           
            exportgraphics(app.EdgeColorbarAxes,'colorbar.emf')%app.images.vein = false(size(app.images.crop,1,2));
            % figure
%             montage(app.PR_sensitivity.images.Initial)
%             figure
%             montage(app.PR_sensitivity.images.CNN)
%             figure
%             montage(app.PR_sensitivity.images.PR_FWB)
%                         figure
%             montage(app.PR_sensitivity.images.PR_Sk)
%             save([app.var.dir_out_PR_results [FolderName '_results']],'app.PR_results','app.PR_methods')
%             sheets = setdiff(fieldnames(app.PR_results),{'File';'TimeStamp';'MicronPerPixel';'DownSample'});
%             for iS = 1:numel(sheets)
%                 writetable(app.PR_results.(sheets{iS}),[dir_out_PR_results [FolderName '_results.xlsx']],'FileType','Spreadsheet','Sheet',sheets{iS},'WriteVariableNames',true,'WriteRowNames',true)
%             end
% funcPRTableUpdate(app)
% 
% 
%             assignin('base','PR_methods',app.PR_methods)
%             assignin('base','PR_results',app.PR_results)
%             assignin('base','PR_sensitivity',app.PR_sensitivity)
%             funcPRPlotSensitivity(app,[1,2],'Blur')
            % hold(app.PRAxes,'off')
            % data = app.PR_results.F1_ratio{:,:}';
            % labels = categorical(app.PR_results.F1.Properties.VariableNames);
            %             bar(app.PRAxes,labels,data)
            %             app.PRAxes.Title.String = 'xxx';
            %             %app.PRAxes.XTickMode = 'auto';
            %             app.PRAxes.YTickMode = 'auto';
            %             axis(app.PRAxes,"auto")
            % app.var.G_veins.Nodes.node_Coefficient  = funcSolveCoefficient(app,app.var.G_veins);


            %             app.images.polygonsRGB = fnc_polygon_image(app,app.var.polygon_stats, app.images.sk_polygon, app.images.total_area_mask);
            %             funcImageDisplay(app,'polygonsRGB')
            %             app.StatusLabel.Text = 'Initialising the CNN';
            %             tic
            %             funcGetModels(app);
            %             nModels = numel(app.var.CNNModelNames);
            %             app.images.CNN = zeros([size(app.images.crop) nModels]);
            %             stride = round(app.CNNStrideSlider.Value);
            %             UseParallel = app.CNNParallelCheckBox.Value;
            %             if mod(stride,2) == 1
            %                 stride = stride+1;
            %             end
            %
            %             for iF = 1:nModels
            %                 fname = app.var.CNNModelNames{iF};
            %                 app.StatusLabel.Text = ['Calculating the CNN prediction for iteration ' num2str(iF) ' using network ' fname '. This will take some time. Please wait...'];drawnow
            %                 temp = load(fullfile(app.var.CNNModelDir,fname));
            %                 net = temp.net;
            %                 myfun = @(block_struct) predict(net,block_struct.data);
            %                 border_size = [(256-stride)/2 (256-stride)/2];
            %                 cnn = blockproc(app.images.CLAHE,[stride stride],myfun,'PadPartialBlocks',true,'PadMethod','replicate','BorderSize',border_size,'UseParallel',UseParallel,'DisplayWaitBar',true);
            %                 cnn1 = imresize(cnn(1:size(app.images.CLAHE,1),1:size(app.images.CLAHE,2)),size(app.images.crop),'bilinear');
            %
            %                 im = impyramid(app.images.CLAHE,'expand');
            %                 cnn = blockproc(im,[stride stride],myfun,'PadPartialBlocks',true,'PadMethod','replicate','BorderSize',border_size,'UseParallel',UseParallel,'DisplayWaitBar',true);
            %                 cnn2 = imresize(cnn(1:size(im,1),1:size(im,2)),size(app.images.crop),'bilinear');
            %                 app.images.CNN(:,:,iF) = max(cnn1,cnn2);
            %             end
            %             app.images.CNNmean = mean(app.images.CNN,3);
            %             figure
            %             subplot(1,3,1)
            %             imshow(cnn1,[])
            %             subplot(1,3,2)
            %             imshow(cnn2,[])
            %             subplot(1,3,3)
            %             imshow(app.images.CNNmean,[])
            %             funcImageDisplay(app,'CNN')
            %             app.DisplayImageDropDown.Items = unique([app.DisplayImageDropDown.Items, 'CNNmean'],'stable');
            %             app.DisplayImageDropDown.Items = unique([app.DisplayImageDropDown.Items, 'CNNoverlay'],'stable');
            %             funcEnablePanels(app,'on',{'Petiole','Segmentation'})
            %             t = toc;
            %             app.StatusLabel.Text = ['CNN Complete in ' num2str(t,0) ' s.'];
        end

        % Button pushed function: SaveAllPanelsButton
        function SaveAllPanelsButtonPushed(app, event)
            [fout,pathname] = uiputfile('*.png','Panel output');
            if ~isempty(fout)
                pos1 = app.LeafVeinGUI.Position;
                [~, name, ~] = fileparts(fout);
                fileout = [name '_all'];
                cd(pathname)
                app.TabGroup.SelectedTab = app.ResultsTab;
                figure(app.LeafVeinGUI)
                drawnow
                pause(0.5)
                if app.SaveHighResCheckBox.Value
                    screencapture(0,[pos1(1)*2 -pos1(4) pos1(3)*2 pos1(4)*2],'target',fullfile(pathname,[fileout '.png']))
                else
                    screencapture(0,pos1,'target',fullfile(pathname,[fileout '.png']))
                end
            end
            % we cannot easily access the components in the app designer
            % GUI, so we have to specify them manually!
            panels = {'Load';'Image';'DisplayControls';'Scaling';'Channel';'Crop';'Petiole';'Segmentation';'Analysis';'CLAHE';'HLD'};
            CNNtabs = {'CNNPredictTab';'CNNSelectTab'};
            resultsTabs = {'GraphDisplayControls';'Results'};
            outputTabs = {'DisplayOutput';'AnalysisFigures';'HLDFigures'};
            PRTabs = {'PRResults';'PRAnalysis';'PRSensitivity'};
            app.CNN.SelectedTab = app.CNNPredictTab;
            %exportgraphics(app.CNNPredictTab,'test.png')
            % save every panel as a png
            for iP = 1:numel(panels)
                fileout = [name '_' panels{iP,1}];
                %exportgraphics(app.([panels{iP,1} 'Panel']),[fileout '.pdf'],'ContentType','auto')
                pos2 = app.([panels{iP,1} 'Panel']).Position;
                figure(app.LeafVeinGUI)
                drawnow
                pause(0.5)
                if app.SaveHighResCheckBox.Value
                    screencapture(0,[2*pos1(1)+2*pos2(1)-1 -pos1(4)+2*pos2(2) 2*pos2(3) 2*pos2(4)],'target',fullfile(pathname,[fileout '.png']))
                else
                    screencapture(0,[pos1(1)+pos2(1) pos1(2)+pos2(2) pos2(3) pos2(4)],'target',fullfile(pathname,[fileout '.png']))
                end
            end
            for iP = 1:numel(CNNtabs)
                fileout = [name '_' CNNtabs{iP,1}];
                pos2 = app.CNN.Position;
                app.CNN.SelectedTab = app.(CNNtabs{iP,1});
                %exportgraphics(app.(CNNtabs{iP,1}),[fileout '.png'],'ContentType','image','Resolution','300')
                figure(app.LeafVeinGUI)
                drawnow
                pause(0.5)
                if app.SaveHighResCheckBox.Value
                    screencapture(0,[2*pos1(1)+2*pos2(1) -pos1(4)+2*pos2(2) 2*pos2(3) 2*pos2(4)],'target',fullfile(pathname,[fileout '.png']))
                else
                    screencapture(0,[pos1(1)+pos2(1) pos1(2)+pos2(2) pos2(3) pos2(4)],'target',fullfile(pathname,[fileout '.png']))
                end
            end
            for iP = 1:numel(resultsTabs)
                fileout = [name '_' resultsTabs{iP,1}];
                pos2 = app.TabGroup.Position;
                app.TabGroup.SelectedTab = app.ResultsTab;
                %exportgraphics(app.([resultsTabs{iP,1} 'Panel']),[fileout '.png'],'ContentType','image','Resolution','300')
                figure(app.LeafVeinGUI)
                drawnow
                pause(0.5)
                pos3 = app.([resultsTabs{iP,1} 'Panel']).Position;
                if app.SaveHighResCheckBox.Value
                    screencapture(0,[2*pos1(1)+2*pos2(1)+pos3(1) -pos1(4)+2*pos2(2)+2*pos3(2) 2*pos3(3) 2*pos3(4)],'target',fullfile(pathname,[fileout '.png']))
                else
                    screencapture(0,[pos1(1)+pos2(1)+pos3(1) pos1(2)+pos2(2)+pos3(2) pos3(3) pos3(4)],'target',fullfile(pathname,[fileout '.png']))
                end
            end
            for iP = 1:numel(outputTabs)
                fileout = [name '_' outputTabs{iP,1}];
                pos2 = app.TabGroup.Position;
                app.TabGroup.SelectedTab = app.OutputTab;
                %exportgraphics(app.([resultsTabs{iP,1} 'Panel']),[fileout '.png'],'ContentType','image','Resolution','300')
                figure(app.LeafVeinGUI)
                drawnow
                pause(0.5)
                pos3 = app.([outputTabs{iP,1} 'Panel']).Position;
                if app.SaveHighResCheckBox.Value
                    screencapture(0,[2*pos1(1)+2*pos2(1)+pos3(1) -pos1(4)+2*pos2(2)+2*pos3(2) 2*pos3(3) 2*pos3(4)],'target',fullfile(pathname,[fileout '.png']))
                else
                    screencapture(0,[pos1(1)+pos2(1)+pos3(1) pos1(2)+pos2(2)+pos3(2) pos3(3) pos3(4)],'target',fullfile(pathname,[fileout '.png']))
                end
            end
            for iP = 1:numel(PRTabs)
                fileout = [name '_' PRTabs{iP,1}];
                pos2 = app.TabGroup.Position;
                app.TabGroup.SelectedTab = app.PRTab;
                %exportgraphics(app.([resultsTabs{iP,1} 'Panel']),[fileout '.png'],'ContentType','image','Resolution','300')
                figure(app.LeafVeinGUI)
                drawnow
                pause(0.5)
                pos3 = app.([PRTabs{iP,1} 'Panel']).Position;
                if app.SaveHighResCheckBox.Value
                    screencapture(0,[2*pos1(1)+2*pos2(1)+pos3(1) -pos1(4)+2*pos2(2)+2*pos3(2) 2*pos3(3) 2*pos3(4)],'target',fullfile(pathname,[fileout '.png']))
                else
                    screencapture(0,[pos1(1)+pos2(1)+pos3(1) pos1(2)+pos2(2)+pos3(2) pos3(3) pos3(4)],'target',fullfile(pathname,[fileout '.png']))
                end
            end
        end

        % Button pushed function: SaveSingleImageButton
        function SaveSingleImageButtonPushed(app, event)
            [fout,pathname] = uiputfile('*.png','Panel output');
            if ~isempty(fout)
                [~, fileout, ~] = fileparts(fout);
                cd(pathname)
                exportgraphics(app.DisplayAxes,[fileout '.png'],'ContentType','image','Resolution','300')
                exportgraphics(app.DisplayAxes,[fileout '.pdf'],'ContentType','auto')
                funcSaveImage(app,[fileout 'Zoom'])
            end
        end

        % Button pushed function: SaveGUIButton
        function SaveGUIButtonPushed(app, event)
            [fout,pathname] = uiputfile('*.png','Panel output');
            if ~isempty(fout)
                pos1 = app.LeafVeinGUI.Position;
                [~, name, ~] = fileparts(fout);
                fileout = [name '_all'];
                cd(pathname)
                app.TabGroup.SelectedTab = app.ResultsTab;
                figure(app.LeafVeinGUI)
                drawnow
                pause(0.5)
                %exportgraphics(app.LeafVeinGUI,[fileout '.png'],'ContentType','image','Resolution','300')
                %exportgraphics(app.LeafVeinGUI,[fileout '.pdf'],'ContentType','auto')
                if app.SaveHighResCheckBox.Value
                    screencapture(0,[pos1(1)*2 -pos1(4) pos1(3)*2 pos1(4)*2],'target',fullfile(pathname,[fileout '.png']))
                else
                    screencapture(0,pos1,'target',fullfile(pathname,[fileout '.png']))
                end
                
            end
        end

        % Button pushed function: SavePlotsButton
        function SavePlotsButtonPushed(app, event)
            [fout,pathname] = uiputfile('*.png','Save Plots');
            if ~isempty(fout)
                [~, fileout, ~] = fileparts(fout);
                cd(pathname)
                figure(app.LeafVeinGUI)
                drawnow
                pause(0.5)
                funcSavePlots(app, fileout)
            end
        end

        % Button pushed function: SaveManualButton
        function SaveManualButtonPushed(app, event)
            cd(app.var.dirCNN)
            % bring the results tab into focus
            app.TabGroup.SelectedTab = app.ResultsTab;
            figure(app.LeafVeinGUI)
            % reset the display axes and zoom
            app.DisplayAxes.XLimMode = 'auto';
            app.DisplayAxes.YLimMode = 'auto';
            zoom(app.DisplayAxes, 1.5);
            % set up the images to be saved
            items = {'crop';'CLAHE'; 'CNN';'CNNmean';'CNNfw';'CNNoverlay';'skeleton';'coded';'areolesRGB';'polygonsRGB'};
            for i = 1:length(items)
                funcImageDisplay(app, items{i});
                funcSaveImage(app, [items{i} 'Zoom'])
            end
            % save the dual graph
            app.PolygonNodePlotCheckBox.Value = 1;
            app.PolygonDualGraphPlotCheckBox.Value = 1;
            funcImageDisplay(app, 'areolesRGB');
            funcDisplayPolygonDualGraph(app)
            funcSaveImage(app,'polygonsDualGraphZoom')
            % save the graphs
            app.GraphImageDropDown.Value = 'clahe';
            app.GraphEdgePlotCheckBox.Value = true;
            app.GraphEdgeWidthDropDown.Value = '2';
            app.GraphNodeSizeDropDown.Value = '3';
            funcAnalysisDisplay(app, 'clahe');
            app.GraphEdgeColorMapDropDown.Value = 'R2';
            app.GraphNodeColorMapDropDown.Value = 'hsv';
            % plot the selected graphs
            items = { ...
                'veins','Width','1D hist';
                'veins','Length','1D hist';
                'nodes','theta_D2_D1','1D hist';
                'polygons','polygon_Area','1D hist';
                'HLD','HLD_MSTRatio','scatter'};
            for i = 1:size(items,1)
                app.ResultsTargetDropDown.Value = items{i,1};
                funcResultsTarget(app)
                switch items{i,1}
                    case 'veins'
                        funcAnalysisDisplay(app, 'clahe');
                        app.GraphEdgeDropDown.Value = items{i,2};
                        app.GraphNodeLabelCheckBox.Value = 0;
                        app.GraphNodePlotCheckBox.Value = 0;
                        app.PolygonNodePlotCheckBox.Value = 0;
                    case 'nodes'
                        funcAnalysisDisplay(app, 'clahe');
                        app.GraphEdgeDropDown.Value = 'Width';
                        app.GraphNodeDropDown.Value = items{i,2};
                        app.GraphNodeLabelCheckBox.Value = 1;
                        app.GraphNodePlotCheckBox.Value = 1;
                        app.PolygonNodePlotCheckBox.Value = 0;
                    case 'polygons'
                        funcAnalysisDisplay(app, 'areolesRGB');
                        app.PolygonModeDropDown.Value = 'polygons';
                        funcPolygonOptionsUpdate(app)
                        app.PolygonNodeDropDown.Value = items{i,2};
                        app.GraphNodeLabelCheckBox.Value = 0;
                        app.GraphNodePlotCheckBox.Value = 0;
                        app.PolygonNodePlotCheckBox.Value = 1;
                        app.PolygonDualGraphPlotCheckBox.Value = 0;
                        app.PolygonNodeLabelCheckBox.Value = 1;
                        app.PolygonSizeDropDown.Value = '4';
                        app.PolygonLabelColourDropDown.Value = 'k';
                        %funcDisplayPolygonDualGraph(app)
                        funcDisplayPolygonNodes(app)
                    case 'HLD'
                        app.ResultsXaxisDropDown.Value = 'HLD_width_threshold';
                end
                funcDisplayGraph(app)
                funcSaveImage(app, [items{i,2} 'Graph'])
                
                app.ResultsYaxisDropDown.Value = items{i,2};
                app.ResultsPlotTypeDropDown.Value = items{i,3};
                
                funcResultsPlot(app)
                funcSavePlots(app, [items{i} 'Graph'])
            end
        end

        % Button pushed function: PRLoadGTButton
        function PRLoadGTButtonPushed(app, event)
            [app.var.fname_GT,~,~] = uigetfile({'*_GT.png'},'Select File');
            funcLoadImage(app,'GT_original')
            if app.CropSkipCheckBox.Value == 1
                app.images.GT = app.images.GT_original;
            else
                app.images.GT = app.images.GT_original(app.var.CropROI(2):app.var.CropROI(2)+app.var.CropROI(4), app.var.CropROI(1):app.var.CropROI(1)+app.var.CropROI(3));
            end
            app.DisplayImageDropDown.Items = [app.DisplayImageDropDown.Items,'GT'];
            funcImageDisplay(app, 'GT')
            app.StatusLabel.Text = ['Ground-Truth image loaded'];
            figure(app.LeafVeinGUI)
        end

        % Button pushed function: PRLoadGTMaskButton
        function PRLoadGTMaskButtonPushed(app, event)
            [app.var.fname_GT_mask,~,~] = uigetfile({'*_GT_mask.png'},'Select File');
            funcLoadImage(app,'GT_mask_original')
            if app.CropSkipCheckBox.Value == 1
                app.images.GT_mask = app.images.GT_mask_original;
            else
                app.images.GT_mask = app.images.GT_mask_original(app.var.CropROI(2):app.var.CropROI(2)+app.var.CropROI(4), app.var.CropROI(1):app.var.CropROI(1)+app.var.CropROI(3));
            end
            app.DisplayImageDropDown.Items = [app.DisplayImageDropDown.Items,'GT_mask'];
            funcImageDisplay(app, 'GT_mask')
            app.StatusLabel.Text = ['Ground-Truth mask image loaded'];
            figure(app.LeafVeinGUI)            
            figure(app.LeafVeinGUI)
        end

        % Button pushed function: PRRunButton
        function PRRunButtonPushed(app, event)
            funcPR(app)
        end

        % Value changed function: PRImageDropDown
        function PRImageDropDownValueChanged(app, event)
            funcPRImageDisplay(app)
        end

        % Value changed function: PRMetricDropDown
        function PRMetricDropDownValueChanged(app, event)
            funcPRImageDisplay(app)
        end

        % Value changed function: PRTypeDropDown
        function PRTypeDropDownValueChanged(app, event)
            funcPRImageDisplay(app)
        end

        % Value changed function: PRGraphDropDown
        function PRGraphDropDownValueChanged(app, event)
            switch app.PRGraphDropDown.Value
                case {'PR FWB'; 'PR Skeleton'}
                    funcPRPlotPRC(app)
                case {'F1 FWB'; 'F1 Skeleton'; 'F2 FWB'; 'F2 Skeleton'}
                    funcPRPlotMetric(app)
                case 'Network '
                    funcPRPlotNetwork(app)
            end
        end

        % Cell edit callback: PRResultsTable
        function PRResultsTableCellEdit(app, event)
            app.PR_methods.select = logical([app.PRResultsTable.Data{:,1}])';
        end

        % Value changed function: PRDownSampleDropDown
        function PRDownSampleDropDownValueChanged(app, event)
            app.param.PRDownSample = str2double(app.PRDownSampleDropDown.Value);
        end

        % Button pushed function: PRPlotGraph
        function PRPlotGraphButtonPushed(app, event)
            switch app.PRGraphDropDown.Value
                case {'PR FWB'; 'PR Skeleton'}
                    funcPRPlotPRC(app)
                case {'F1 FWB'; 'F1 Skeleton'; 'F2 FWB'; 'F2 Skeleton'}
                    funcPRPlotMetric(app)
                case 'Network '
                    funcPRPlotNetwork(app)
            end
        end

        % Button pushed function: PRDisplayImage
        function PRDisplayImageButtonPushed(app, event)
            funcPRImageDisplay(app)
        end

        % Button pushed function: PRSaveDataButton
        function PRSaveDataButtonPushed(app, event)
            PR_results = app.PR_results;
            PR_methods = app.PR_methods;
            save([app.var.dir_out_PR_results [app.var.PRName '_results']],'PR_results','PR_methods')
            sheets = setdiff(fieldnames(app.PR_results),{'File';'TimeStamp';'MicronPerPixel';'DownSample'});
            for iS = 1:numel(sheets)
                writetable(app.PR_results.(sheets{iS}),[app.var.dir_out_PR_results [app.var.PRName '_results.xlsx']],'FileType','Spreadsheet','Sheet',sheets{iS},'WriteVariableNames',true,'WriteRowNames',true)
            end
        end

        % Button pushed function: PRSensitivityButton
        function PRSensitivityButtonButtonPushed(app, event)
            funcPRSensitivity(app)
        end

        % Value changed function: PRWidthDropDown
        function PRWidthDropDownValueChanged(app, event)
           app.param.PRWidth = str2double(app.PRWidthDropDown.Value);
        end

        % Value changed function: PRSensitivityDisplayDropDown
        function PRSensitivityDisplayDropDownValueChanged(app, event)
            
            switch app.PRTypeDropDown.Value
                case 'FWB'
                    mode = 'FWB';
                case 'Skeleton'
                    mode = 'sk';
            end
            
            code = app.PRSensitivityDisplayDropDown.Value;
            control = 2;
            switch code
                case 'CLAHE'
                    idx = [1:4];
                case 'Contrast'
                    idx = [control,5:7];
                case 'Contrast renorm'
                    idx = [control,8:10];
                case 'Blur'
                    idx = [control,11:13];
                case 'Scale'
                    idx = [control,14:16];
                case 'Upsample'
                    idx = [control,17:19];
            end
            funcPRPlotSensitivity(app,mode,idx,code)
        end

        % Button pushed function: PRSensitivitySaveButton
        function PRSensitivitySaveButtonPushed(app, event)
            % save the figures
            control = 3;
            for i = 1:6
                if i == 1
                    code = 'CLAHE'; % varying CLAHE
                    suffix = '';
                     idx = [1:4];
                elseif i == 2
                    code = 'Contrast'; % varying contrast, no CLAHE
                    idx = [control,5:7];
                    suffix = '';
                elseif i == 3
                    code = 'Contrast'; % varying contrast, with CLAHE
                    idx = [control,8:10];
                    suffix = '_CLAHE';                    
                elseif i == 4
                    code = 'Blur'; % different sigma
                    idx = [control,11:13];
                    suffix = '';
                elseif i == 5
                    code = 'Scale'; % downsampling, no interpolation
                    idx = [control,14:16];
                    suffix = '';
                elseif i == 6
                    code = 'Upsample'; % downsampling and then upsampling
                    idx = [control,17:19];
                    suffix = '';
                end
                hfig = funcPRSensitivityFigure(app,idx,code);
                exportgraphics(hfig,[app.var.dir_out_PR_images 'Sensitivity_' code suffix '.png'])
                exportgraphics(hfig,[app.var.dir_out_PR_images 'Sensitivity_' code suffix '.pdf'])
                exportgraphics(hfig,[app.var.dir_out_PR_images 'Sensitivity_' code suffix '.emf'])
                delete(hfig)
                figure(app.LeafVeinGUI)
                app.TabGroup.SelectedTab = app.PRTab;
                % save the FWB PR plots
                funcPRPlotSensitivity(app,'FWB',idx,code)
                drawnow
                pause(0.5)                
                exportgraphics(app.PRAxes,[app.var.dir_out_PR_graphs 'Sensitivity_PR_' code  suffix '_FWB.png'])
                exportgraphics(app.PRAxes,[app.var.dir_out_PR_graphs 'Sensitivity_PR_' code  suffix '_FWB.pdf'])
                exportgraphics(app.PRAxes,[app.var.dir_out_PR_graphs 'Sensitivity_PR_' code  suffix '_FWB.emf'])
                % save the skeleton PR plots
                funcPRPlotSensitivity(app,'sk',idx,code)
                drawnow
                pause(0.5)                
                exportgraphics(app.PRAxes,[app.var.dir_out_PR_graphs 'Sensitivity_PR_' code  suffix '_sk.png'])
                exportgraphics(app.PRAxes,[app.var.dir_out_PR_graphs 'Sensitivity_PR_' code  suffix '_sk.png'])
                exportgraphics(app.PRAxes,[app.var.dir_out_PR_graphs 'Sensitivity_PR_' code  suffix '_sk.emf'])
            end
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.LeafVeinGUI = uifigure('Visible', 'off');
            app.LeafVeinGUI.Name = 'UI Figure';
            app.LoadPanel = uipanel(app.LeafVeinGUI);
            app.LoadPanel.Title = 'Load';
            app.LoadImageButton = uibutton(app.LoadPanel, 'push');
            app.LoadImageButton.ButtonPushedFcn = createCallbackFcn(app, @LoadImageButtonPushed, true);
            app.LoadImageButton.Text = 'Load Image';
            app.ImageLabel = uilabel(app.LoadPanel);
            app.ImageLabel.Text = 'Image';
            app.DimensionsTextAreaLabel = uilabel(app.LoadPanel);
            app.DimensionsTextAreaLabel.Text = 'Dimensions';
            app.DimensionsTextArea = uitextarea(app.LoadPanel);
            app.TargetDropDownLabel = uilabel(app.LoadPanel);
            app.TargetDropDownLabel.Text = 'Target';
            app.TargetDropDown = uidropdown(app.LoadPanel);
            app.TargetDropDown.Items = {'original', 'CLAHE', 'CNN'};
            app.TargetDropDown.ValueChangedFcn = createCallbackFcn(app, @TargetDropDownValueChanged, true);
            app.TargetDropDown.Value = 'original';
            app.LoadMaskButton = uibutton(app.LoadPanel, 'push');
            app.LoadMaskButton.ButtonPushedFcn = createCallbackFcn(app, @LoadMaskButtonPushed, true);
            app.LoadMaskButton.Text = 'Load Mask';
            app.LoadVeinButton = uibutton(app.LoadPanel, 'push');
            app.LoadVeinButton.ButtonPushedFcn = createCallbackFcn(app, @LoadVeinButtonPushed, true);
            app.LoadVeinButton.Text = 'Load Vein';
            app.micronperpixelEditFieldLabel = uilabel(app.LoadPanel);
            app.micronperpixelEditFieldLabel.Text = 'micron per pixel';
            app.MicronPerPixelEditField = uieditfield(app.LoadPanel, 'numeric');
            app.MicronPerPixelEditField.ValueChangedFcn = createCallbackFcn(app, @MicronPerPixelEditFieldValueChanged, true);
            app.ImagePanel = uipanel(app.LeafVeinGUI);
            app.DisplayAxes = uiaxes(app.ImagePanel);
            title(app.DisplayAxes, 'Title')
            xlabel(app.DisplayAxes, '')
            ylabel(app.DisplayAxes, '')
            app.EdgeColorbarAxes = uiaxes(app.ImagePanel);
            title(app.EdgeColorbarAxes, '')
            xlabel(app.EdgeColorbarAxes, '')
            ylabel(app.EdgeColorbarAxes, '')
            app.EdgeColorbarAxes.XTick = [];
            app.EdgeColorbarAxes.XTickLabel = '';
            app.EdgeColorbarAxes.YTick = [];
            app.NodeColorbarAxes = uiaxes(app.ImagePanel);
            title(app.NodeColorbarAxes, '')
            xlabel(app.NodeColorbarAxes, '')
            ylabel(app.NodeColorbarAxes, '')
            app.NodeColorbarAxes.XTick = [];
            app.NodeColorbarAxes.XTickLabel = '';
            app.NodeColorbarAxes.YTick = [];
            app.PolygonColorbarAxes = uiaxes(app.ImagePanel);
            title(app.PolygonColorbarAxes, '')
            xlabel(app.PolygonColorbarAxes, '')
            ylabel(app.PolygonColorbarAxes, '')
            app.PolygonColorbarAxes.XTick = [];
            app.PolygonColorbarAxes.XTickLabel = '';
            app.PolygonColorbarAxes.YTick = [];
            app.DisplayControlsPanel = uipanel(app.LeafVeinGUI);
            app.DisplayControlsPanel.Title = 'Processing Display Controls';
            app.DisplayImageLabel = uilabel(app.DisplayControlsPanel);
            app.DisplayImageLabel.Text = 'Display Image';
            app.DisplayImageDropDown = uidropdown(app.DisplayControlsPanel);
            app.DisplayImageDropDown.Items = {'original'};
            app.DisplayImageDropDown.ValueChangedFcn = createCallbackFcn(app, @DisplayImageDropDownValueChanged, true);
            app.DisplayImageDropDown.Value = 'original';
            app.StatusLabel = uilabel(app.LeafVeinGUI);
            app.StatusLabel.Text = 'Status';
            app.TabGroup = uitabgroup(app.LeafVeinGUI);
            app.ResultsTab = uitab(app.TabGroup);
            app.ResultsTab.Title = 'Results';
            app.GraphDisplayControlsPanel = uipanel(app.ResultsTab);
            app.GraphDisplayControlsPanel.Title = 'Graph Display Controls';
            app.BaseimageLabel = uilabel(app.GraphDisplayControlsPanel);
            app.BaseimageLabel.Text = 'Base image';
            app.GraphImageDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphImageDropDown.Items = {'crop', 'clahe', 'CNNmean', 'skeleton', 'coded', 'polygonsRGB', 'areolesRGB'};
            app.GraphImageDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphImageDropDownValueChanged, true);
            app.GraphImageDropDown.Value = 'clahe';
            app.EdgemetricLabel = uilabel(app.GraphDisplayControlsPanel);
            app.EdgemetricLabel.Text = 'Edge metric';
            app.GraphEdgeDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphEdgeDropDown.Items = {'edge'};
            app.GraphEdgeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphEdgeDropDownValueChanged, true);
            app.GraphEdgeDropDown.Value = 'edge';
            app.NodemetricLabel = uilabel(app.GraphDisplayControlsPanel);
            app.NodemetricLabel.Text = 'Node metric';
            app.GraphNodeDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphNodeDropDown.Items = {'node'};
            app.GraphNodeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphNodeDropDownValueChanged, true);
            app.GraphNodeDropDown.Value = 'node';
            app.StyleLabel = uilabel(app.GraphDisplayControlsPanel);
            app.StyleLabel.Text = 'Style';
            app.GraphEdgeStyleDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphEdgeStyleDropDown.Items = {'graph', 'pixel'};
            app.GraphEdgeStyleDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphEdgeStyleDropDownValueChanged, true);
            app.GraphEdgeStyleDropDown.Value = 'graph';
            app.GraphEdgePlotCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.GraphEdgePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraphEdgePlotCheckBoxValueChanged, true);
            app.GraphEdgePlotCheckBox.Text = {'Plot'; ''};
            app.GraphNodePlotCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.GraphNodePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraphNodePlotCheckBoxValueChanged, true);
            app.GraphNodePlotCheckBox.Text = 'Plot';
            app.WidthLabel = uilabel(app.GraphDisplayControlsPanel);
            app.WidthLabel.Text = 'Width';
            app.GraphEdgeWidthDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphEdgeWidthDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8'};
            app.GraphEdgeWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphEdgeWidthDropDownValueChanged, true);
            app.GraphEdgeWidthDropDown.Value = '1';
            app.GraphEdgeLogUseCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.GraphEdgeLogUseCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraphEdgeLogUseCheckBoxValueChanged, true);
            app.GraphEdgeLogUseCheckBox.Text = 'log';
            app.GraphNodeLogUseCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.GraphNodeLogUseCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraphNodeLogUseCheckBoxValueChanged, true);
            app.GraphNodeLogUseCheckBox.Text = 'log';
            app.GraphEdgeLabelCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.GraphEdgeLabelCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraphEdgeLabelCheckBoxValueChanged, true);
            app.GraphEdgeLabelCheckBox.Text = 'Label';
            app.GraphNodeLabelCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.GraphNodeLabelCheckBox.ValueChangedFcn = createCallbackFcn(app, @GraphNodeLabelCheckBoxValueChanged, true);
            app.GraphNodeLabelCheckBox.Text = 'Label';
            app.UnitsLabel = uilabel(app.GraphDisplayControlsPanel);
            app.UnitsLabel.Text = 'Units';
            app.GraphUnitsDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphUnitsDropDown.Items = {'pixels', 'microns', 'mm'};
            app.GraphUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphUnitsDropDownValueChanged, true);
            app.GraphUnitsDropDown.Value = 'pixels';
            app.PolygonNodePlotCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.PolygonNodePlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolygonNodePlotCheckBoxValueChanged, true);
            app.PolygonNodePlotCheckBox.Text = 'Plot';
            app.PolygonLogUseCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.PolygonLogUseCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolygonLogUseCheckBoxValueChanged, true);
            app.PolygonLogUseCheckBox.Text = 'log';
            app.PolygonNodeDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.PolygonNodeDropDown.Items = {'polygon'};
            app.PolygonNodeDropDown.ValueChangedFcn = createCallbackFcn(app, @PolygonNodeDropDownValueChanged, true);
            app.PolygonNodeDropDown.Value = 'polygon';
            app.PolygonNodeLabelCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.PolygonNodeLabelCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolygonNodeLabelCheckBoxValueChanged, true);
            app.PolygonNodeLabelCheckBox.Text = 'Label';
            app.SizeLabel = uilabel(app.GraphDisplayControlsPanel);
            app.SizeLabel.Text = 'Size';
            app.GraphNodeSizeDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphNodeSizeDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '11', '12', '14', '16', '18', '20'};
            app.GraphNodeSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphNodeSizeDropDownValueChanged, true);
            app.GraphNodeSizeDropDown.Value = '5';
            app.SizeLabel_2 = uilabel(app.GraphDisplayControlsPanel);
            app.SizeLabel_2.Text = 'Size';
            app.PolygonSizeDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.PolygonSizeDropDown.Items = {'1', '2', '4', '5', '6', '7', '8', '9', '10', '11', '12', '14', '16', '18', '20'};
            app.PolygonSizeDropDown.ValueChangedFcn = createCallbackFcn(app, @PolygonSizeDropDownValueChanged, true);
            app.PolygonSizeDropDown.Value = '8';
            app.ColourDropDownLabel = uilabel(app.GraphDisplayControlsPanel);
            app.ColourDropDownLabel.Text = 'Colour';
            app.GraphEdgeLabelColourDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphEdgeLabelColourDropDown.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'k', 'w'};
            app.GraphEdgeLabelColourDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphEdgeLabelColourDropDownValueChanged, true);
            app.GraphEdgeLabelColourDropDown.Value = 'r';
            app.ColourDropDownLabel_2 = uilabel(app.GraphDisplayControlsPanel);
            app.ColourDropDownLabel_2.Text = 'Colour';
            app.GraphNodeLabelColourDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphNodeLabelColourDropDown.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'k', 'w'};
            app.GraphNodeLabelColourDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphNodeLabelColourDropDownValueChanged, true);
            app.GraphNodeLabelColourDropDown.Value = 'r';
            app.ColourDropDownLabel_3 = uilabel(app.GraphDisplayControlsPanel);
            app.ColourDropDownLabel_3.Text = 'Colour';
            app.PolygonLabelColourDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.PolygonLabelColourDropDown.Items = {'r', 'g', 'b', 'c', 'm', 'y', 'k', 'w'};
            app.PolygonLabelColourDropDown.ValueChangedFcn = createCallbackFcn(app, @PolygonLabelColourDropDownValueChanged, true);
            app.PolygonLabelColourDropDown.Value = 'r';
            app.cMapLabel = uilabel(app.GraphDisplayControlsPanel);
            app.cMapLabel.Text = 'cMap';
            app.GraphEdgeColorMapDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphEdgeColorMapDropDown.Items = {'parula'};
            app.GraphEdgeColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphEdgeColorMapDropDownValueChanged, true);
            app.GraphEdgeColorMapDropDown.Value = 'parula';
            app.cMapLabel_2 = uilabel(app.GraphDisplayControlsPanel);
            app.cMapLabel_2.Text = 'cMap';
            app.GraphNodeColorMapDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.GraphNodeColorMapDropDown.Items = {'parula'};
            app.GraphNodeColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @GraphNodeColorMapDropDownValueChanged, true);
            app.GraphNodeColorMapDropDown.Value = 'parula';
            app.cMapLabel_3 = uilabel(app.GraphDisplayControlsPanel);
            app.cMapLabel_3.Text = 'cMap';
            app.PolygonNodeColorMapDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.PolygonNodeColorMapDropDown.Items = {'parula'};
            app.PolygonNodeColorMapDropDown.ValueChangedFcn = createCallbackFcn(app, @PolygonNodeColorMapDropDownValueChanged, true);
            app.PolygonNodeColorMapDropDown.Value = 'parula';
            app.PolygonDualGraphPlotCheckBox = uicheckbox(app.GraphDisplayControlsPanel);
            app.PolygonDualGraphPlotCheckBox.ValueChangedFcn = createCallbackFcn(app, @PolygonDualGraphPlotCheckBoxValueChanged, true);
            app.PolygonDualGraphPlotCheckBox.Text = 'Dual';
            app.PolygonModeDropDown = uidropdown(app.GraphDisplayControlsPanel);
            app.PolygonModeDropDown.Items = {'areoles', 'polygons'};
            app.PolygonModeDropDown.ValueChangedFcn = createCallbackFcn(app, @PolygonModeDropDownValueChanged, true);
            app.PolygonModeDropDown.Value = 'areoles';
            app.ResultsAxes = uiaxes(app.ResultsTab);
            title(app.ResultsAxes, 'Title')
            xlabel(app.ResultsAxes, 'X')
            ylabel(app.ResultsAxes, 'Y')
            app.ResultsPanel = uipanel(app.ResultsTab);
            app.ResultsPanel.Title = 'Results';
            app.TargetLabel = uilabel(app.ResultsPanel);
            app.TargetLabel.Text = 'Target';
            app.ResultsTargetDropDown = uidropdown(app.ResultsPanel);
            app.ResultsTargetDropDown.Items = {'veins', 'nodes', 'areoles', 'polygons', 'HLD', 'summary'};
            app.ResultsTargetDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultsTargetDropDownValueChanged, true);
            app.ResultsTargetDropDown.Value = 'veins';
            app.ResultsClearButton = uibutton(app.ResultsPanel, 'push');
            app.ResultsClearButton.ButtonPushedFcn = createCallbackFcn(app, @ResultsClearButtonPushed, true);
            app.ResultsClearButton.Text = 'Clear';
            app.YLabel = uilabel(app.ResultsPanel);
            app.YLabel.Text = 'Y';
            app.ResultsYaxisDropDown = uidropdown(app.ResultsPanel);
            app.ResultsYaxisDropDown.Items = {'none'};
            app.ResultsYaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultsYaxisDropDownValueChanged, true);
            app.ResultsYaxisDropDown.Value = 'none';
            app.XLabel = uilabel(app.ResultsPanel);
            app.XLabel.Text = 'X';
            app.ResultsXaxisDropDown = uidropdown(app.ResultsPanel);
            app.ResultsXaxisDropDown.Items = {'none'};
            app.ResultsXaxisDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultsXaxisDropDownValueChanged, true);
            app.ResultsXaxisDropDown.Value = 'none';
            app.ResultsHoldCheckBox = uicheckbox(app.ResultsPanel);
            app.ResultsHoldCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResultsHoldCheckBoxValueChanged, true);
            app.ResultsHoldCheckBox.Text = 'Hold';
            app.TypeLabel = uilabel(app.ResultsPanel);
            app.TypeLabel.Text = 'Type';
            app.ResultsPlotTypeDropDown = uidropdown(app.ResultsPanel);
            app.ResultsPlotTypeDropDown.Items = {'scatter', '1D hist', '2D hist'};
            app.ResultsPlotTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultsPlotTypeDropDownValueChanged, true);
            app.ResultsPlotTypeDropDown.Value = 'scatter';
            app.ResultsYlogCheckBox = uicheckbox(app.ResultsPanel);
            app.ResultsYlogCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResultsYlogCheckBoxValueChanged, true);
            app.ResultsYlogCheckBox.Text = 'log';
            app.ResultsXlogCheckBox = uicheckbox(app.ResultsPanel);
            app.ResultsXlogCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResultsXlogCheckBoxValueChanged, true);
            app.ResultsXlogCheckBox.Text = 'log';
            app.UnitsLabel_2 = uilabel(app.ResultsPanel);
            app.UnitsLabel_2.Text = 'Units';
            app.ResultsUnitsDropDown = uidropdown(app.ResultsPanel);
            app.ResultsUnitsDropDown.Items = {'pixels', 'microns', 'mm'};
            app.ResultsUnitsDropDown.ValueChangedFcn = createCallbackFcn(app, @ResultsUnitsDropDownValueChanged, true);
            app.ResultsUnitsDropDown.Value = 'pixels';
            app.ResultsExcludeBoundaryCheckBox = uicheckbox(app.ResultsPanel);
            app.ResultsExcludeBoundaryCheckBox.ValueChangedFcn = createCallbackFcn(app, @ResultsExcludeBoundaryCheckBoxValueChanged, true);
            app.ResultsExcludeBoundaryCheckBox.Text = 'Exclude boundary';
            app.OutputTab = uitab(app.TabGroup);
            app.OutputTab.Title = 'Output';
            app.TestButton = uibutton(app.OutputTab, 'push');
            app.TestButton.ButtonPushedFcn = createCallbackFcn(app, @TestButtonPushed, true);
            app.TestButton.Text = 'Test';
            app.DisplayOutputPanel = uipanel(app.OutputTab);
            app.DisplayOutputPanel.Title = 'Display Outputs';
            app.SaveSingleImageButton = uibutton(app.DisplayOutputPanel, 'push');
            app.SaveSingleImageButton.ButtonPushedFcn = createCallbackFcn(app, @SaveSingleImageButtonPushed, true);
            app.SaveSingleImageButton.Text = 'Save Image';
            app.SavePlotsButton = uibutton(app.DisplayOutputPanel, 'push');
            app.SavePlotsButton.ButtonPushedFcn = createCallbackFcn(app, @SavePlotsButtonPushed, true);
            app.SavePlotsButton.Text = 'Save Plots';
            app.SaveGUIButton = uibutton(app.DisplayOutputPanel, 'push');
            app.SaveGUIButton.ButtonPushedFcn = createCallbackFcn(app, @SaveGUIButtonPushed, true);
            app.SaveGUIButton.Text = 'Save GUI';
            app.SaveManualButton = uibutton(app.DisplayOutputPanel, 'push');
            app.SaveManualButton.ButtonPushedFcn = createCallbackFcn(app, @SaveManualButtonPushed, true);
            app.SaveManualButton.Text = 'Save Manual';
            app.SaveAllPanelsButton = uibutton(app.DisplayOutputPanel, 'push');
            app.SaveAllPanelsButton.ButtonPushedFcn = createCallbackFcn(app, @SaveAllPanelsButtonPushed, true);
            app.SaveAllPanelsButton.Text = 'Save All Panels';
            app.SaveHighResCheckBox = uicheckbox(app.DisplayOutputPanel);
            app.SaveHighResCheckBox.Text = 'high res. screen';
            app.AnalysisFiguresPanel = uipanel(app.OutputTab);
            app.AnalysisFiguresPanel.Title = 'Analysis Figures';
            app.FigureSaveButton = uibutton(app.AnalysisFiguresPanel, 'push');
            app.FigureSaveButton.ButtonPushedFcn = createCallbackFcn(app, @FigureSaveButtonPushed, true);
            app.FigureSaveButton.Text = 'Figure';
            app.EdgeWidthDropDownLabel = uilabel(app.AnalysisFiguresPanel);
            app.EdgeWidthDropDownLabel.Text = 'Edge Width';
            app.FigureEdgeWidthDropDown = uidropdown(app.AnalysisFiguresPanel);
            app.FigureEdgeWidthDropDown.Items = {'1', '2', '3', '4', '5'};
            app.FigureEdgeWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @FigureEdgeWidthDropDownValueChanged, true);
            app.FigureEdgeWidthDropDown.Value = '1';
            app.SkeletonWidthDropDownLabel = uilabel(app.AnalysisFiguresPanel);
            app.SkeletonWidthDropDownLabel.Text = 'Skeleton Width';
            app.FigureSkeletonWidthDropDown = uidropdown(app.AnalysisFiguresPanel);
            app.FigureSkeletonWidthDropDown.Items = {'1', '2', '3', '4', '5'};
            app.FigureSkeletonWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @FigureSkeletonWidthDropDownValueChanged, true);
            app.FigureSkeletonWidthDropDown.Value = '1';
            app.HLDFiguresPanel = uipanel(app.OutputTab);
            app.HLDFiguresPanel.Title = 'HLD Figures';
            app.HLDFigureSaveButton = uibutton(app.HLDFiguresPanel, 'push');
            app.HLDFigureSaveButton.ButtonPushedFcn = createCallbackFcn(app, @HLDFigureSaveButtonPushed, true);
            app.HLDFigureSaveButton.Text = 'HLD figures';
            app.HLDFullLeafCheckBox = uicheckbox(app.HLDFiguresPanel);
            app.HLDFullLeafCheckBox.ValueChangedFcn = createCallbackFcn(app, @HLDFullLeafCheckBoxValueChanged, true);
            app.HLDFullLeafCheckBox.Text = 'Full Leaf';
            app.NumberofROILabel = uilabel(app.HLDFiguresPanel);
            app.NumberofROILabel.Text = 'Number of ROI';
            app.HLDNumROIDropDown = uidropdown(app.HLDFiguresPanel);
            app.HLDNumROIDropDown.Items = {'1', '2', '3', '4', '5', '6', '7', '8', '9', '10', '12', '14', '16', '18', '20', '25', '30', '35', '40', '50', '60', '70', '80', '90', '100'};
            app.HLDNumROIDropDown.Value = '1';
            app.PRTab = uitab(app.TabGroup);
            app.PRTab.Title = 'PR';
            app.PRResultsPanel = uipanel(app.PRTab);
            app.PRResultsPanel.Title = 'PR Results';
            app.PRResultsTable = uitable(app.PRResultsPanel);
            app.PRResultsTable.ColumnName = {'Method'; 'Use'; 'F1'; 'F1 thr.'; 'F2'; 'F2 thr.'};
            app.PRResultsTable.RowName = {};
            app.PRResultsTable.ColumnEditable = [false true false false false false];
            app.PRResultsTable.CellEditCallback = createCallbackFcn(app, @PRResultsTableCellEdit, true);
            app.ImageLabel_2 = uilabel(app.PRResultsPanel);
            app.ImageLabel_2.Text = 'Image';
            app.PRImageDropDown = uidropdown(app.PRResultsPanel);
            app.PRImageDropDown.Items = {'CNN', 'Midgrey', 'Niblack', 'Bernsen', 'Sauvola', 'Vesselness', 'MFATl', 'MFATp', 'FeatureType', 'BowlerHat'};
            app.PRImageDropDown.ValueChangedFcn = createCallbackFcn(app, @PRImageDropDownValueChanged, true);
            app.PRImageDropDown.Value = 'CNN';
            app.MetricLabel = uilabel(app.PRResultsPanel);
            app.MetricLabel.Text = 'Metric';
            app.PRMetricDropDown = uidropdown(app.PRResultsPanel);
            app.PRMetricDropDown.Items = {'F1', 'F2'};
            app.PRMetricDropDown.ValueChangedFcn = createCallbackFcn(app, @PRMetricDropDownValueChanged, true);
            app.PRMetricDropDown.Value = 'F1';
            app.TypeLabel_3 = uilabel(app.PRResultsPanel);
            app.TypeLabel_3.Text = 'Type';
            app.PRTypeDropDown = uidropdown(app.PRResultsPanel);
            app.PRTypeDropDown.Items = {'FWB', 'Skeleton'};
            app.PRTypeDropDown.ValueChangedFcn = createCallbackFcn(app, @PRTypeDropDownValueChanged, true);
            app.PRTypeDropDown.Value = 'FWB';
            app.GraphLabel = uilabel(app.PRResultsPanel);
            app.GraphLabel.Text = 'Graph';
            app.PRGraphDropDown = uidropdown(app.PRResultsPanel);
            app.PRGraphDropDown.Items = {'PR FWB', 'PR Skeleton', 'F1 FWB', 'F1 Skeleton', 'F2 FWB', 'F2 Skeleton', 'Network '};
            app.PRGraphDropDown.ValueChangedFcn = createCallbackFcn(app, @PRGraphDropDownValueChanged, true);
            app.PRGraphDropDown.Value = 'PR FWB';
            app.PRDisplayImage = uibutton(app.PRResultsPanel, 'push');
            app.PRDisplayImage.ButtonPushedFcn = createCallbackFcn(app, @PRDisplayImageButtonPushed, true);
            app.PRDisplayImage.Text = 'Display';
            app.PRPlotGraph = uibutton(app.PRResultsPanel, 'push');
            app.PRPlotGraph.ButtonPushedFcn = createCallbackFcn(app, @PRPlotGraphButtonPushed, true);
            app.PRPlotGraph.Text = 'Plot';
            app.WidthLabel_2 = uilabel(app.PRResultsPanel);
            app.WidthLabel_2.Text = 'Width';
            app.PRWidthDropDown = uidropdown(app.PRResultsPanel);
            app.PRWidthDropDown.Items = {'1', '2', '3', '4', '5'};
            app.PRWidthDropDown.ValueChangedFcn = createCallbackFcn(app, @PRWidthDropDownValueChanged, true);
            app.PRWidthDropDown.Value = '1';
            app.PRAxes = uiaxes(app.PRTab);
            title(app.PRAxes, 'Title')
            xlabel(app.PRAxes, 'X')
            ylabel(app.PRAxes, 'Y')
            app.PRAnalysisPanel = uipanel(app.PRTab);
            app.PRAnalysisPanel.Title = 'PR Analysis';
            app.PRLoadGTButton = uibutton(app.PRAnalysisPanel, 'push');
            app.PRLoadGTButton.ButtonPushedFcn = createCallbackFcn(app, @PRLoadGTButtonPushed, true);
            app.PRLoadGTButton.Text = 'Load GT';
            app.PRLoadGTMaskButton = uibutton(app.PRAnalysisPanel, 'push');
            app.PRLoadGTMaskButton.ButtonPushedFcn = createCallbackFcn(app, @PRLoadGTMaskButtonPushed, true);
            app.PRLoadGTMaskButton.Text = 'Load Mask';
            app.PRRunButton = uibutton(app.PRAnalysisPanel, 'push');
            app.PRRunButton.ButtonPushedFcn = createCallbackFcn(app, @PRRunButtonPushed, true);
            app.PRRunButton.Text = 'Run PR';
            app.DownSampleLabel = uilabel(app.PRAnalysisPanel);
            app.DownSampleLabel.Text = 'Down Sample';
            app.PRDownSampleDropDown = uidropdown(app.PRAnalysisPanel);
            app.PRDownSampleDropDown.Items = {'0.33', '0.5', '0.67', '1', '1.5', '2', '2.5', '3', '4'};
            app.PRDownSampleDropDown.ValueChangedFcn = createCallbackFcn(app, @PRDownSampleDropDownValueChanged, true);
            app.PRDownSampleDropDown.Value = '2';
            app.PRSaveDataButton = uibutton(app.PRAnalysisPanel, 'push');
            app.PRSaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @PRSaveDataButtonPushed, true);
            app.PRSaveDataButton.Text = 'Save Data';
            app.PRSensitivityPanel = uipanel(app.PRTab);
            app.PRSensitivityPanel.Title = 'PR Sensitivity';
            app.PRSensitivityButton = uibutton(app.PRSensitivityPanel, 'push');
            app.PRSensitivityButton.ButtonPushedFcn = createCallbackFcn(app, @PRSensitivityButtonButtonPushed, true);
            app.PRSensitivityButton.Text = 'Sensitivity';
            app.SensitivityLabel = uilabel(app.PRSensitivityPanel);
            app.SensitivityLabel.Text = 'Sensitivity';
            app.PRSensitivityDisplayDropDown = uidropdown(app.PRSensitivityPanel);
            app.PRSensitivityDisplayDropDown.Items = {'Scale', 'Contrast', 'Blur', 'Upsample'};
            app.PRSensitivityDisplayDropDown.ValueChangedFcn = createCallbackFcn(app, @PRSensitivityDisplayDropDownValueChanged, true);
            app.PRSensitivityDisplayDropDown.Value = 'Scale';
            app.PRSensitivitySaveButton = uibutton(app.PRSensitivityPanel, 'push');
            app.PRSensitivitySaveButton.ButtonPushedFcn = createCallbackFcn(app, @PRSensitivitySaveButtonPushed, true);
            app.PRSensitivitySaveButton.Text = 'Save';
            app.AnalysisPanel = uipanel(app.LeafVeinGUI);
            app.AnalysisPanel.Title = 'Analysis';
            app.AnalysisAutoSaveCheckBox = uicheckbox(app.AnalysisPanel);
            app.AnalysisAutoSaveCheckBox.Text = 'Auto save';
            app.AnalysisButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalysisButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisButtonPushed, true);
            app.AnalysisButton.Text = 'Analysis';
            app.AnalysisSaveDataButton = uibutton(app.AnalysisPanel, 'push');
            app.AnalysisSaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @AnalysisSaveDataButtonPushed, true);
            app.AnalysisSaveDataButton.Text = 'Save Data';
            app.ScalingPanel = uipanel(app.LeafVeinGUI);
            app.ScalingPanel.Title = 'Scaling';
            app.FactorLabel = uilabel(app.ScalingPanel);
            app.FactorLabel.Text = 'Factor';
            app.ScalingFactorEditField = uieditfield(app.ScalingPanel, 'numeric');
            app.ScalingFactorEditField.ValueChangedFcn = createCallbackFcn(app, @ScalingFactorEditFieldValueChanged, true);
            app.ScalingButton = uibutton(app.ScalingPanel, 'push');
            app.ScalingButton.ButtonPushedFcn = createCallbackFcn(app, @ScalingButtonPushed, true);
            app.ScalingButton.Text = {'Rescale'; ''};
            app.ScalingValue = uilabel(app.ScalingPanel);
            app.ScalingValue.Text = '1';
            app.HLDPanel = uipanel(app.LeafVeinGUI);
            app.HLDPanel.Title = 'Hierarchical Loop Decomposition';
            app.HLDSaveFigureCheckBox = uicheckbox(app.HLDPanel);
            app.HLDSaveFigureCheckBox.Text = 'Auto save';
            app.HLDRunButton = uibutton(app.HLDPanel, 'push');
            app.HLDRunButton.ButtonPushedFcn = createCallbackFcn(app, @HLDRunButtonPushed, true);
            app.HLDRunButton.Text = 'Run HLD';
            app.HLDSaveDataButton = uibutton(app.HLDPanel, 'push');
            app.HLDSaveDataButton.ButtonPushedFcn = createCallbackFcn(app, @HLDSaveDataButtonPushed, true);
            app.HLDSaveDataButton.Text = 'Save HLD';
            app.CNN = uitabgroup(app.LeafVeinGUI);
            app.CNNPredictTab = uitab(app.CNN);
            app.CNNPredictTab.Title = 'CNN Predict';
            app.CNNRunButton = uibutton(app.CNNPredictTab, 'push');
            app.CNNRunButton.ButtonPushedFcn = createCallbackFcn(app, @CNNRunButtonPushed, true);
            app.CNNRunButton.Text = 'Run CNN';
            app.CNNSaveButton = uibutton(app.CNNPredictTab, 'push');
            app.CNNSaveButton.ButtonPushedFcn = createCallbackFcn(app, @CNNSaveButtonPushed, true);
            app.CNNSaveButton.Text = 'Save CNN';
            app.CNNParallelCheckBox = uicheckbox(app.CNNPredictTab);
            app.CNNParallelCheckBox.Text = 'Parallel';
            app.EnsembleDropDownLabel = uilabel(app.CNNPredictTab);
            app.EnsembleDropDownLabel.Text = 'Ensemble';
            app.CNNEnsembleDropDown = uidropdown(app.CNNPredictTab);
            app.CNNEnsembleDropDown.Items = {'3'};
            app.CNNEnsembleDropDown.ValueChangedFcn = createCallbackFcn(app, @CNNEnsembleDropDownValueChanged, true);
            app.CNNEnsembleDropDown.Value = '3';
            app.PatchSeparationLabel = uilabel(app.CNNPredictTab);
            app.PatchSeparationLabel.Text = {'Patch'; 'Separation'};
            app.CNNStrideSlider = uislider(app.CNNPredictTab);
            app.CNNStrideSlider.Limits = [50 250];
            app.CNNStrideSlider.MajorTicks = [50 100 150 200 250];
            app.CNNStrideSlider.Value = 200;
            app.ModeLabel = uilabel(app.CNNPredictTab);
            app.ModeLabel.Text = 'Mode';
            app.CNNModeDropDown = uidropdown(app.CNNPredictTab);
            app.CNNModeDropDown.Items = {'random', 'select', 'fixed'};
            app.CNNModeDropDown.ValueChangedFcn = createCallbackFcn(app, @CNNModeDropDownValueChanged, true);
            app.CNNModeDropDown.Value = 'random';
            app.CNNSelectLoad_2 = uibutton(app.CNNPredictTab, 'push');
            app.CNNSelectLoad_2.ButtonPushedFcn = createCallbackFcn(app, @CNNSelectLoad_2ButtonPushed, true);
            app.CNNSelectLoad_2.Text = 'Load Select';
            app.CNNSelectButton = uibutton(app.CNNPredictTab, 'push');
            app.CNNSelectButton.ButtonPushedFcn = createCallbackFcn(app, @CNNSelectButtonPushed, true);
            app.CNNSelectButton.Text = 'Run Select';
            app.CNNPyramidCheckBox = uicheckbox(app.CNNPredictTab);
            app.CNNPyramidCheckBox.Text = 'Pyramid';
            app.CNNSelectTab = uitab(app.CNN);
            app.CNNSelectTab.Title = 'CNN Select';
            app.CNNModelTable = uitable(app.CNNSelectTab);
            app.CNNModelTable.RowName = {'''f0'';''f1'';''f2'';''f3'';''f4'';''f5'';''f6'''};
            app.CNNModelTable.ColumnEditable = true;
            app.CNNSelectSave = uibutton(app.CNNSelectTab, 'push');
            app.CNNSelectSave.ButtonPushedFcn = createCallbackFcn(app, @CNNSelectSaveButtonPushed, true);
            app.CNNSelectSave.Text = 'Save';
            app.CNNSelectLoad = uibutton(app.CNNSelectTab, 'push');
            app.CNNSelectLoad.ButtonPushedFcn = createCallbackFcn(app, @CNNSelectLoadButtonPushed, true);
            app.CNNSelectLoad.Text = 'Load';
            app.CNNSelectAll = uibutton(app.CNNSelectTab, 'push');
            app.CNNSelectAll.ButtonPushedFcn = createCallbackFcn(app, @CNNSelectAllButtonPushed, true);
            app.CNNSelectAll.Text = 'All';
            app.CNNSelectNone = uibutton(app.CNNSelectTab, 'push');
            app.CNNSelectNone.ButtonPushedFcn = createCallbackFcn(app, @CNNSelectNoneButtonPushed, true);
            app.CNNSelectNone.Text = 'None';
            app.PetiolePanel = uipanel(app.LeafVeinGUI);
            app.PetiolePanel.Title = 'Petiole';
            app.PetioleSetButton = uibutton(app.PetiolePanel, 'push');
            app.PetioleSetButton.ButtonPushedFcn = createCallbackFcn(app, @PetioleSetButtonPushed, true);
            app.PetioleSetButton.Text = 'Set Petiole';
            app.PetioleUseCheckBox = uicheckbox(app.PetiolePanel);
            app.PetioleUseCheckBox.Text = 'Use';
            app.SegmentationPanel = uipanel(app.LeafVeinGUI);
            app.SegmentationPanel.Title = 'Segmentation';
            app.SegmentationThresholdSlider = uislider(app.SegmentationPanel);
            app.SegmentationThresholdSlider.ValueChangedFcn = createCallbackFcn(app, @SegmentationThresholdSliderValueChanged, true);
            app.SegmentationThresholdSlider.ValueChangingFcn = createCallbackFcn(app, @SegmentationThresholdSliderValueChanging, true);
            app.ThresholdEditFieldLabel = uilabel(app.SegmentationPanel);
            app.ThresholdEditFieldLabel.Text = 'Threshold';
            app.SegmentationThresholdEditField = uieditfield(app.SegmentationPanel, 'numeric');
            app.SegmentationThresholdEditField.Limits = [0 1];
            app.SegmentationThresholdEditField.ValueDisplayFormat = '%11.2f';
            app.SegmentationThresholdEditField.ValueChangedFcn = createCallbackFcn(app, @SegmentationThresholdEditFieldValueChanged, true);
            app.SegmentationThresholdEditField.Value = 0.65;
            app.SegmentationButton = uibutton(app.SegmentationPanel, 'push');
            app.SegmentationButton.ButtonPushedFcn = createCallbackFcn(app, @SegmentationButtonPushed, true);
            app.SegmentationButton.Text = 'Segment';
            app.SegmentationSaveButton = uibutton(app.SegmentationPanel, 'push');
            app.SegmentationSaveButton.Enable = 'off';
            app.SegmentationSaveButton.Text = 'Save';
            app.FlowPanel = uipanel(app.LeafVeinGUI);
            app.FlowPanel.Title = 'Flow';
            app.CropPanel = uipanel(app.LeafVeinGUI);
            app.CropPanel.Title = 'Crop';
            app.CropButton = uibutton(app.CropPanel, 'push');
            app.CropButton.ButtonPushedFcn = createCallbackFcn(app, @CropButtonPushed, true);
            app.CropButton.Text = 'Crop';
            app.CropSaveButton = uibutton(app.CropPanel, 'push');
            app.CropSaveButton.ButtonPushedFcn = createCallbackFcn(app, @CropSaveButtonPushed, true);
            app.CropSaveButton.Text = 'Save';
            app.CropSkipCheckBox = uicheckbox(app.CropPanel);
            app.CropSkipCheckBox.ValueChangedFcn = createCallbackFcn(app, @CropSkipCheckBoxValueChanged, true);
            app.CropSkipCheckBox.Text = 'Skip';
            app.TLabel = uilabel(app.CropPanel);
            app.TLabel.Text = 'T';
            app.CropTopEditField = uieditfield(app.CropPanel, 'numeric');
            app.CropTopEditField.Limits = [1 Inf];
            app.CropTopEditField.ValueDisplayFormat = '%u';
            app.CropTopEditField.ValueChangedFcn = createCallbackFcn(app, @CropTopEditFieldValueChanged, true);
            app.CropTopEditField.Value = 1;
            app.LLabel = uilabel(app.CropPanel);
            app.LLabel.Text = 'L';
            app.CropLeftEditField = uieditfield(app.CropPanel, 'numeric');
            app.CropLeftEditField.Limits = [1 Inf];
            app.CropLeftEditField.ValueDisplayFormat = '%u';
            app.CropLeftEditField.ValueChangedFcn = createCallbackFcn(app, @CropLeftEditFieldValueChanged, true);
            app.CropLeftEditField.Value = 1;
            app.WLabel = uilabel(app.CropPanel);
            app.WLabel.Text = 'W';
            app.CropWidthEditField = uieditfield(app.CropPanel, 'numeric');
            app.CropWidthEditField.Limits = [1 Inf];
            app.CropWidthEditField.ValueDisplayFormat = '%u';
            app.CropWidthEditField.ValueChangedFcn = createCallbackFcn(app, @CropWidthEditFieldValueChanged, true);
            app.CropWidthEditField.Value = 1;
            app.HLabel = uilabel(app.CropPanel);
            app.HLabel.Text = 'H';
            app.CropHeightEditField = uieditfield(app.CropPanel, 'numeric');
            app.CropHeightEditField.Limits = [1 Inf];
            app.CropHeightEditField.ValueDisplayFormat = '%u';
            app.CropHeightEditField.ValueChangedFcn = createCallbackFcn(app, @CropHeightEditFieldValueChanged, true);
            app.CropHeightEditField.Value = 1;
            app.CropSetRegionButton = uibutton(app.CropPanel, 'push');
            app.CropSetRegionButton.ButtonPushedFcn = createCallbackFcn(app, @CropSetRegionButtonPushed, true);
            app.CropSetRegionButton.Text = 'Set ROI';
            app.ChannelPanel = uipanel(app.LeafVeinGUI);
            app.ChannelPanel.Title = 'Channel';
            app.ChannelButton = uibutton(app.ChannelPanel, 'push');
            app.ChannelButton.ButtonPushedFcn = createCallbackFcn(app, @ChannelButtonPushed, true);
            app.ChannelButton.Text = 'Channel';
            app.ChannelDropDownLabel = uilabel(app.ChannelPanel);
            app.ChannelDropDownLabel.Text = 'Channel';
            app.ChannelDropDown = uidropdown(app.ChannelPanel);
            app.ChannelDropDown.Items = {'R', 'G', 'B', 'mean', 'max', 'min', 'R-G', 'R-B', 'G-R', 'G-B', 'B-R', 'B-G', 'R/G', 'R/B', 'G/R', 'G/B', 'B/R', 'B/G', 'R^2/G', 'R^2/B', 'G^2/R', 'G^2/B', 'B^2/R', 'B^2/G'};
            app.ChannelDropDown.ValueChangedFcn = createCallbackFcn(app, @ChannelDropDownValueChanged, true);
            app.ChannelDropDown.Value = 'R';
            app.CLAHEPanel = uipanel(app.LeafVeinGUI);
            app.CLAHEPanel.Title = 'Contrast Enhancement';
            app.ClaheSaveButton = uibutton(app.CLAHEPanel, 'push');
            app.ClaheSaveButton.ButtonPushedFcn = createCallbackFcn(app, @ClaheSaveButtonPushed, true);
            app.ClaheSaveButton.Text = 'Save';
            app.ClaheButton = uibutton(app.CLAHEPanel, 'push');
            app.ClaheButton.ButtonPushedFcn = createCallbackFcn(app, @ClaheButtonPushed, true);
            app.ClaheButton.Text = 'CLAHE';
            app.ClaheSkipCheckBox = uicheckbox(app.CLAHEPanel);
            app.ClaheSkipCheckBox.ValueChangedFcn = createCallbackFcn(app, @ClaheSkipCheckBoxValueChanged, true);
            app.ClaheSkipCheckBox.Text = 'Skip';
            app.TileSizeLabel = uilabel(app.CLAHEPanel);
            app.TileSizeLabel.Text = 'Tile Size';
            app.ClaheTileSizeEditField = uieditfield(app.CLAHEPanel, 'numeric');
            app.ClaheTileSizeEditField.Limits = [1 1000];
            app.ClaheTileSizeEditField.RoundFractionalValues = 'on';
            app.ClaheTileSizeEditField.ValueDisplayFormat = '%u';
            app.ClaheTileSizeEditField.ValueChangedFcn = createCallbackFcn(app, @ClaheTileSizeEditFieldValueChanged, true);
            app.ClaheTileSizeEditField.Value = 1;
            app.ClipLimitLabel = uilabel(app.CLAHEPanel);
            app.ClipLimitLabel.Text = 'Clip Limit';
            app.ClaheClipLimitEditField = uieditfield(app.CLAHEPanel, 'numeric');
            app.ClaheClipLimitEditField.Limits = [0 1];
            app.ClaheClipLimitEditField.ValueDisplayFormat = '%11.3f';
            app.ClaheClipLimitEditField.ValueChangedFcn = createCallbackFcn(app, @ClaheClipLimitEditFieldValueChanged, true);
            app.ClaheClipLimitEditField.Value = 0.01;
            % Show the figure after all components are created
            app.LeafVeinGUI.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = LeafVeinCNN

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.LeafVeinGUI)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.LeafVeinGUI)
        end
    end
end